classdef CriptUN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CriptUNUIFigure                 matlab.ui.Figure
        ArchivoMenu                     matlab.ui.container.Menu
        SalirMenu                       matlab.ui.container.Menu
        SclsicosMenu                    matlab.ui.container.Menu
        DesplazamientoMenu_2            matlab.ui.container.Menu
        CifrarMenu_7                    matlab.ui.container.Menu
        DecifrarMenu_7                  matlab.ui.container.Menu
        SustitucinMenu_2                matlab.ui.container.Menu
        CifrarMenu_8                    matlab.ui.container.Menu
        DecifrarMenu_8                  matlab.ui.container.Menu
        AfnMenu_2                       matlab.ui.container.Menu
        CifrarMenu_10                   matlab.ui.container.Menu
        DecifrarMenu_10                 matlab.ui.container.Menu
        VigenreMenu_2                   matlab.ui.container.Menu
        CifrarMenu_11                   matlab.ui.container.Menu
        DecifrarMenu_11                 matlab.ui.container.Menu
        HillMenu_2                      matlab.ui.container.Menu
        CifrarMenu_12                   matlab.ui.container.Menu
        DecifrarMenu_12                 matlab.ui.container.Menu
        ImgenesMenu_2                   matlab.ui.container.Menu
        PermutacinMenu_2                matlab.ui.container.Menu
        CifrarMenu_9                    matlab.ui.container.Menu
        DecifrarMenu_9                  matlab.ui.container.Menu
        CriptoanlisisMenu               matlab.ui.container.Menu
        DesplazamientoMenu_3            matlab.ui.container.Menu
        SustitucionMenu_3               matlab.ui.container.Menu
        AfnMenu_3                       matlab.ui.container.Menu
        VigenreMenu_3                   matlab.ui.container.Menu
        HillMenu_3                      matlab.ui.container.Menu
        PermutacinMenu_3                matlab.ui.container.Menu
        SdeBloqueMenu                   matlab.ui.container.Menu
        GammaPentagonalMenu             matlab.ui.container.Menu
        SDESMenu                        matlab.ui.container.Menu
        DESMenu                         matlab.ui.container.Menu
        TRESDESMenu                     matlab.ui.container.Menu
        AESMenu                         matlab.ui.container.Menu
        SClavePblicaMenu                matlab.ui.container.Menu
        RSAMenu                         matlab.ui.container.Menu
        RabinMenu                       matlab.ui.container.Menu
        ElGamalMenezesVanstoneMenu      matlab.ui.container.Menu
        AyudaMenu                       matlab.ui.container.Menu
        AcercaMenu                      matlab.ui.container.Menu
        Button                          matlab.ui.control.StateButton
        GridLayout                      matlab.ui.container.GridLayout
        CriptUNTabGroup                 matlab.ui.container.TabGroup
        CriptoClasicaTab                matlab.ui.container.Tab
        GridLayoutCriptoClasica         matlab.ui.container.GridLayout
        ClavePanel                      matlab.ui.container.Panel
        GridLayoutClave                 matlab.ui.container.GridLayout
        GenerarClaveButton              matlab.ui.control.Button
        LimpiarClaveButton              matlab.ui.control.Button
        CargarClaveButton               matlab.ui.control.Button
        TextAreaClave                   matlab.ui.control.TextArea
        ResultadoPanel                  matlab.ui.container.Panel
        GridLayoutResultado             matlab.ui.container.GridLayout
        CopiarButton                    matlab.ui.control.Button
        CifrarDecifrarButton            matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        GuardarResultadoButton          matlab.ui.control.Button
        TextAreaResultado               matlab.ui.control.TextArea
        TextodeentradaPanel             matlab.ui.container.Panel
        GridLayoutEntrada               matlab.ui.container.GridLayout
        PegarButton                     matlab.ui.control.Button
        CargarTextoButton               matlab.ui.control.Button
        LimpiarTextoButton              matlab.ui.control.Button
        TextAreaTexto                   matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        SdeBloqueTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TrabajandoLabel                 matlab.ui.control.Label
        PruebasButton                   matlab.ui.control.Button
        DecifrarButton                  matlab.ui.control.Button
        CifrarButton                    matlab.ui.control.Button
        ImagendeSalidaPanel             matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        LimpiarOutputImageButton        matlab.ui.control.Button
        GuardarOutputImageButton        matlab.ui.control.Button
        OutputImage                     matlab.ui.control.Image
        ImagendeEntradaPanel            matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LimpiarInputImageButton         matlab.ui.control.Button
        CargarInputImageButton          matlab.ui.control.Button
        InputImage                      matlab.ui.control.Image
        IVEditField                     matlab.ui.control.EditField
        IVEditFieldLabel                matlab.ui.control.Label
        ClaveEditField                  matlab.ui.control.EditField
        ClaveEditFieldLabel             matlab.ui.control.Label
        TamaodelaclavebitsDropDown      matlab.ui.control.DropDown
        TamaodelaclavebitsDropDownLabel  matlab.ui.control.Label
        ModosdeciframientoDropDown      matlab.ui.control.DropDown
        ModosdeciframientoDropDownLabel  matlab.ui.control.Label
        CriptosistemaDropDown           matlab.ui.control.DropDown
        CriptosistemaDropDownLabel      matlab.ui.control.Label
        ClavePublicaTab                 matlab.ui.container.Tab
        ClavePublicaGridLayout          matlab.ui.container.GridLayout
        RespuestaLabel                  matlab.ui.control.Label
        GenerarClaveLabel               matlab.ui.control.Label
        GeneracindeclavesPanel          matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        OpcionesEjecutarDropDown        matlab.ui.control.DropDown
        gc6EditField                    matlab.ui.control.EditField
        gc6EditFieldLabel               matlab.ui.control.Label
        gc3EditField                    matlab.ui.control.EditField
        gc3EditFieldLabel               matlab.ui.control.Label
        gc5EditField                    matlab.ui.control.EditField
        gc5EditFieldLabel               matlab.ui.control.Label
        gc2EditField                    matlab.ui.control.EditField
        gc2EditFieldLabel               matlab.ui.control.Label
        gc4EditField                    matlab.ui.control.EditField
        gc4EditFieldLabel               matlab.ui.control.Label
        gc1EditField                    matlab.ui.control.EditField
        gc1EditFieldLabel               matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        CifrarDecifrarLabel             matlab.ui.control.Label
        CDSwitch                        matlab.ui.control.Switch
        CifrarCDButton                  matlab.ui.control.Button
        TextocifradoPanel               matlab.ui.container.Panel
        TextoCifradoGridLayout          matlab.ui.container.GridLayout
        TextoCifradoTextArea            matlab.ui.control.TextArea
        TextoclaroPanel                 matlab.ui.container.Panel
        TextoClaroGridLayout            matlab.ui.container.GridLayout
        TextoClaroTextArea              matlab.ui.control.TextArea
        OpcionesdeclavePanel            matlab.ui.container.Panel
        OpcionesCPGridLayout            matlab.ui.container.GridLayout
        LimpiarButton_2                 matlab.ui.control.Button
        oc3EditField                    matlab.ui.control.EditField
        oc3EditFieldLabel               matlab.ui.control.Label
        oc5EditField                    matlab.ui.control.EditField
        oc5EditFieldLabel               matlab.ui.control.Label
        oc2EditField                    matlab.ui.control.EditField
        oc2EditFieldLabel               matlab.ui.control.Label
        oc4EditField                    matlab.ui.control.EditField
        oc4EditFieldLabel               matlab.ui.control.Label
        oc1EditField                    matlab.ui.control.EditField
        oc1EditFieldLabel               matlab.ui.control.Label
        CriptosistemaPublicoDropDown    matlab.ui.control.DropDown
        CriptosistemaDropDown_2Label    matlab.ui.control.Label
        FirmaDigitaldeArchivosTab       matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        EsquemadefirmaElGamalPanel      matlab.ui.container.Panel
        EsquemaGridLayout               matlab.ui.container.GridLayout
        FirmeeldocumentoPanel           matlab.ui.container.Panel
        FirmarVerificarGridLayout       matlab.ui.container.GridLayout
        PruebasButton_2                 matlab.ui.control.Button
        VerificarLabel                  matlab.ui.control.Label
        GuardarCargarFirmaButton        matlab.ui.control.Button
        FirmarVerificarFirmaButton      matlab.ui.control.Button
        gammaEditField                  matlab.ui.control.EditField
        gammaEditFieldLabel             matlab.ui.control.Label
        deltaEditField                  matlab.ui.control.EditField
        deltaEditFieldLabel             matlab.ui.control.Label
        GenerelascontraseasPanel        matlab.ui.container.Panel
        PasswordGridLayout              matlab.ui.container.GridLayout
        GenerarCPbButton                matlab.ui.control.Button
        PublicaEditField                matlab.ui.control.EditField
        PublicaEditFieldLabel           matlab.ui.control.Label
        GenerarPasswordButton           matlab.ui.control.Button
        GuardarPasswordButton           matlab.ui.control.Button
        CargarPasswordButton            matlab.ui.control.Button
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        ConfigureelesquemaPanel         matlab.ui.container.Panel
        ConfiguracionGridLayout         matlab.ui.container.GridLayout
        OpcionesDropDown                matlab.ui.control.DropDown
        GenerarEsquemaButton            matlab.ui.control.Button
        GuardarEsquemaButton            matlab.ui.control.Button
        CargarEsquemaButton             matlab.ui.control.Button
        GeneradorEditField              matlab.ui.control.EditField
        GeneradorEditFieldLabel         matlab.ui.control.Label
        PrimoEditField                  matlab.ui.control.EditField
        PrimoEditFieldLabel             matlab.ui.control.Label
        CargueunarchivoPanel            matlab.ui.container.Panel
        CargarArchivoGridLayout         matlab.ui.container.GridLayout
        SHA256delarchivoEditField       matlab.ui.control.EditField
        SHA256delarchivoEditFieldLabel  matlab.ui.control.Label
        CargarArchivoButton             matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        DesplazamientoMenu              matlab.ui.container.Menu
        CifrarMenu                      matlab.ui.container.Menu
        DecifrarMenu                    matlab.ui.container.Menu
        SustitucinMenu                  matlab.ui.container.Menu
        CifrarMenu_2                    matlab.ui.container.Menu
        DecifrarMenu_2                  matlab.ui.container.Menu
        AfnMenu                         matlab.ui.container.Menu
        CifrarMenu_4                    matlab.ui.container.Menu
        DecifrarMenu_4                  matlab.ui.container.Menu
        VigenreMenu                     matlab.ui.container.Menu
        CifrarMenu_5                    matlab.ui.container.Menu
        DecifrarMenu_5                  matlab.ui.container.Menu
        HillMenu                        matlab.ui.container.Menu
        CifrarMenu_6                    matlab.ui.container.Menu
        DecifrarMenu_6                  matlab.ui.container.Menu
        ImgenesMenu                     matlab.ui.container.Menu
        PermutacinMenu                  matlab.ui.container.Menu
        CifrarMenu_3                    matlab.ui.container.Menu
        DecifrarMenu_3                  matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        GenerarMenu                     matlab.ui.container.Menu
        LimpiarMenu                     matlab.ui.container.Menu
        CargarMenu                      matlab.ui.container.Menu
        GuardarMenu                     matlab.ui.container.Menu
        ContextMenu3                    matlab.ui.container.ContextMenu
        GenerarMenu_2                   matlab.ui.container.Menu
        LimpiarMenu_2                   matlab.ui.container.Menu
        CargarMenu_2                    matlab.ui.container.Menu
        GuardarMenu_2                   matlab.ui.container.Menu
        ContextMenu4                    matlab.ui.container.ContextMenu
        LimpiarMenu_3                   matlab.ui.container.Menu
        CargarMenu_3                    matlab.ui.container.Menu
        ContextMenu5                    matlab.ui.container.ContextMenu
        LimpiarMenu_4                   matlab.ui.container.Menu
        GuardarMenu_4                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CriptoClasicoSeleccionado='' %
        criptoAnalisisSustitucionWindow  % Ventana para el criptoanalisis - sustitucion
        criptoAnalisisVigenereWindow
        criptoAnalisisHillWindow
        ImagenesHillWindow
        criptoAnalisisFuerzaBrutaWindow
        criptoAnalisisPermutacionWindow
        SistemaGammaPentagonalWindow
        %Parámetros SDES
        P10_sdes=[3,5,2,7,4,10,1,9,8,6]; %Permutación para las claves
        P8_sdes=[6,3,7,4,8,5,10,9]; %Permutación para las claves
        EP_sdes=[4,1,2,3,2,3,4,1];%Expansion Permutacion
        S0_sdes=[1,0,3,2;3,2,1,0;0,2,1,3;3,1,3,2];%Caja 1
        S1_sdes=[0,1,2,3;2,0,1,3;3,0,1,0;2,1,0,3];%Caja 2
        P4_sdes=[2,4,3,1]; %Permutación para la funcion de ronda
        IP_sdes=[2,6,3,1,4,8,5,7];%Permutación inicial
        IPinv_sdes=[4,1,3,5,7,2,8,6];%Permutacion final
        %Parámetros DES
        PC1_des=load("PC1_des.txt"); %Permutación para las claves 56->56 bits
        %PC2_des=[13,14,9,29,4,5,15,25,23,55,50,56,53,10,2,21,51,35,44,11,26,41,1,3,33,34,19,52,32,36,37,43,20,47,8,49,48,27,40,18,16,24,17,22,31,28,39,42,38,12,54,46,30,45,6,7]; %Permutación para las claves 56->48 bits
        PC2_des=load("PC2_des.txt");%Permutación para las claves 56->48 bits
        EP_des=load('EP_des.txt');%Expansión-Permutación 32->48 bits
        S1_des=load('S1_des.txt');  %Caja 1
        S2_des=load('S2_des.txt'); %Caja 2
        S3_des=load('S3_des.txt');%Caja 3
        S4_des=load('S4_des.txt');  %Caja 4
        S5_des=load('S5_des.txt');  %Caja 5
        S6_des=load('S6_des.txt');  %Caja 6
        S7_des=load('S7_des.txt');  %Caja 7
        S8_des=load('S8_des.txt');  %Caja 8
        Pfr_des=load("Pfr_des.txt"); %Permutación función de ronda
        IP_des=load('IP_des.txt'); %Permutación inicial
        IPinv_des=load('IPinv_des.txt'); %Permutación final
        
        %Imagen de entrada
        imagenClara
        dimImagenClara

        %Imagen de salida
        imagenCodificada
        %tipo de criptosistema moderno
        tamPalabraCriptoSistema=8;

        %AES
        numeroRondas=10; %por defecto se inicia con bloques de 128bits
        inversosAES=load('inversosAES.txt'); %Tabla de inversos en el cuerpo GF 2^8, por ejemplo el inverso de un numero n de 0 a 255 es inversosAES(n+1)
        multiplicarAES=load('multiplicarAES.txt'); %Multiplicacion de dos numeros en el cuerpo GF 2^8, por ejemplo a*b = multiplicar(a+1,b+1);
        sboxAES= [hex2dec('63'), hex2dec('7c'), hex2dec('77'), hex2dec('7b'), hex2dec('f2'), hex2dec('6b'), hex2dec('6f'), hex2dec('c5'), hex2dec('30'), hex2dec('01'), hex2dec('67'), hex2dec('2b'), hex2dec('fe'), hex2dec('d7'), hex2dec('ab'), hex2dec('76'); ... %0
	            hex2dec('ca'), hex2dec('82'), hex2dec('c9'), hex2dec('7d'), hex2dec('fa'), hex2dec('59'), hex2dec('47'), hex2dec('f0'), hex2dec('ad'), hex2dec('d4'), hex2dec('a2'), hex2dec('af'), hex2dec('9c'), hex2dec('a4'), hex2dec('72'), hex2dec('c0'); ... %1
	            hex2dec('b7'), hex2dec('fd'), hex2dec('93'), hex2dec('26'), hex2dec('36'), hex2dec('3f'), hex2dec('f7'), hex2dec('cc'), hex2dec('34'), hex2dec('a5'), hex2dec('e5'), hex2dec('f1'), hex2dec('71'), hex2dec('d8'), hex2dec('31'), hex2dec('15'); ... %2
	            hex2dec('04'), hex2dec('c7'), hex2dec('23'), hex2dec('c3'), hex2dec('18'), hex2dec('96'), hex2dec('05'), hex2dec('9a'), hex2dec('07'), hex2dec('12'), hex2dec('80'), hex2dec('e2'), hex2dec('eb'), hex2dec('27'), hex2dec('b2'), hex2dec('75'); ... %3
	            hex2dec('09'), hex2dec('83'), hex2dec('2c'), hex2dec('1a'), hex2dec('1b'), hex2dec('6e'), hex2dec('5a'), hex2dec('a0'), hex2dec('52'), hex2dec('3b'), hex2dec('d6'), hex2dec('b3'), hex2dec('29'), hex2dec('e3'), hex2dec('2f'), hex2dec('84'); ... %4
	            hex2dec('53'), hex2dec('d1'), hex2dec('00'), hex2dec('ed'), hex2dec('20'), hex2dec('fc'), hex2dec('b1'), hex2dec('5b'), hex2dec('6a'), hex2dec('cb'), hex2dec('be'), hex2dec('39'), hex2dec('4a'), hex2dec('4c'), hex2dec('58'), hex2dec('cf'); ... %5
	            hex2dec('d0'), hex2dec('ef'), hex2dec('aa'), hex2dec('fb'), hex2dec('43'), hex2dec('4d'), hex2dec('33'), hex2dec('85'), hex2dec('45'), hex2dec('f9'), hex2dec('02'), hex2dec('7f'), hex2dec('50'), hex2dec('3c'), hex2dec('9f'), hex2dec('a8'); ... %6
	            hex2dec('51'), hex2dec('a3'), hex2dec('40'), hex2dec('8f'), hex2dec('92'), hex2dec('9d'), hex2dec('38'), hex2dec('f5'), hex2dec('bc'), hex2dec('b6'), hex2dec('da'), hex2dec('21'), hex2dec('10'), hex2dec('ff'), hex2dec('f3'), hex2dec('d2'); ... %7
	            hex2dec('cd'), hex2dec('0c'), hex2dec('13'), hex2dec('ec'), hex2dec('5f'), hex2dec('97'), hex2dec('44'), hex2dec('17'), hex2dec('c4'), hex2dec('a7'), hex2dec('7e'), hex2dec('3d'), hex2dec('64'), hex2dec('5d'), hex2dec('19'), hex2dec('73'); ... %8
	            hex2dec('60'), hex2dec('81'), hex2dec('4f'), hex2dec('dc'), hex2dec('22'), hex2dec('2a'), hex2dec('90'), hex2dec('88'), hex2dec('46'), hex2dec('ee'), hex2dec('b8'), hex2dec('14'), hex2dec('de'), hex2dec('5e'), hex2dec('0b'), hex2dec('db'); ... %9
	            hex2dec('e0'), hex2dec('32'), hex2dec('3a'), hex2dec('0a'), hex2dec('49'), hex2dec('06'), hex2dec('24'), hex2dec('5c'), hex2dec('c2'), hex2dec('d3'), hex2dec('ac'), hex2dec('62'), hex2dec('91'), hex2dec('95'), hex2dec('e4'), hex2dec('79'); ... %A
	            hex2dec('e7'), hex2dec('c8'), hex2dec('37'), hex2dec('6d'), hex2dec('8d'), hex2dec('d5'), hex2dec('4e'), hex2dec('a9'), hex2dec('6c'), hex2dec('56'), hex2dec('f4'), hex2dec('ea'), hex2dec('65'), hex2dec('7a'), hex2dec('ae'), hex2dec('08'); ... %B
	            hex2dec('ba'), hex2dec('78'), hex2dec('25'), hex2dec('2e'), hex2dec('1c'), hex2dec('a6'), hex2dec('b4'), hex2dec('c6'), hex2dec('e8'), hex2dec('dd'), hex2dec('74'), hex2dec('1f'), hex2dec('4b'), hex2dec('bd'), hex2dec('8b'), hex2dec('8a'); ... %C
	            hex2dec('70'), hex2dec('3e'), hex2dec('b5'), hex2dec('66'), hex2dec('48'), hex2dec('03'), hex2dec('f6'), hex2dec('0e'), hex2dec('61'), hex2dec('35'), hex2dec('57'), hex2dec('b9'), hex2dec('86'), hex2dec('c1'), hex2dec('1d'), hex2dec('9e'); ... %D
	            hex2dec('e1'), hex2dec('f8'), hex2dec('98'), hex2dec('11'), hex2dec('69'), hex2dec('d9'), hex2dec('8e'), hex2dec('94'), hex2dec('9b'), hex2dec('1e'), hex2dec('87'), hex2dec('e9'), hex2dec('ce'), hex2dec('55'), hex2dec('28'), hex2dec('df'); ... %E
	            hex2dec('8c'), hex2dec('a1'), hex2dec('89'), hex2dec('0d'), hex2dec('bf'), hex2dec('e6'), hex2dec('42'), hex2dec('68'), hex2dec('41'), hex2dec('99'), hex2dec('2d'), hex2dec('0f'), hex2dec('b0'), hex2dec('54'), hex2dec('bb'), hex2dec('16')];    %F

        numeroPalabrasKeyExp=4; % Por defecto se inicializa con bloques de 128bits
        RCj=[hex2dec('01'),hex2dec('02'),hex2dec('04'),hex2dec('08'),hex2dec('10'),hex2dec('20'),hex2dec('40'),hex2dec('80'),hex2dec('1b'),hex2dec('36'),hex2dec('6c'),hex2dec('d8'),hex2dec('ab'),hex2dec('4d')] % Description
        cerouno='01';

        SignPassword='';
    end

    properties (Access = public)
        alfabeto='abcdefghijklmnopqrstuvwxyz';
        probabilidadesAlfabeto=[.082,.015,.028,.043,.127,.022,.020,.061,.070,.002,.008,.040,.024,.067,.075,.019,.001,.060,.063,.091,.028,.010,.023,.001,.020,.001]
        metodoFuerzaBruta
    end


    methods (Access = private)

        function actualizarCritptoClasico(app)
            %Actualiza el boton cifrar/decifrar
            if strcmp(app.CriptoClasicoSeleccionado,'')
                app.CifrarDecifrarButton.Text='Cifrar/Decifrar';
            end
            if strcmp(app.CriptoClasicoSeleccionado(2),'0')
                app.CifrarDecifrarButton.Text='Cifrar';
            end
            if strcmp(app.CriptoClasicoSeleccionado(2),'1')
                app.CifrarDecifrarButton.Text='Decifrar';
            end
            %Limpia el área de resultados
            app.TextAreaResultado.Value='';
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%Criptosistemas modernos
        
        function limpiarCriptoModerna(app)
            app.ClaveEditField.Value='';
            app.IVEditField.Value='';
            app.InputImage.ImageSource='';
            app.OutputImage.ImageSource='';
            app.TrabajandoLabel.Text='';
        end
                
        function [al,dim]=img2ArregloLogico(~,img)
            dim=size(img);
            al=reshape(dec2bin(img),1,[])=='1';
        end

        function img=ArregloLogico2Img(~,al,dim)
            unocero='01';
            img=reshape(uint8(bin2dec(reshape(unocero(1+al),[],8))),dim);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%Inicio S-DES
        function [K1,K2]=construccionSubClavesSDES(app,clave)
            %Se asume que clave es una arreglo lógico de tamaño 10
            claveaux=clave(app.P10_sdes);
            claveaux1=claveaux(1:5);
            claveaux2=claveaux(6:10);
            claveaux1=circshift(claveaux1,-1);
            claveaux2=circshift(claveaux2,-1);
            claveaux=[claveaux1,claveaux2];
            K1=claveaux(app.P8_sdes);
            claveaux1=claveaux(1:5);
            claveaux2=claveaux(6:10);
            claveaux1=circshift(claveaux1,-2);
            claveaux2=circshift(claveaux2,-2);
            claveaux=[claveaux1,claveaux2];
            K2=claveaux(app.P8_sdes);
        end
        
        function output=funcionDeRondaSDES(app,R,SK)
            aux=xor(R(app.EP_sdes),SK);
            aux1=aux(1:4);
            aux2=aux(5:8);
            filaS0=bin2dec(num2str(aux1([1,4])))+1;
            colS0=bin2dec(num2str(aux1([2,3])))+1;
            filaS1=bin2dec(num2str(aux2([1,4])))+1;
            colS1=bin2dec(num2str(aux2([2,3])))+1;
            alpha1=dec2bin(app.S0_sdes(filaS0,colS0),2);
            alpha2=dec2bin(app.S1_sdes(filaS1,colS1),2);
            alpha=append(alpha1,alpha2)=='1';
            output=alpha(app.P4_sdes);
        end

        
        function textoSalida = SDES(app, textoEntrada, K1,K2)
            %Itinerario para la construcción de las subclaves K1,K2
            %[K1,K2]=app.construccionSubClavesSDES(clave);
            %Ronda 1
            
            textoaux1=textoEntrada(app.IP_sdes);
    
            %División del textoClaro en L y R, se asume que textoClaro es
            %un arreglo lógico de 8 bits
            L=textoaux1(1:4);
            R=textoaux1(5:8);
            
            ronda=xor(L,app.funcionDeRondaSDES(R,K1));
            %Ronda 2
            L=R;
            R=ronda;
            ronda=xor(L,app.funcionDeRondaSDES(R,K2));
            L=ronda;
            
            textoaux2=[L,R];
            textoSalida=textoaux2(app.IPinv_sdes);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%Fin S-DES

        %%%%%%%%%%%%%%%%%%%%%%%%%%%% DES
        function subClaves=construccionSubClavesDES(app,clave)
            %Se asume que clave es una arreglo lógico de tamaño 56
            claveaux=clave(app.PC1_des);
            subClaves=cell(1,16);
            lcsbits=[1,1,2,2,2,2,2,2,1,2,2,2,2,2,2,1];
            for ii=1:16
                claveauxL=circshift(claveaux(1:28),-lcsbits(ii));
                claveauxR=circshift(claveaux(29:56),-lcsbits(ii));
                claveaux=[claveauxL,claveauxR];
                subClaves{ii}=claveaux(app.PC2_des);
            end
        end

        function output=funcionDeRondaDES(app,R,SK)
            aux=xor(R(app.EP_des),SK);
            aux1=aux(1:6);
            aux2=aux(7:12);
            aux3=aux(13:18);
            aux4=aux(19:24);
            aux5=aux(25:30);
            aux6=aux(31:36);
            aux7=aux(37:42);
            aux8=aux(43:48);
            filaS1=bin2dec(num2str(aux1([1,6])))+1;
            colS1=bin2dec(num2str(aux1([2,3,4,5])))+1;
            filaS2=bin2dec(num2str(aux2([1,6])))+1;
            colS2=bin2dec(num2str(aux2([2,3,4,5])))+1;
            filaS3=bin2dec(num2str(aux3([1,6])))+1;
            colS3=bin2dec(num2str(aux3([2,3,4,5])))+1;
            filaS4=bin2dec(num2str(aux4([1,6])))+1;
            colS4=bin2dec(num2str(aux4([2,3,4,5])))+1;
            filaS5=bin2dec(num2str(aux5([1,6])))+1;
            colS5=bin2dec(num2str(aux5([2,3,4,5])))+1;
            filaS6=bin2dec(num2str(aux6([1,6])))+1;
            colS6=bin2dec(num2str(aux6([2,3,4,5])))+1;
            filaS7=bin2dec(num2str(aux7([1,6])))+1;
            colS7=bin2dec(num2str(aux7([2,3,4,5])))+1;
            filaS8=bin2dec(num2str(aux8([1,6])))+1;
            colS8=bin2dec(num2str(aux8([2,3,4,5])))+1;
            alpha1=dec2bin(app.S1_des(filaS1,colS1),4);
            alpha2=dec2bin(app.S2_des(filaS2,colS2),4);
            alpha3=dec2bin(app.S3_des(filaS3,colS3),4);
            alpha4=dec2bin(app.S4_des(filaS4,colS4),4);
            alpha5=dec2bin(app.S5_des(filaS5,colS5),4);
            alpha6=dec2bin(app.S6_des(filaS6,colS6),4);
            alpha7=dec2bin(app.S7_des(filaS7,colS7),4);
            alpha8=dec2bin(app.S8_des(filaS8,colS8),4);
            alpha=append(alpha1,alpha2,alpha3,alpha4,alpha5,alpha6,alpha7,alpha8)=='1';
            output=alpha(app.Pfr_des);
        end

         function textoSalida = DES(app, textoEntrada, subclaves)
            
            %Permutacion inicial
            %disp('Permutacion inicial')
            textoaux=textoEntrada(app.IP_des);
                
            %División del textoClaro en L y R, se asume que textoClaro es
            %un arreglo lógico de 64 bits
            %disp('Rondas')
            L=cell(1,17);
            R=cell(1,17);
            L{1}=textoaux(1:32);
            R{1}=textoaux(33:64);
            for ii=1:16
                L{ii+1}=R{ii};
                %disp('L_ii')
                %disp(L{ii+1})
                R{ii+1}=xor(L{ii},app.funcionDeRondaDES(R{ii},subclaves{ii}));
                %disp('R_ii')
                %disp(R{ii+1})
            end
            %disp('Permutacion final')
            textoaux=[R{17},L{17}];
            textoSalida=textoaux(app.IPinv_des); %permutacion final
         end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%Inicio 3-DES
        %Codificacion
        function textoSalida = CTRESDES(app, textoEntrada, subclavesK1,subclavesK2)
            textoSalida=app.DES(app.DES(app.DES(textoEntrada,subclavesK1),subclavesK2(end:-1:1)),subclavesK1);
        end
        %Decodificacion
        function textoSalida = DTRESDES(app, textoEntrada, subclavesK1,subclavesK2)
            textoSalida=app.DES(app.DES(app.DES(textoEntrada,subclavesK1(end:-1:1)),subclavesK2),subclavesK1(end:-1:1));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%AES
        %{
        function Salida=SubBytes(app,Estado)
            %Estado es un bloque de 4x4 de números de 0 a 255
            %Salida es un bloque de 4x4 de números de 0 a 255
            Salida=uint8(zeros(size(4,4)));
            for ii=1:size(Estado,1)
                for jj=1:size(Estado,2)
                    aux=dec2hex(Estado(ii,jj),2);
                    fila=hex2dec(aux(1))+1;
                    columna=hex2dec(aux(2))+1;
                    Salida(ii,jj)=app.sboxAES(fila,columna);
                end
            end
        end

        function Salida=ShiftRows(app,Estado)
            %Estado es un bloque de 4x4 de números de 0 a 255
            Salida=uint8(zeros(4,4));
            Salida(1,:)=Estado(1,:);
            Salida(2,:)=circshift(Estado(2,:),-1);
            Salida(3,:)=circshift(Estado(3,:),-2);
            Salida(4,:)=circshift(Estado(4,:),-3);
        end

        function salida=MixColumn(app,c)
            %c es un vector columna de 4x1 con números de 0 a 255
            t=uint8(zeros(4,1));
            for ii=1:4
                t(ii)=c(ii);
            end
            u=cell(4,1);
            t21=dec2bin(app.multiplicarAES(3,t(1)+1),8)=='1';
            t22=dec2bin(app.multiplicarAES(3,t(2)+1),8)=='1';
            t23=dec2bin(app.multiplicarAES(3,t(3)+1),8)=='1';
            t24=dec2bin(app.multiplicarAES(3,t(4)+1),8)=='1';
            t31=dec2bin(app.multiplicarAES(4,t(1)+1),8)=='1';
            t32=dec2bin(app.multiplicarAES(4,t(2)+1),8)=='1';
            t33=dec2bin(app.multiplicarAES(4,t(3)+1),8)=='1';
            t34=dec2bin(app.multiplicarAES(4,t(4)+1),8)=='1';
            u{1}=xor(xor(xor(t21,t32),dec2bin(t(3),8)),dec2bin(t(4),8));
            u{2}=xor(xor(xor(t22,t33),dec2bin(t(4),8)),dec2bin(t(1),8));
            u{3}=xor(xor(xor(t23,t34),dec2bin(t(1),8)),dec2bin(t(2),8));
            u{4}=xor(xor(xor(t24,t31),dec2bin(t(2),8)),dec2bin(t(3),8));
            salida=uint8(zeros(4,1));
            for ii=1:4
                salida(ii)=bin2dec(app.cerouno(u{ii}+1));
            end
        end

        function Salida=MixColumns(app,Estado)
            %Estado es un bloque de 4x4 de números de 0 a 255
            Salida=uint8(zeros(4,4));
            for ii=1:4
                Salida(ii,:)=app.MixColumn(Estado(ii,:));
            end
        end

        function salida=funciong(app,palabra,ronda)
            %palabra es un arreglo de 1x4 de números del 0 al 255
            %RotWord
            aux=circshift(palabra,-1);
            for ii=1:4
                aux0=dec2bin(aux(ii),8);
                fil=bin2dec(aux0(1:4))+1;
                col=bin2dec(aux0(5:8))+1;
                aux(ii)=app.sboxAES(fil,col);
            end
            aux0=dec2bin(aux(1),8)=='1';
            aux1=dec2bin(app.RCj(ronda),8)=='1';
            aux2=xor(aux0,aux1);
            aux(1)=bin2dec(num2str(aux2));
            salida=aux;
        end

        function Salida=KeyExpansion(app,Clave)
            %Clave es un arreglo fila de números del 0 al 255 ( Depende del tamaño de la clave)
            %Salida puede tener muchas palabras
            nr=app.numeroRondas;
            np=app.numeroPalabrasKeyExp;
            k=reshape(Clave,4,[])
            %cada columna de Clave conformará una palabra
            w=zeros((nr+1)*np,4);
            %inicializamos la ronda 0
            for ii=1:np
                w(ii,:)=transpose(k(:,ii));
            end
            for ii=1:nr
                aux0=app.funciong(w(np*ii,:),ii);
                aux1=reshape(dec2bin(aux0,8),1,[])=='1';
                aux2=reshape(dec2bin(w(np*(ii-1)+1,:),8),1,[])=='1';
                w(np*ii+1,:)=transpose(bin2dec(num2str(transpose(reshape(xor(aux1,aux2),8,[])))));
                for jj=2:np
                    aux1=aux2;
                    aux2=reshape(dec2bin(w(np*(ii-1)+jj,:),8),1,[])=='1';
                    w(np*ii+jj,:)=transpose(bin2dec(num2str(transpose(reshape(xor(aux1,aux2),8,[])))));
                end
            end
            Salida=w;
        end

        function Salida=AddRoundKey(app,ClaveExpandida,Estado,ronda)
            %ClaveRonda es un arreglo 4x4 de números del 0 al 255 ( 128-bit)
            %Estado es un arreglo 4x4 de números del 0 al 255 (128-bit)
            %Salida es un arreglo 4x4 de números del 0 al 255
            aux0=reshape(transpose(dec2bin(ClaveExpandida(4*ronda+1:4*(ronda+1),:),8)),1,[])=='1';
            aux1=reshape(transpose(dec2bin(Estado,8)),1,[])=='1';           
            Salida=reshape(bin2dec(num2str(transpose(reshape(xor(aux0,aux1),8,[])))),4,4);
        end
        
        function Salida=AES(app,textoClaro,Clave)
            %textoClaro es un arreglo lógico de 1x128
            %clave es un arreglo lógico de 1x128,1x192 o 1x256
            nr=app.numeroRondas;
            ClaveAux=reshape(bin2dec(num2str(transpose(reshape(Clave,8,[])))),1,[])
            ClaveExpandida=app.KeyExpansion(ClaveAux);            
            Estado=reshape(bin2dec(num2str(transpose(reshape(textoClaro,8,[])))),4,4)
            Estado=app.AddRoundKey(ClaveExpandida,Estado,0)
            for ii=1:nr-1
                Estado=app.SubBytes(Estado);
                Estado=app.ShiftRows(Estado);
                Estado=app.MixColumns(Estado);
                Estado=app.AddRoundKey(ClaveExpandida,Estado,ii);
            end
            Estado=app.SubBytes(Estado);
            Estado=app.ShiftRows(Estado);
            Estado=app.AddRoundKey(ClaveExpandida,Estado,nr);
            Salida=Estado;
        end
        %}
        function textoSalida=AES(app,textoClaro,Clave)
            %Clave en binario formato texto
            %textoClaro arreglo lógico
            %textoSalida: arreglo lógico
            auxTexto=app.cerouno(textoClaro+1);
            hexTexto=reshape(dec2hex(bin2dec(transpose(reshape(auxTexto,4,[])))),1,[]);
            hexClave=reshape(dec2hex(bin2dec(transpose(reshape(Clave,4,[])))),1,[]);
            textoSalida=Cipher(hexClave,hexTexto);
            textoSalida=reshape(transpose(dec2bin(hex2dec(transpose(textoSalida)),4)),1,[])=='1';
        end

        function textoSalida=AESinv(app,textoClaro,Clave)
            %Clave en binario
            %textoClaro arreglo lógico
            auxTexto=app.cerouno(textoClaro+1);
            hexTexto=reshape(dec2hex(bin2dec(transpose(reshape(auxTexto,4,[])))),1,[]);
            hexClave=reshape(dec2hex(bin2dec(transpose(reshape(Clave,4,[])))),1,[]);
            textoSalida=InvCipher(hexClave,hexTexto);
            textoSalida=reshape(transpose(dec2bin(hex2dec(transpose(textoSalida)),4)),1,[])=='1';
        end
        %%%%%%%%%%%%%%%%%%%%%%%%MODOS DE OPERACION
        %%%%%%%%%%%%%%%%%EBC
        function salida=ModoECB(app,entrada,codificar)
            aux=reshape(entrada,[],app.tamPalabraCriptoSistema);
            salida=[];
            switch app.CriptosistemaDropDown.Value
                case 'S-DES'
                    [K1,K2]=app.construccionSubClavesSDES(app.ClaveEditField.Value=='1');
                    for ii=1:size(aux,1)
                        if codificar
                            salida=[salida;app.SDES(aux(ii,:),K1,K2)];
                        else
                            salida=[salida;app.SDES(aux(ii,:),K2,K1)];
                        end
                    end
                case 'DES'
                    subclaves=app.construccionSubClavesDES(app.ClaveEditField.Value=='1');
                    for ii=1:size(aux,1)
                        if codificar
                            salida=[salida;app.DES(aux(ii,:),subclaves)];
                        else
                            salida=[salida;app.DES(aux(ii,:),subclaves(end:-1:1))];
                        end
                    end
                case '3-DES'
                    K=app.ClaveEditField.Value;
                    K1=K(1:64)==1;
                    K2=K(65:128)==1;
                    subclavesK1=app.construccionSubClavesDES(K1);
                    subclavesK2=app.construccionSubClavesDES(K2);
                    for ii=1:size(aux,1)
                        if codificar
                            salida=[salida;app.CTRESDES(aux(ii,:),subclavesK1,subclavesK2)];
                        else
                            salida=[salida;app.DTRESDES(aux(ii,:),subclavesK1,subclavesK2)];
                        end
                    end
                case 'AES'
                    K=app.ClaveEditField.Value;
                    for ii=1:size(aux,1)
                        if codificar
                            salida=[salida;app.AES(aux(ii,:),K)];
                        else
                            salida=[salida;app.AESinv(aux(ii,:),K)];
                        end
                    end
                salida=reshape(salida,size(entrada));


             end
                

        end

         %%%%%%%%%%%%%%%%%EBC
        function salida=ModoCBC(app,entrada,codificar)
            aux=reshape(entrada,[],app.tamPalabraCriptoSistema);
            salida=[];
            switch app.CriptosistemaDropDown.Value
                case 'S-DES'
                    [K1,K2]=app.construccionSubClavesSDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.SDES(xor(iv,aux(ii,:)),K1,K2);
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(iv,app.SDES(aux(ii,:),K2,K1))];
                            iv=aux(ii,:);
                        end
                    end
                case 'DES'
                    subclaves=app.construccionSubClavesDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.DES(xor(iv,aux(ii,:)),subclaves);
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(iv,app.DES(aux(ii,:),subclaves(end:-1:1)))];
                            iv=aux(ii,:);
                        end
                    end
                case '3-DES'
                    K=app.ClaveEditField.Value;
                    K1=K(1:64)==1;
                    K2=K(65:128)==1;
                    subclavesK1=app.construccionSubClavesDES(K1);
                    subclavesK2=app.construccionSubClavesDES(K2);
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.CTRESDES(xor(iv,aux(ii,:)),subclavesK1,subclavesK2);
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(iv,app.DTRESDES(aux(ii,:),subclavesK1,subclavesK2))];
                            iv=aux(ii,:);
                        end
                    end
                case 'AES'
                    K=app.ClaveEditField.Value;
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.AES(xor(iv,aux(ii,:)),K);
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(iv,app.AESinv(aux(ii,:),K))];
                            iv=aux(ii,:);
                        end
                    end
                salida=reshape(salida,size(entrada));
            end

        end

        %%%%%%%%%%%%%%%%%CFB
        function salida=ModoCFB(app,entrada,codificar)
            aux=reshape(entrada,[],app.tamPalabraCriptoSistema);
            salida=[];
            switch app.CriptosistemaDropDown.Value
                case 'S-DES'
                    [K1,K2]=app.construccionSubClavesSDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=xor(app.SDES(iv,K1,K2),aux(ii,:));
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(app.SDES(iv,K1,K2),aux(ii,:))];
                            iv=aux(ii,:);
                        end
                    end
                case 'DES'
                    subclaves=app.construccionSubClavesDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=xor(app.DES(iv,subclaves),aux(ii,:));
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(aux(ii,:),app.DES(iv,subclaves))];
                            iv=aux(ii,:);
                        end
                    end
                case '3-DES'
                    K=app.ClaveEditField.Value;
                    K1=K(1:64)==1;
                    K2=K(65:128)==1;
                    subclavesK1=app.construccionSubClavesDES(K1);
                    subclavesK2=app.construccionSubClavesDES(K2);
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=xor(app.CTRESDES(iv,subclavesK1,subclavesK2),aux(ii,:));
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(aux(ii,:),app.CTRESDES(iv,subclavesK1,subclavesK2))];
                            iv=aux(ii,:);
                        end
                    end
                case 'AES'
                    K=app.ClaveEditField.Value;
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=xor(app.AES(iv,K),aux(ii,:));
                            salida=[salida;iv];
                        else
                            salida=[salida;xor(aux(ii,:),app.AES(iv,K))];
                            iv=aux(ii,:);
                        end
                    end
                salida=reshape(salida,size(entrada));
            end

        end
        
        %%%%%%%%%%%%%%%%%OFB
        function salida=ModoOFB(app,entrada,codificar)
            aux=reshape(entrada,[],app.tamPalabraCriptoSistema);
            salida=[];
            switch app.CriptosistemaDropDown.Value
                case 'S-DES'
                    [K1,K2]=app.construccionSubClavesSDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.SDES(iv,K1,K2); 
                            salida=[salida;xor(iv,aux(ii,:))];
                        else
                            iv=app.SDES(iv,K1,K2); 
                            salida=[salida;xor(iv,aux(ii,:))];
                        end
                    end
                case 'DES'
                    subclaves=app.construccionSubClavesDES(app.ClaveEditField.Value=='1');
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.DES(iv,subclaves);
                            salida=[salida;xor(iv,aux(ii,:))];
                        else
                            iv=app.DES(iv,subclaves);
                            salida=[salida;xor(iv,aux(ii,:))];
                        end
                    end
                case '3-DES'
                    K=app.ClaveEditField.Value;
                    K1=K(1:64)==1;
                    K2=K(65:128)==1;
                    subclavesK1=app.construccionSubClavesDES(K1);
                    subclavesK2=app.construccionSubClavesDES(K2);
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        if codificar
                            iv=app.CTRESDES(iv,subclavesK1,subclavesK2);
                            salida=[salida;xor(iv,aux(ii,:))];
                        else
                            iv=app.CTRESDES(iv,subclavesK1,subclavesK2);
                            salida=[salida;xor(iv,aux(ii,:))];
                        end
                    end
                case 'AES'
                    K=app.ClaveEditField.Value;
                    iv=app.IVEditField.Value=='1';
                    for ii=1:size(aux,1)
                        iv=app.AES(iv,K);
                        salida=[salida;xor(iv,aux(ii,:))];
                    end
                salida=reshape(salida,size(entrada));
            end

        end
        
        %%%CTR MODE
        function salida=ModoCTR(app,entrada)
            aux=reshape(entrada,[],app.tamPalabraCriptoSistema);
            contador=randi(app.tamPalabraCriptoSistema,1,app.tamPalabraCriptoSistema);
            salida=[];
            switch app.CriptosistemaDropDown.Value
                case 'S-DES'
                    [K1,K2]=app.construccionSubClavesSDES(app.ClaveEditField.Value=='1');
                    for ii=1:size(aux,1)
                        Ti=dec2bin(mod(ii+contador(mod(ii,app.tamPalabraCriptoSistema)+1),app.tamPalabraCriptoSistema),app.tamPalabraCriptoSistema)==1;
                        salida=[salida;xor(app.SDES(Ti,K1,K2),aux(ii,:))];
                    end
                case 'DES'
                    subclaves=app.construccionSubClavesDES(app.ClaveEditField.Value=='1');
                    for ii=1:size(aux,1)
                        Ti=dec2bin(mod(ii+contador(mod(ii,app.tamPalabraCriptoSistema)+1),app.tamPalabraCriptoSistema),app.tamPalabraCriptoSistema)==1;
                        salida=[salida;xor(app.DES(Ti,subclaves),aux(ii,:))];
                    end
                case '3-DES'
                    K=app.ClaveEditField.Value;
                    K1=K(1:64)==1;
                    K2=K(65:128)==1;
                    subclavesK1=app.construccionSubClavesDES(K1);
                    subclavesK2=app.construccionSubClavesDES(K2);
                    for ii=1:size(aux,1)
                        Ti=dec2bin(mod(ii+contador(mod(ii,app.tamPalabraCriptoSistema)+1),app.tamPalabraCriptoSistema),app.tamPalabraCriptoSistema)==1;
                        salida=[salida;xor(app.CTRESDES(Ti,subclavesK1,subclavesK2),aux(ii,:))];
                    end
                case 'AES'
                    K=app.ClaveEditField.Value;
                    for ii=1:size(aux,1)
                        Ti=dec2bin(mod(ii+contador(mod(ii,app.tamPalabraCriptoSistema)+1),app.tamPalabraCriptoSistema),app.tamPalabraCriptoSistema)==1;
                        salida=[salida;xor(app.AES(Ti,K),aux(ii,:))];
                    end
                salida=reshape(salida,size(entrada));
            end

        end
        
        
        function p = generarPrimo(app,exponente)
            p=randi([3,2^exponente-1],1,1);
            while ~isprime(p)
                p=randi([3,2^exponente-1],1,1);
            end            
        end
        
        function t= inversoMultiplicativo(app,numero,modulo)
            a=modulo;
            b=mod(numero,modulo);
            a0=a;
            b0=b;
            t0=0;
            t=1;
            q=floor(a0/b0);
            r=mod(a0-q*b0,modulo);
            while r>0
                temp=mod(t0-q*t,modulo);
                t0=t;
                t=temp;
                a0=b0;
                b0=r;
                q=floor(a0/b0);
                r=mod(a0-q*b0,modulo);
            end
            if mod(t*numero,modulo)~=1
                error('No se pudo calcular efectivamente el inverso.')
            end
        end
        
        function output=puntoSobreCurvaEliptica(~,a,b,p)
            y2=@(x)mod(x^3+a*x+b,p);
            output=[];
            while size(output,1)==0
                x=randi([0,p-1],1,1);
                js=mod(jacobiSymbol(y2(x),p),p);
                if js==1
                    break;
                end
            end
            for ii=1:p-1
                if mod(ii*ii-y2(x),p)==0
                    output=[x,ii];
                    return
                end
            end
        end

        function output = sumaEliptica(app,puntoP,puntoQ,a,b,p)
            x1=puntoP(1);
            y1=puntoP(2);
            x2=puntoQ(1);
            y2=puntoQ(2);
            if isequal(puntoP,puntoQ)
                inverso=app.inversoMultiplicativo(mod(2*y1,p),p);
                lambda=mod(mod(mod(3*mod(x1^2,p),p)+a,p)*inverso,p);
            else
                inverso=app.inversoMultiplicativo(mod(x2-x1,p),p);
                lambda=mod((y2-y1)*inverso,p);
            end
            x3=mod(mod(lambda^2,p)-x1-x2,p);
            y3=mod(mod(lambda*(x1-x3),p)-y1,p);
            output=[x3,y3];
        end

        function output=multiplicacionPorEscalarEliptica(app,punto,escalar,a,b,p)
            output=punto;
            for ii=1:escalar-1
                output=app.sumaEliptica(output,punto,a,b,p);
            end
        end
        
        function numpuntos = contarPuntosEliptica(~,a,b,p)
            y2=@(x)mod(x^3+a*x+b,p);
            cuadrados=[];
            for ii=0:p-1
                cuadrados=[cuadrados,mod(ii^2,p)];
            end
            cuadrados=unique(cuadrados);
            numpuntos=0;
            for ii=0:p-1
                if ~isempty(find(cuadrados==y2(ii),1))
                    numpuntos=numpuntos+1;
                end
            end
            numpuntos=2*numpuntos+1;
        end
        
        function output = MultiplicacionPorEscalarRapida(app,punto,escalar,a,b,p)
            binario=dec2bin(escalar);
            if escalar==1
                output=punto;
                return
            end
            zz=punto;
            for ii=2:length(binario)
                zz=app.sumaEliptica(zz,zz,a,b,p);
                if strcmp(binario(ii),'1')
                    zz=app.sumaEliptica(zz,punto,a,b,p);
                end
            end
            output=zz;
        end
        
        %Teorema chino del resto
        function x = TCR(app,a,m)
            %a y m son vectores de manera que x=a(i) mod m(i)
            for ii=1:length(m)-1
                for jj=ii+1:length(m)
                    if gcd(m(ii),m(jj))~=1
                        error(append('Los valores de m: ',num2str(m(ii)),' y ', num2str(m(jj)) ,', en las posiciones ',num2str(ii),' y ',num2str(jj),', no son primos relativos.'))
                    end
                end
            end
            m=int64(m);
            a=int64(a);
            M=int64(prod(m));
            Mi=int64(M./m);
            y=zeros(size(m));
            for ii=1:length(m)
                y(ii)=app.inversoMultiplicativo(double(mod(Mi(ii),m(ii))),double(m(ii)));
            end
            y=int64(y);
            x=mod(sum(mod(mod(a.*Mi,M).*y,M)),M);
            x=double(x);
        end
        
        %calcula a^b (mod modulo)
        function potencia=potenciaModular(~,a,b,modulo)
            a=mod(a,modulo);
            binario=dec2bin(b);
            if b==0
                potencia=1;
                return
            end
            if b==1
                potencia=a;
                return
            end
            z=a;
            for ii=2:length(binario)
                z=mod(z*z,modulo);
                if strcmp(binario(ii),'1')
                    z=mod(z*a,modulo);
                end
            end
            potencia=z;
        end
        
        function raices= raiz2_Primo3m4(app,cuadrado,primo)
            raiz=app.potenciaModular(cuadrado,(primo+1)/4,primo);
            raices=[raiz,mod(-raiz,primo)];
        end

        function raices=raiz2_pq3m4(app,cuadrado,p,q)
            a=[app.raiz2_Primo3m4(cuadrado,p),app.raiz2_Primo3m4(cuadrado,q)];
            raiz1=app.TCR(a([1,3]),[p,q]);
            raiz2=app.TCR(a([2,3]),[p,q]);
            raiz3=app.TCR(a([1,4]),[p,q]);
            raiz4=app.TCR(a([2,4]),[p,q]);
            raices=[raiz1,raiz2,raiz3,raiz4];
        end
        
        function results = revisarPuntoEnEliptica(app,punto,a,b,p)
            y2=@(x)mod(mod(mod(x*x,p)*x,p)+mod(a*x,p)+b,p);
            results=y2(punto(1))==mod(punto(2)^2,p);            
        end
        
        function binario = hexAbin(app,hexadecimal)
            binario='';
            binario=append(binario,dec2bin(hex2dec(hexadecimal(1))));
            for ii=2:length(hexadecimal)
                binario=append(binario,dec2bin(hex2dec(hexadecimal(ii)),4));
            end
        end
        

        function potencia=potenciaModularHex(app,a,b,modulo)
            %a,b,modulo están en hexadecimal
            %devuelve la potencia en hexadecimal
            a=hex2dec(a);
            modulo=hex2dec(modulo);
            a=mod(a,modulo);
            binario=app.hexAbin(b);
            if b==0
                potencia=1;
                return
            end
            if b==1
                potencia=a;
                return
            end
            z=a;
            for ii=2:length(binario)
                z=mod(z*z,modulo);
                if strcmp(binario(ii),'1')
                    z=mod(z*a,modulo);
                end
            end
            potencia=dec2hex(z);
        end
        
        function resultado = modHex(app,numero,modulo)
            %Tanto numero como modulo estan en hexadecimal
            %resultado también será hexadecimal
            resultado=0;
            modulo=hex2dec(modulo);
            for ii=length(numero)-1:-1:0
                resultado=mod(resultado+mod(hex2dec(numero(length(numero)-ii))*app.potenciaModular(16,ii,modulo),modulo),modulo);
            end
            resultado=dec2hex(resultado);
        end
    end
    
    methods (Access = public)


        function tabla=subcadenasN(~,texto,n)
            if length(texto)<n
                tabla={'NO EXISTE',0,0};
                return
            end
            subcad={};
            for i=1:length(texto)-n+1
                subcad{end+1}=texto(i:i+n-1);
            end
            subcadUnicas=unique(subcad);
            contsubcadUnicas={};
            porcentaje={};
            for i=1:length(subcadUnicas)
                cont=0;
                for j=1:length(subcad)
                    if strcmp(subcadUnicas{i},subcad{j})
                        cont=cont+1;
                    end
                end
                contsubcadUnicas{end+1}=cont;
                porcentaje{end+1}=cont/(length(texto)-n+1);
            end
            tabla=sortrows([subcadUnicas;contsubcadUnicas;porcentaje]',2,'descend');
        end

        function l=NumeroALetra(app,n)
            l=app.alfabeto(n+1);
        end

        function n=LetraANumero(app,l)
            n=strfind(app.alfabeto,l)-1;
        end

        function [r,s,t]=ExtendedEuclideanAlgorithm(~,a,b)
            a0=a;
            b0=b;
            t0=0;
            t=1;
            s0=1;
            s=0;
            q=floor(a0/b0);
            r=a0-q*b0;
            while r>0
                temp=t0-q*t;
                t0=t;
                t=temp;
                temp=s0-q*r;
                s0=s;
                s=temp;
                a0=b0;
                b0=r;
                q=floor(a0/b0);
                r=a0-q*b0;
            end
            r=b0;
        end
        
        function C=MultMatMod(~,A,B,m)
            if length(A(1,:))==length(B(:,1))
                C=zeros(length(A(:,1)),length(B(1,:)));
                for i=1:length(A(:,1))
                    for j=1:length(B(1,:))
                        acum=0;
                        for k=1:length(A(1,:))
                            acum=mod(acum+mod(A(i,k)*B(k,j),m),m);
                        end
                        C(i,j)=acum;
                    end
                end
            else
                C=nan;%displayerror
            end
        end

        function d=detMod(app,A,m)
            if size(A)==size(zeros(1))
                d=A;
                return
            end
            if size(A)==size(zeros(2))
                d=mod(mod(A(1,1)*A(2,2),m)-mod(A(1,2)*A(2,1),m),m);
                return
            end
            n=size(A,1);
            if size(A)==size(zeros(n))
                d=0;
                for i=1:n
                    d=mod(d+mod(power(-1,i+1)*A(1,i)*detMod(app,A(2:end,[1:i-1,i+1:end]),m),m),m);
                end
            else
                d=nan;
            end
        end

        function C=InversaMod(app,Mat,m)
            d=app.detMod(Mat,m);
            if gcd(mod(d,m),m)~=1
                C=nan;
                return
            end
            n=size(Mat,1);
            Co=zeros(n);
            for i=1:n
                for j=1:n
                   Co(i,j)=power(-1,i+j)*app.detMod(Mat([1:i-1,i+1:end],[1:j-1,j+1:end]));
                end
            end
            Adj=transpose(Co);
            [~,~,dinv]=app.ExtendedEuclideanAlgorithm(m,d);
            dinv=mod(dinv,m);
            C=mod(dinv*Adj,m);
        end

        function C=InversaModGauss(app,Mat,M)
           A=[Mat, eye(size(Mat))];
            [m,n]=size(Mat);
            for j=1:m-1
                
                for z=j+1:m
                    if gcd(A(j,j),M)~=1
                       t=A(j,:);
                       A(j,:)=A(z,:);
                       A(z,:)=t;
                    end
                    
                end
                
                for i=j+1:m
                    [~,~,invAjj]=app.ExtendedEuclideanAlgorithm(M,A(j,j));
                    A(i,:)=mod(A(i,:)-mod(A(j,:)*mod(A(i,j)*invAjj,M),M),M);
                    
                    
                end
                
            end
            
            for j=m:-1:2
                for i=j-1:-1:1
                    [~,~,invAjj]=app.ExtendedEuclideanAlgorithm(M,A(j,j));
                    A(i,:)=mod(A(i,:)-mod(A(j,:)*mod(A(i,j)*invAjj,M),M),M);
                end
            end

            for s=1:m
                [~,~,invAss]=app.ExtendedEuclideanAlgorithm(M,A(s,s));
                A(s,:)=mod(A(s,:)*invAss,M);
            end
            C=A(:,n+1:end);
        end 
                  
       function textoCifrado=DesplazamientoCifrar(app,texto,clavenumerica)
            textoCifrado='';
            for i=1:length(texto)
                textoCifrado=append(textoCifrado,NumeroALetra(app,mod(LetraANumero(app,texto(i))+clavenumerica,26)));
            end
        end

        function textoDecifrado=DesplazamientoDecifrar(app,texto,clavenumerica)
            textoDecifrado='';
            for i=1:length(texto)
                textoDecifrado=append(textoDecifrado,NumeroALetra(app,mod(LetraANumero(app,texto(i))-clavenumerica,26)));
            end
        end


        function textoCifrado=SustitucionCifrar(app,texto,clave)
            textoCifrado='';
            for i=1:length(texto)
                textoCifrado=append(textoCifrado,clave(LetraANumero(app,texto(i))+1));
            end
        end

        function textoDecifrado=SustitucionDecifrar(app,texto,clave)
            textoDecifrado='';
            for i=1:length(texto)
                textoDecifrado=append(textoDecifrado,NumeroALetra(app,strfind(clave,texto(i))-1));
            end
        end

        function textoCifrado=PermutacionCifrar(~,texto,clave)
            n=length(clave);
            textoaux=pad(texto,n*ceil(length(texto)/n),'right','x');
            textoMat=reshape(textoaux,n,[]);
            textoCifradoMat=reshape(textoaux,n,[]);
            [ni,nj]=size(textoMat);
            for j=1:nj
                for i = 1:ni
                    textoCifradoMat(i,j)=textoMat(clave(i),j);
                end
            end
            textoCifrado=reshape(textoCifradoMat,1,[]);
        end

        function textoDecifrado=PermutacionDecifrar(~,texto,clave)
            n=length(clave);
            textoaux=pad(texto,n*ceil(length(texto)/n),'right','x');
            textoMat=reshape(textoaux,n,[]);
            textoDecifradoMat=reshape(textoaux,n,[]);
            [ni,nj]=size(textoMat);
            for j=1:nj
                for i = 1:ni
                    textoDecifradoMat(clave(i),j)=textoMat(i,j);
                end
            end
            textoDecifrado=reshape(textoDecifradoMat,1,[]);
        end
        
        function reporte=PermutacionCriptoanalisis(~,texto)
            reporte='';
            titulo=upper('Para el sistema de Permutación:');
            reporte=append(reporte,titulo,newline);
        end

        function textoCifrado=AfinCifrar(app,texto,clave)
            textoCifrado='';
            for i=1:length(texto)
                textoCifrado=append(textoCifrado,NumeroALetra(app,mod(clave(1)*LetraANumero(app,texto(i))+clave(2),26)));
            end
        end

        function textoDecifrado=AfinDecifrar(app,texto,clave)
            textoDecifrado='';
            [~,~,inversoa]=app.ExtendedEuclideanAlgorithm(26,clave(1));
            for i=1:length(texto)
                textoDecifrado=append(textoDecifrado,NumeroALetra(app,mod(inversoa*(LetraANumero(app,texto(i))-clave(2)),26)));
            end
        end

        function textoCifrado=VigenereCifrar(app,texto,clave)
            textoCifrado='';
            for i=1:length(texto)
                if mod(i,length(clave))==0
                    indiceClave=length(clave);
                else
                    indiceClave=mod(i,length(clave));
                end
                textoCifrado=append(textoCifrado,app.NumeroALetra(mod(app.LetraANumero(texto(i))+app.LetraANumero(clave(indiceClave)),26)));
            end
        end
        
        function textoDecifrado=VigenereDecifrar(app,texto,clave)
            textoDecifrado='';
            for i=1:length(texto)
                if mod(i,length(clave))==0
                    indiceClave=length(clave);
                else
                    indiceClave=mod(i,length(clave));
                end
                textoDecifrado=append(textoDecifrado,app.NumeroALetra(mod(app.LetraANumero(texto(i))-app.LetraANumero(clave(indiceClave)),26)));
            end
        end


        function textoCifrado=HillCifrar(app,texto,H)
            n=length(H);
            textoaux=pad(texto,n*ceil(length(texto)/n),'right','x');
            textoMat=transpose(reshape(textoaux,n,[]));
            mataux=arrayfun(@(x)LetraANumero(app,x),textoMat);
            matCifrada=app.MultMatMod(mataux,H,26);
            textoCifradoMat=arrayfun(@(x)NumeroALetra(app,x),matCifrada);
            textoCifrado=reshape(transpose(textoCifradoMat),1,[]);
        end

        function textoDecifrado=HillDecifrar(app,texto,H)
            n=length(H);
            invH=app.InversaModGauss(H,26);
            %Decodificación
            textoaux=pad(texto,n*ceil(length(texto)/n),'right','x');
            textoMat=transpose(reshape(textoaux,n,[]));
            mataux=arrayfun(@(x)LetraANumero(app,x),textoMat);
            matDecifrada=app.MultMatMod(mataux,invH,26);
            textoDecifradoMat=arrayfun(@(x)NumeroALetra(app,x),matDecifrada);
            textoDecifrado=reshape(transpose(textoDecifradoMat),1,[]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            
            selection = uiconfirm(app.CriptUNUIFigure,'¿Desea cerrar la aplicación?',...
                    'CriptUN'); 
            switch selection 
                case 'OK'
                    closereq();
                case 'Cancel'
                    return 
            end
            delete(app)
        end

        % Menu selected function: CifrarMenu, CifrarMenu_7
        function DesplazamientoCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Desplazamiento - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='00' ;%00: Desplazamiento - Cifrar;
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese un número entero.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu, DecifrarMenu_7
        function DesplazamientoDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Desplazamiento - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='01' ;%01: Desplazamiento - Decifrar;
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese un número entero.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: CifrarMenu_2, CifrarMenu_8
        function SustitucionCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Sustitución - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='10' ;%10: Sustitución - Cifrar;
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese una permutación del alfabeto.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu_2, DecifrarMenu_8
        function SustitucionDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Sustitución - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='11' ;%00: Desplazamiento - Cifrar;
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese una permutación del alfabeto.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: CifrarMenu_3, CifrarMenu_9
        function PermutacionCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Permutación - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='20';
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese una permutación de números (con el formato de los arreglos en Matlab).';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu_3, DecifrarMenu_9
        function PermutacionDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Permutación - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='21';
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese una permutación de números (con el formato de los arreglos en Matlab).';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: CifrarMenu_10, CifrarMenu_4
        function AfinCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Afín - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='30';
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese un arreglo de números enteros de la forma a,b. Recuerde que <a> debe ser primo relativo con 26.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu_10, DecifrarMenu_4
        function AfinDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Afin - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='31';
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese un arreglo de números enteros de la forma a,b. Recuerde que <a> debe ser primo relativo con 26.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: CifrarMenu_11, CifrarMenu_5
        function VigenereCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Vigenère - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='40';
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese una cadena de texto.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu_11, DecifrarMenu_5
        function VigenereDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Vigenère - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='41';
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese una cadena de texto.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: CifrarMenu_12, CifrarMenu_6
        function HillCifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Hill - Cifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='50';
            app.GenerarClaveButton.Enable=1;
            app.TextAreaClave.Placeholder='Ingrese una matriz de números. Los valores entre columnas van intercalados con comas o espacios, mientras que los valores entre filas se separan por un enter(nueva línea). Es el mismo formato que usa matlab.';
            app.actualizarCritptoClasico();
        end

        % Menu selected function: DecifrarMenu_12, DecifrarMenu_6
        function HillDecifrarSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.CriptoClasicaTab;
            app.Label.Text="Criptosistema seleccionado: Hill - Decifrar";
            app.Label.FontColor='black';
            app.CriptoClasicoSeleccionado='51';
            app.GenerarClaveButton.Enable=0;
            app.TextAreaClave.Placeholder='Ingrese una matriz de números. Los valores entre columnas van intercalados con comas o espacios, mientras que los valores entre filas se separan por un enter(nueva línea). Es el mismo formato que usa matlab.';
            app.actualizarCritptoClasico();
        end

        % Button pushed function: LimpiarTextoButton
        function LimpiarTextoButtonPushed(app, event)
            app.TextAreaTexto.Value="";
        end

        % Button pushed function: LimpiarClaveButton
        function LimpiarClaveButtonPushed(app, event)
            app.TextAreaClave.Value="";
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.TextAreaResultado.Value='';
        end

        % Button pushed function: GenerarClaveButton
        function GenerarClaveButtonPushed(app, event)
            switch app.CriptoClasicoSeleccionado
                case '00'
                    app.TextAreaClave.Value=int2str(randi(26));
                
                case '10'
                    rp=randperm(26);
                    alfabetoPerm='';
                    for i=1:26
                        alfabetoPerm=append(alfabetoPerm,app.alfabeto(rp(i)));
                    end
                    app.TextAreaClave.Value=alfabetoPerm;
                
                case '20'
                    tam=inputdlg('Ingrese la longitud de la clave:','Generar clave');
                    figure(app.CriptUNUIFigure);
                    if isempty(tam)
                        return
                    end
                    archivotemporal=tempname;
                    mat=randperm(str2double(tam));
                    writematrix(mat,archivotemporal);
                    app.TextAreaClave.Value=fileread(append(archivotemporal,'.txt'));
                    delete(append(archivotemporal,'.txt'));
                
                
                case '30'
                    a=26;
                    while(gcd(a,26)~=1)
                        a=randi(26);
                    end
                    app.TextAreaClave.Value=append(int2str(a),',',int2str(randi(26)));
                
                
                case '40'
                    tam=inputdlg('Ingrese la longitud de la clave:','Generar clave');
                    figure(app.CriptUNUIFigure);
                    if isempty(tam)
                        return
                    end
                    tam=str2double(tam);
                    if isnan(tam)||tam<=0
                        uialert(app.CriptUNUIFigure,'La longitud ingresada no es válida.','Error');
                    end
                    app.TextAreaClave.Value=char(randi([97 122],1,tam));
                
                case '50'
                    entrada=inputdlg('Ingrese la dimensión de la matriz:','Generar clave');
                    figure(app.CriptUNUIFigure);
                    if isempty(entrada)
                        return
                    end
                    tam=str2double(entrada);
                    if tam<1
                        uialert(app.CriptUNUIFigure,'El número ingresado debe ser un entero positivo.','Error');
                        return
                    end
                    if tam<10
                        matriz=randi(26,tam,tam)-1;

                        while gcd(app.detMod(matriz,26),26)~=1
                            matriz=randi(26,tam,tam)-1;
                        end
                    else
                        generador=@(M)M(randperm(length(M)),:);
                        matriz=generador(eye(tam));
                    end

                    archivotemporal=tempname;
                    writematrix(matriz,archivotemporal);
                    app.TextAreaClave.Value=fileread(append(archivotemporal,'.txt'));
                    delete(append(archivotemporal,'.txt'));
            end
        end

        % Button pushed function: CargarTextoButton
        function CargarTextoButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            app.TextAreaTexto.Value=fileread(fullfile(path,file));
        end

        % Button pushed function: CargarClaveButton
        function CargarClaveButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            app.TextAreaClave.Value=fileread(fullfile(path,file));
        end

        % Button pushed function: CifrarDecifrarButton
        function CifrarDecifrarButtonPushed(app, event)
             %Revisar el texto: Automáticamente eliminará los espacios, los signos
            %de puntuación y los números, por lo que solo hay que revisar que el texto ingresado tenga longitud mayor que 0.
            texto=lower(regexprep(convertStringsToChars(strjoin(app.TextAreaTexto.Value)),'[\W\d\r\n]',''));
            if isempty(texto)
                uialert(app.CriptUNUIFigure,'No ingresó ningún texto o el texto ingresado no es válido.','Error');
                return
            end
            resultado='';
            switch app.CriptoClasicoSeleccionado
                case '00'
                    clavenumerica=str2double(strjoin(app.TextAreaClave.Value));
                    if isnan(clavenumerica)
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. Revise que sea un número entero e intente nuevamente','Error');
                        return
                    end
                    resultado=upper(DesplazamientoCifrar(app,texto,clavenumerica));
                case '01'
                    clavenumerica=str2double(strjoin(app.TextAreaClave.Value));
                    if isnan(clavenumerica) 
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. Revise que sea un número entero e intente nuevamente','Error');
                        return
                    end
                    resultado=DesplazamientoDecifrar(app,texto,clavenumerica);
                case '10'
                    %Se tiene que revisar que la clave sea una permutación del
                    %alfabeto
                    clave=lower(convertStringsToChars(strjoin(app.TextAreaClave.Value)));
                    if ~strcmp(sort(clave),app.alfabeto)
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es una permutación del alfabeto.','Error');
                        return
                    end
                    resultado=upper(SustitucionCifrar(app,texto,clave));
                case '11'
                    %Se tiene que revisar que la clave sea una permutación del
                    %alfabeto
                    clave=lower(convertStringsToChars(strjoin(app.TextAreaClave.Value)));
                    if ~strcmp(sort(clave),app.alfabeto)
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es una permutación del alfabeto.','Error');
                        return
                    end
                    resultado=SustitucionDecifrar(app,texto,clave);
                case '20'
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    clave=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    if ~isequal(sort(clave),[1:length(clave)])
                        uialert(app.CriptUNUIFigure,append('La clave ingresada no es una permutación de números del 1 al ',string(length(clave)),'.'),'Error');
                        return
                    end
                    resultado=upper(PermutacionCifrar(app,texto,clave));
                case '21'
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    clave=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    if ~isequal(sort(clave),[1:length(clave)])
                        uialert(app.CriptUNUIFigure,append('La clave ingresada no es una permutación de números del 1 al ',string(length(clave)),'.'),'Error');
                        return
                    end
                    resultado=PermutacionDecifrar(app,texto,clave);
                case '30'
                    %La clave debe ser un vector de dimensión 2 de la forma [a,b]
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    clave=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    %clave=str2double(app.TextAreaClave.Value);
                    if length(clave)~=2||isnan(clave(1))||isnan(clave(2))
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es un arreglo de dimensión 2.','Error');
                        return
                    end
                    %El mcd(a,26) debe ser 1
                    if gcd(clave(1),26)~=1
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. El primer valor del arreglo debe ser primo relativo de 26.','Error');
                        return
                    end
                    resultado=upper(AfinCifrar(app,texto,clave));
                case '31'
                    %La clave debe ser un vector de dimensión 2 de la forma [a,b]
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    clave=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    %clave=str2double(app.TextAreaClave.Value);
                    if length(clave)~=2||isnan(clave(1))||isnan(clave(2))
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es un arreglo de dimensión 2.','Error');
                        return
                    end
                    %El mcd(a,26) debe ser 1
                    if gcd(clave(1),26)~=1
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. El primer valor del arreglo debe ser primo relativo de 26.','Error');
                        return
                    end
                    resultado=AfinDecifrar(app,texto,clave);
                case '40'
                    clave=lower(regexprep(convertStringsToChars(strjoin(app.TextAreaClave.Value)),'[\W\d\r\n]',''));
                    if isempty(clave)||~isempty(regexprep(convertStringsToChars(strjoin(app.TextAreaClave.Value)),'[\w]',''))
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida.','Error');
                        return
                    end
                    resultado=upper(VigenereCifrar(app,texto,clave));
                case '41'
                    clave=lower(regexprep(convertStringsToChars(strjoin(app.TextAreaClave.Value)),'[\W\d\r\n]',''));
                    if isempty(clave)||~isempty(regexprep(convertStringsToChars(strjoin(app.TextAreaClave.Value)),'[\w]',''))
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida.','Error');
                        return
                    end
                    resultado=VigenereDecifrar(app,texto,clave);
                case '50'
                    %La clave es una matriz, que se ingresará de la misma manera
                    %que se ingresan las matrices en matlab, excluyendo los []
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    H=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    if sum(isnan(H(:)))==1
                        uialert(app.CriptUNUIFigure,'La matriz ingresada no es una matriz válida. Revise que todos los valores sean números enteros','Error');
                        return
                    end
                    if gcd(app.detMod(H,26),26)~=1
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. El determinante de la matriz debe ser primo relativo con 26.','Error');
                        return
                    end
                    resultado=upper(HillCifrar(app,texto,H));
                case '51'
                    %La clave es una matriz, que se ingresará de la misma manera
                    %que se ingresan las matrices en matlab, excluyendo los []
                    archivotemporal=tempname;
                    writecell(app.TextAreaClave.Value, archivotemporal, 'QuoteStrings',false);
                    H=round(readmatrix(archivotemporal));
                    delete(append(archivotemporal,'.txt'));
                    if sum(isnan(H(:)))==1
                        uialert(app.CriptUNUIFigure,'La matriz ingresada no es una matriz válida. Revise que todos los valores sean números enteros','Error');
                        return
                    end
                    if gcd(mod(round(det(H)),26),26)~=1
                        uialert(app.CriptUNUIFigure,'La clave ingresada no es válida. El determinante de la matriz debe ser primo relativo con 26.','Error');
                        return
                    end
                    resultado=HillDecifrar(app,texto,H);
                otherwise
                    uialert(app.CriptUNUIFigure,'Ningún criptosistema fue seleccionado.','Error');
            end
            app.TextAreaResultado.Value=resultado;
            
        end

        % Button pushed function: GuardarResultadoButton
        function GuardarResultadoButtonPushed(app, event)
            if strcmp(app.CriptoClasicoSeleccionado,'')
                uialert(app.CriptUNUIFigure,'No es posible guardar el resultado porque no se seleccionó ningún criptosistema.','Error');
                return
            end

            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar resultado','Guardar todo','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Guardar resultado'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar resultado',append('Texto - ',app.Label.Text,'.txt'));
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writecell(app.TextAreaResultado.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Guardar todo'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar todo','Reporte.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    texto=join(string(app.TextAreaTexto.Value),newline);
                    clave=join(string(app.TextAreaClave.Value),newline);
                    resultado=join(string(app.TextAreaResultado.Value),newline);
                    reporte=join([append(char(9),upper(app.Label.Text)),'Texto original:',texto,'Clave:',clave,'Resultado:',resultado],newline);
                    writematrix(reporte, fullfile(path,file), 'QuoteStrings',false);

                case 'Cancelar'
                    return
            end
            
        end

        % Button pushed function: CopiarButton
        function CopiarButtonPushed(app, event)
            cadena=join(string(app.TextAreaResultado.Value),newline);
            clipboard('copy',cadena);
        end

        % Button pushed function: PegarButton
        function PegarButtonPushed(app, event)
            app.TextAreaTexto.Value=clipboard('paste');
        end

        % Close request function: CriptUNUIFigure
        function CriptUNUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.CriptUNUIFigure,'¿Desea cerrar la aplicación?',...
                    'CriptUN'); 
            switch selection 
                case 'OK'
                    closereq();
                case 'Cancel'
                    return 
            end
            delete(app)
            
            
        end

        % Menu selected function: SustitucionMenu_3
        function SustitucionMenu_3Selected(app, event)
            app.criptoAnalisisSustitucionWindow=CriptoAnalisisSustitucionWindow(app);
        end

        % Callback function
        function SustitucinButtonPushed(app, event)
            app.criptoAnalisisSustitucionWindow=CriptoAnalisisSustitucionWindow(app);
        end

        % Menu selected function: DesplazamientoMenu_3
        function DesplazamientoMenu_3Selected(app, event)
            app.metodoFuerzaBruta='Desplazamiento';
            app.criptoAnalisisFuerzaBrutaWindow=CriptoAnalisisFuerzaBrutaWindow(app);
        end

        % Menu selected function: AfnMenu_3
        function AfnMenu_3Selected(app, event)
            app.metodoFuerzaBruta='Afin';
            app.criptoAnalisisFuerzaBrutaWindow=CriptoAnalisisFuerzaBrutaWindow(app);
        end

        % Menu selected function: VigenreMenu_3
        function VigenreMenu_3Selected(app, event)
            app.criptoAnalisisVigenereWindow=CriptoAnalisisVigenereWindow(app);
        end

        % Callback function
        function VigenreButtonPushed(app, event)
            app.criptoAnalisisVigenereWindow=CriptoAnalisisVigenereWindow(app);
        end

        % Menu selected function: HillMenu_3
        function HillMenu_3Selected(app, event)
            app.criptoAnalisisHillWindow=CriptoAnalisisHillWindow(app);
        end

        % Callback function
        function HillButtonPushed(app, event)
            app.criptoAnalisisHillWindow=CriptoAnalisisHillWindow(app);
        end

        % Menu selected function: ImgenesMenu_2
        function ImgenesMenu_2Selected(app, event)
            app.ImagenesHillWindow=ImagenesHill(app);
        end

        % Menu selected function: ImgenesMenu
        function ImgenesMenuSelected(app, event)
            app.ImagenesHillWindow=ImagenesHill(app);
        end

        % Menu selected function: AcercaMenu
        function AcercaMenuSelected(app, event)
            uialert(app.CriptUNUIFigure,append('Aplicación para la asignatura Introducción a la criptografía y a la teoría de información',newline,'Programado por: Daniel Darío Fula Argüello',newline,'Segundo semestre del 2021',newline,'Universidad Nacional de Colombia'),'Acerca de CriptUN');
        end

        % Menu selected function: PermutacinMenu_3
        function PermutacinMenu_3Selected(app, event)
            app.criptoAnalisisPermutacionWindow=CriptoAnalisisPermutacionWindow(app);
        end

        % Menu selected function: SdeBloqueMenu
        function SdeBloqueMenuSelected(app, event)
            
        end

        % Menu selected function: GammaPentagonalMenu
        function GammaPentagonalMenuSelected(app, event)
            app.SistemaGammaPentagonalWindow=SistemaGammaPentagonal(app);
        end

        % Value changed function: CriptosistemaDropDown
        function CriptosistemaDropDownValueChanged(app, event)
            value = app.CriptosistemaDropDown.Value;
            switch value
                case 'Gamma Pentagonal'
                   app.SistemaGammaPentagonalWindow=SistemaGammaPentagonal(app);
                case 'S-DES'
                    app.TamaodelaclavebitsDropDown.Items={'10'};
                    app.TamaodelaclavebitsDropDown.Value='10';
                    app.tamPalabraCriptoSistema=8;
                    
                case 'DES'
                    app.TamaodelaclavebitsDropDown.Items={'64'};
                    app.TamaodelaclavebitsDropDown.Value='64';
                    app.tamPalabraCriptoSistema=64;
                case '3-DES'
                    app.TamaodelaclavebitsDropDown.Items={'128'};
                    app.TamaodelaclavebitsDropDown.Value='128';
                    app.tamPalabraCriptoSistema=64;
                case 'AES'
                    app.TamaodelaclavebitsDropDown.Items={'128','192','256'};
                    app.TamaodelaclavebitsDropDown.Value='128';
                    app.tamPalabraCriptoSistema=128;
            end
            app.limpiarCriptoModerna();
        end

        % Menu selected function: SDESMenu
        function SDESMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.SdeBloqueTab;
            app.CriptosistemaDropDown.Value='S-DES';
            app.TamaodelaclavebitsDropDown.Items={'10'};
            app.TamaodelaclavebitsDropDown.Value='10';
            app.tamPalabraCriptoSistema=8;
            app.limpiarCriptoModerna();
        end

        % Menu selected function: DESMenu
        function DESMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.SdeBloqueTab;
            app.CriptosistemaDropDown.Value='DES';
            app.TamaodelaclavebitsDropDown.Items={'64'};
            app.TamaodelaclavebitsDropDown.Value='64';
            app.tamPalabraCriptoSistema=64;
            app.limpiarCriptoModerna();
        end

        % Menu selected function: TRESDESMenu
        function TRESDESMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.SdeBloqueTab;
            app.CriptosistemaDropDown.Value='3-DES';
            app.TamaodelaclavebitsDropDown.Items={'128'};
            app.TamaodelaclavebitsDropDown.Value='128';
            app.tamPalabraCriptoSistema=64;
            app.limpiarCriptoModerna();
        end

        % Menu selected function: AESMenu
        function AESMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.SdeBloqueTab;
            app.CriptosistemaDropDown.Value='AES';
            app.TamaodelaclavebitsDropDown.Items={'128','192','256'};
            app.TamaodelaclavebitsDropDown.Value='128';
            app.tamPalabraCriptoSistema=128;
            app.limpiarCriptoModerna();
        end

        % Menu selected function: GenerarMenu
        function GenerarMenuSelected(app, event)
           tamClave=str2double(app.TamaodelaclavebitsDropDown.Value);
           app.ClaveEditField.Value=app.cerouno(randi(2,1,tamClave));

        end

        % Menu selected function: LimpiarMenu
        function LimpiarMenuSelected(app, event)
            app.ClaveEditField.Value='';
        end

        % Menu selected function: CargarMenu
        function CargarMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            app.ClaveEditField.Value=fileread(fullfile(path,file));
        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar',append('clave.txt'));
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writematrix(app.ClaveEditField.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: GenerarMenu_2
        function GenerarMenu_2Selected(app, event)

           tamIV=app.tamPalabraCriptoSistema;
           app.IVEditField.Value=app.cerouno(randi(2,1,tamIV));
        end

        % Menu selected function: LimpiarMenu_2
        function LimpiarMenu_2Selected(app, event)
            app.IVEditField.Value='';
        end

        % Menu selected function: CargarMenu_2
        function CargarMenu_2Selected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            app.IVEditField.Value=fileread(fullfile(path,file));
        end

        % Menu selected function: GuardarMenu_2
        function GuardarMenu_2Selected(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar',append('IV.txt'));
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writematrix(app.ClaveEditField.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: CargarInputImageButton
        function CargarInputImageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           figure(app.CriptUNUIFigure)
           if filename==0
                return
           end
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           [~,~,colores]=size(ImageFile);
           if colores==1
                imagenAux(:,:,1)=ImageFile;
                imagenAux(:,:,2)=ImageFile;
                imagenAux(:,:,3)=ImageFile;
           end
           if colores==3
               imagenAux=ImageFile;
           end
           app.InputImage.ImageSource=imagenAux;
          
           [app.imagenClara,app.dimImagenClara]=app.img2ArregloLogico(imagenAux);

        end

        % Button pushed function: LimpiarInputImageButton
        function LimpiarInputImageButtonPushed(app, event)
            app.InputImage.ImageSource='';
        end

        % Button pushed function: GuardarOutputImageButton
        function GuardarOutputImageButtonPushed(app, event)
             if isempty(app.OutputImage.ImageSource)
                uialert(app.CriptUNUIFigure,'No se ha generado ningún resultado.','Error');
                return
            end
            msg = '¿Desea guardar el resultado?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.png';'*.*';};
                    [file, path] = uiputfile(filter,'Guardar resultado','Resultado.png');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    imwrite(app.OutputImage.ImageSource, fullfile(path,file),'png');
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: LimpiarOutputImageButton
        function LimpiarOutputImageButtonPushed(app, event)
            app.OutputImage.ImageSource='';
        end

        % Value changed function: ModosdeciframientoDropDown
        function ModosdeciframientoDropDownValueChanged(app, event)
            app.limpiarCriptoModerna();
            value=app.ModosdeciframientoDropDown.Value;
            switch value
                case 'ECB'
                    app.IVEditField.Visible=0;
                    app.IVEditFieldLabel.Visible=0;
                case 'CBC'
                    app.IVEditField.Visible=1;
                    app.IVEditFieldLabel.Visible=1;
                case 'CFB'
                    app.IVEditField.Visible=1;
                    app.IVEditFieldLabel.Visible=1;
                case 'OFB'
                    app.IVEditField.Visible=1;
                    app.IVEditFieldLabel.Visible=1;
                case 'CTR'
                    app.IVEditField.Visible=0;
                    app.IVEditFieldLabel.Visible=0;
            end
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
            %{
            cerouno='01';
            clave=cerouno(randi(2,1,10))=='1';
            textoEntrada=cerouno(randi(2,1,8))=='1';
            [K1,K2]=app.construccionSubClavesSDES(clave);
            textoSalida=app.SDES(textoEntrada,K1,K2);
            textoSalida2=app.SDES(textoSalida,K2,K1);
            textoEntrada==textoSalida2
            clave=cerouno(randi(2,1,64))=='1';
            textoEntrada=cerouno(randi(2,1,64))=='1';
            subclaves=app.construccionSubClavesDES(clave);
            textoSalida=app.DES(textoEntrada,subclaves);
            textoSalida2=app.DES(textoSalida,subclaves(end:-1:1));
            textoEntrada==textoSalida2
            %}
            
            if isempty(app.imagenClara)
                uialert(app.CriptUNUIFigure,'No se ingresó ninguna imagen.','Error');
                return
            end
            
            if size(app.ClaveEditField.Value,2)~=str2double(app.TamaodelaclavebitsDropDown.Value)
                uialert(app.CriptUNUIFigure,'No se ingresó ninguna clave o el tamaño de la clave no es correcto.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end
            aux=unique(app.ClaveEditField.Value);
            if ~strcmp(aux,'01')&&~strcmp(aux,'0')&&~strcmp(aux,'01')
                uialert(app.CriptUNUIFigure,'La clave ingresada debe ser binaria.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end
            aux=unique(app.IVEditField.Value);
            if ~strcmp(aux,'01')&&~strcmp(aux,'0')&&~strcmp(aux,'01')&&app.IVEditField.Visible==1
                uialert(app.CriptUNUIFigure,'El valor inicial ingresado debe ser binario.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end
            if length(app.IVEditField.Value)~=app.tamPalabraCriptoSistema&&app.IVEditField.Visible==1
                uialert(app.CriptUNUIFigure,'El tamaño del valor inicial no es correcto.Intente nuevamente o haga clic derecho sobre el campo para ver más opciones.','Error');
                return
            end

            app.TrabajandoLabel.Text='En proceso';
            pause(0.5)
            switch app.ModosdeciframientoDropDown.Value
                case 'ECB'
                    salida=app.ModoECB(app.imagenClara,true);
                case 'CBC'
                    salida=app.ModoCBC(app.imagenClara,true);
                case 'CFB'
                    salida=app.ModoCFB(app.imagenClara,true);
                case 'OFB'
                    salida=app.ModoOFB(app.imagenClara,true);
                case 'CTR'
                    salida=app.ModoCTR(app.imagenClara);
            end
            app.OutputImage.ImageSource=app.ArregloLogico2Img(salida,app.dimImagenClara);
            app.TrabajandoLabel.Text='Listo';
            
        end

        % Button pushed function: DecifrarButton
        function DecifrarButtonPushed(app, event)
            if isempty(app.imagenClara)
                uialert(app.CriptUNUIFigure,'No se ingresó ninguna imagen.','Error');
                return
            end
            if size(app.ClaveEditField.Value,2)~=str2double(app.TamaodelaclavebitsDropDown.Value)
                uialert(app.CriptUNUIFigure,'No se ingresó ninguna clave o el tamaño de la clave no es correcto.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end
            if length(app.IVEditField.Value)~=app.tamPalabraCriptoSistema&&app.IVEditField.Visible==1
                uialert(app.CriptUNUIFigure,'El tamaño del valor inicial no es correcto.Intente nuevamente o haga clic derecho sobre el campo para ver más opciones.','Error');
                return
            end
            aux=unique(app.ClaveEditField.Value);
            if ~strcmp(aux,'01')&&~strcmp(aux,'0')&&~strcmp(aux,'01')&&~strcmp(aux,'')
                uialert(app.CriptUNUIFigure,'La clave ingresada debe ser binaria.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end
            aux=unique(app.IVEditField.Value);
            if ~strcmp(aux,'01')&&~strcmp(aux,'0')&&~strcmp(aux,'01')&&~strcmp(aux,'')&&app.IVEditField.Visible==1
                uialert(app.CriptUNUIFigure,'El valor inicial ingresado debe ser binaria.Intente nuevamente o haga clic derecho sobre el campo de la clave para ver más opciones.','Error');
                return
            end

            app.TrabajandoLabel.Text='En proceso';
            pause(0.5)
            switch app.ModosdeciframientoDropDown.Value
                case 'ECB'
                    salida=app.ModoECB(app.imagenClara,false);
                case 'CBC'
                    salida=app.ModoCBC(app.imagenClara,false);
                case 'CFB'
                    salida=app.ModoCFB(app.imagenClara,false);
                case 'OFB'
                    salida=app.ModoOFB(app.imagenClara,false);
                case 'CTR'
                    salida=app.ModoCTR(app.imagenClara);
            end
            app.OutputImage.ImageSource=app.ArregloLogico2Img(salida,app.dimImagenClara);
            app.TrabajandoLabel.Text='Listo';
        end

        % Value changed function: TamaodelaclavebitsDropDown
        function TamaodelaclavebitsDropDownValueChanged(app, event)
            value = app.TamaodelaclavebitsDropDown.Value;
            app.limpiarCriptoModerna();
            if strcmp(app.CriptosistemaDropDown.Value,'AES')
                switch value
                    case '128'
                        app.numeroRondas=10;
                        app.numeroPalabrasKeyExp=4;
                    case '192'
                        app.numeroRondas=12;
                        app.numeroPalabrasKeyExp=6;
                    case '256' 
                        app.numeroRondas=14;
                        app.numeroPalabrasKeyExp=8;
                end
            end
        end

        % Button pushed function: PruebasButton
        function PruebasButtonPushed(app, event)
            %textoEntrada=app.cerouno(randi(2,1,128))=='1';
            %textoEntrada='01010100011010000110000101110100011100110010000001101101011110010010000001001011011101010110111001100111001000000100011001110101';
            %textoEntrada='01010100011101110110111100100000010011110110111001100101001000000100111001101001011011100110010100100000010101000111011101101111'=='1';
            %textoSalida=app.AES(textoEntrada,app.ClaveEditField.Value)
            %textoSalida2=app.AESinv(textoSalida,app.ClaveEditField.Value)
        end

        % Value changed function: CDSwitch
        function CDSwitchValueChanged(app, event)
            value = app.CDSwitch.Value;
            app.oc1EditField.Value='';
            app.oc2EditField.Value='';
            app.oc3EditField.Value='';
            app.oc4EditField.Value='';
            app.oc5EditField.Value='';
            app.TextoCifradoTextArea.Value='';
            app.TextoClaroTextArea.Value='';
            app.RespuestaLabel.Text='';
            switch value
                case 'C'
                    app.CifrarCDButton.Text='Cifrar';
                    app.TextoclaroPanel.Title='Texto Claro';
                    app.TextocifradoPanel.Title='Texto Cifrado';
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'RSA')
                        app.oc4EditFieldLabel.Text='Pública';
                    end
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'ElGamal (Menezes-Vanstone)')
                        app.oc4EditFieldLabel.Text='$\alpha$';
                        app.oc5EditFieldLabel.Text='$\beta$';
                        app.oc4EditFieldLabel.Visible=1;
                        app.oc4EditField.Visible=1;
                        app.oc5EditFieldLabel.Visible=1;
                        app.oc5EditField.Visible=1;
                    end
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'Rabin')
                        app.oc1EditFieldLabel.Text='$N$';
                        app.oc2EditFieldLabel.Text='$B$';
                        app.oc4EditFieldLabel.Visible=0;
                        app.oc4EditField.Visible=0;
                    end

                case 'D'
                    app.CifrarCDButton.Text='Decifrar';
                    app.TextoclaroPanel.Title='Texto Cifrado';
                    app.TextocifradoPanel.Title='Texto Claro';
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'RSA')
                        app.oc4EditFieldLabel.Text='Privada';
                    end
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'ElGamal (Menezes-Vanstone)')
                        app.oc4EditFieldLabel.Text='Privada';
                        app.oc4EditFieldLabel.Visible=1;
                        app.oc4EditField.Visible=1;
                        app.oc5EditFieldLabel.Visible=0;
                        app.oc5EditField.Visible=0;
                    end
                    if strcmp(app.CriptosistemaPublicoDropDown.Value,'Rabin')
                        app.oc1EditFieldLabel.Text='$p$';
                        app.oc2EditFieldLabel.Text='$q$';
                        app.oc4EditFieldLabel.Text='$B$';
                        app.oc4EditFieldLabel.Visible=1;
                        app.oc4EditField.Visible=1;
                    end
            end
            
        end

        % Value changed function: CriptosistemaPublicoDropDown
        function CriptosistemaPublicoDropDownValueChanged(app, event)

            value = app.CriptosistemaPublicoDropDown.Value;
            app.TextoClaroTextArea.Value='';
            app.TextoCifradoTextArea.Value='';
            app.oc1EditField.Value='';
            app.oc2EditField.Value='';
            app.oc3EditField.Value='';
            app.oc4EditField.Value='';
            app.oc5EditField.Value='';
            app.gc1EditField.Value='';
            app.gc2EditField.Value='';
            app.gc3EditField.Value='';
            app.gc4EditField.Value='';
            app.gc5EditField.Value='';
            app.gc6EditField.Value='';
            app.GenerarClaveLabel.Text='';
            app.RespuestaLabel.Text='';
            if strcmp(value,'Selecciones un criptosistema')
                app.GeneracindeclavesPanel.Visible=0;
                app.OpcionesdeclavePanel.Visible=0;
                app.TextoclaroPanel.Visible=0;
                app.TextocifradoPanel.Visible=0;
                app.CifrarCDButton.Visible=0;
                app.GenerarClaveLabel.Visible=0;
                app.RespuestaLabel.Visible=0;
            else
                app.GeneracindeclavesPanel.Visible=1;
                app.OpcionesdeclavePanel.Visible=1;
                app.TextoclaroPanel.Visible=1;
                app.TextocifradoPanel.Visible=1;
                app.CifrarCDButton.Visible=1;
                app.GenerarClaveLabel.Visible=1;
                app.RespuestaLabel.Visible=1;
            end
                

            switch value
                case 'RSA'
                    %Etiquetas
                    app.gc1EditFieldLabel.Text='$p$';
                    app.gc2EditFieldLabel.Text='$q$';
                    app.gc3EditFieldLabel.Text='$n$';
                    app.gc4EditFieldLabel.Text='Pública';
                    app.gc5EditFieldLabel.Text='Privada';
                    app.oc1EditFieldLabel.Text='$n$';
                    switch app.CDSwitch.Value
                        case 'C'
                            app.oc4EditFieldLabel.Text='Pública';
                        case 'D'
                            app.oc4EditFieldLabel.Text='Privada';
                    end
                    %Visibilidad
                    app.gc1EditField.Visible=1;
                    app.gc1EditFieldLabel.Visible=1;
                    app.gc2EditField.Visible=1;
                    app.gc2EditFieldLabel.Visible=1;
                    app.gc3EditField.Visible=1;
                    app.gc3EditFieldLabel.Visible=1;
                    app.gc4EditField.Visible=1;
                    app.gc4EditFieldLabel.Visible=1;
                    app.gc5EditField.Visible=1;
                    app.gc5EditFieldLabel.Visible=1;
                    app.gc6EditField.Visible=0;
                    app.gc6EditFieldLabel.Visible=0;
                    app.oc1EditFieldLabel.Visible=1;
                    app.oc1EditField.Visible=1;
                    app.oc2EditFieldLabel.Visible=0;
                    app.oc2EditField.Visible=0;
                    app.oc3EditFieldLabel.Visible=0;
                    app.oc3EditField.Visible=0;
                    app.oc4EditFieldLabel.Visible=1;
                    app.oc4EditField.Visible=1;
                    app.oc5EditFieldLabel.Visible=0;
                    app.oc5EditField.Visible=0;

                case 'ElGamal (Menezes-Vanstone)'
                    %Etiquetas
                    app.gc4EditFieldLabel.Text='$\alpha$';
                    app.gc5EditFieldLabel.Text='$\beta$';
                    app.gc6EditFieldLabel.Text='Privada';
                    app.gc1EditFieldLabel.Text='$p$';
                    app.gc2EditFieldLabel.Text='$\text{Elíptica}_a$';
                    app.gc3EditFieldLabel.Text='$\text{Elíptica}_b$';
                    app.oc1EditFieldLabel.Text='$p$';
                    app.oc2EditFieldLabel.Text='$\text{Elíptica}_a$';
                    app.oc3EditFieldLabel.Text='$\text{Elíptica}_b$';
                    switch app.CDSwitch.Value
                        case 'C'
                            app.oc4EditFieldLabel.Text='$\alpha$';
                            app.oc5EditFieldLabel.Text='$\beta$';
                        case 'D'
                            app.oc4EditFieldLabel.Text='Privada';
                    end

                    %Visibilidad
                    app.gc1EditField.Visible=1;
                    app.gc1EditFieldLabel.Visible=1;
                    app.gc2EditField.Visible=1;
                    app.gc2EditFieldLabel.Visible=1;
                    app.gc3EditField.Visible=1;
                    app.gc3EditFieldLabel.Visible=1;
                    app.gc4EditField.Visible=1;
                    app.gc4EditFieldLabel.Visible=1;
                    app.gc5EditField.Visible=1;
                    app.gc5EditFieldLabel.Visible=1;
                    app.gc6EditField.Visible=1;
                    app.gc6EditFieldLabel.Visible=1;
                    app.oc1EditField.Visible=1;
                    app.oc1EditFieldLabel.Visible=1;
                    app.oc2EditField.Visible=1;
                    app.oc2EditFieldLabel.Visible=1;
                    app.oc3EditFieldLabel.Visible=1;
                    app.oc3EditField.Visible=1;
                    app.oc4EditFieldLabel.Visible=1;
                    app.oc4EditField.Visible=1;
                    switch app.CDSwitch.Value
                        case 'C'
                            app.oc5EditFieldLabel.Visible=1;
                            app.oc5EditField.Visible=1;
                        case 'D'
                            app.oc5EditFieldLabel.Visible=0;
                            app.oc5EditField.Visible=0;
                    end
                case 'Rabin'
                    %Etiquetas
                    app.gc1EditFieldLabel.Text='$p$';
                    app.gc2EditFieldLabel.Text='$q$';
                    app.gc4EditFieldLabel.Text='$N$';
                    app.gc5EditFieldLabel.Text='$B$';
                    switch app.CDSwitch.Value
                        case 'C'
                            app.oc1EditFieldLabel.Text='$N$';
                            app.oc2EditFieldLabel.Text='$B$';
                        case 'D'
                            app.oc1EditFieldLabel.Text='$p$';
                            app.oc2EditFieldLabel.Text='$q$';
                            app.oc4EditFieldLabel.Text='$B$';
                    end
                    %Visibilidad
                    app.gc1EditField.Visible=1;
                    app.gc1EditFieldLabel.Visible=1;
                    app.gc2EditField.Visible=1;
                    app.gc2EditFieldLabel.Visible=1;
                    app.gc3EditField.Visible=0;
                    app.gc3EditFieldLabel.Visible=0;
                    app.gc4EditField.Visible=1;
                    app.gc4EditFieldLabel.Visible=1;
                    app.gc5EditField.Visible=1;
                    app.gc5EditFieldLabel.Visible=1;
                    app.gc6EditField.Visible=0;
                    app.gc6EditFieldLabel.Visible=0;
                    
                    app.oc1EditField.Visible=1;
                    app.oc1EditFieldLabel.Visible=1;
                    app.oc2EditField.Visible=1;
                    app.oc2EditFieldLabel.Visible=1;
                    switch app.CDSwitch.Value
                        case 'C'
                            app.oc4EditField.Visible=0;
                            app.oc4EditFieldLabel.Visible=0;
                        case 'D'
                            app.oc4EditField.Visible=1;
                            app.oc4EditFieldLabel.Visible=1;
                    end

                    app.oc3EditField.Visible=0;
                    app.oc3EditFieldLabel.Visible=0;
                    app.oc5EditField.Visible=0;
                    app.oc5EditFieldLabel.Visible=0;


            end
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            cspvalue=app.CriptosistemaPublicoDropDown.Value;
            cdvalue=app.CDSwitch.Value;
            ejecutarValue=app.OpcionesEjecutarDropDown.Value;
            switch ejecutarValue
                case 'Generar claves'
                    app.GenerarClaveLabel.Text='Generando clave';
                    pause(0.1);
                    switch cspvalue
                        case 'RSA'
                            p=app.generarPrimo(12);
                            while p<2^8
                                p=app.generarPrimo(12);
                            end
                            app.gc1EditField.Value=dec2hex(p);
                            q=app.generarPrimo(12);
                            while q<2^8
                                q=app.generarPrimo(12);
                            end
                            app.gc2EditField.Value=dec2hex(q);
                            n=p*q;
                            app.gc3EditField.Value=dec2hex(n);
                            T=(p-1)*(q-1);
                            b=randi([2,T-1],1,1);
                            %a=app.inversoMultiplicativo(T,b);
                            %while mod(a*b,T)~=1
                            %    b=randi([2,T-1],1,1);
                            %    a=app.inversoMultiplicativo(T,b);
                            %    a=mod(a,T);
                            %end
                            while gcd(b,T)~=1
                                b=randi([2,T-1],1,1);
                            end
                            a=mod(app.inversoMultiplicativo(b,T),T);
                            app.gc4EditField.Value=dec2hex(b);  
                            app.gc5EditField.Value=dec2hex(a);
                            %powermod(110,125998,n)
                        case 'ElGamal (Menezes-Vanstone)'
                            %uialert(app.CriptUNUIFigure,'El resultado puede tomar algo de tiempo. Espere hasta la alerta de fin del proceso.','Advertencia');
                            p=app.generarPrimo(10);
                            while p<2^7||mod(p,4)~=3
                                p=app.generarPrimo(10);
                            end
                            app.gc1EditField.Value=dec2hex(p);
                            ea=randi([0,p-1],1,1);
                            eb=randi([0,p-1],1,1);
                            numPuntosCE=app.contarPuntosEliptica(ea,eb,p);
                            while mod(4*ea^3+27*eb^2,p)==0||~isprime(numPuntosCE)
                                ea=randi([0,p-1],1,1);
                                eb=randi([0,p-1],1,1);
                                numPuntosCE=app.contarPuntosEliptica(ea,eb,p);
                            end
                            app.gc2EditField.Value=dec2hex(ea);
                            app.gc3EditField.Value=dec2hex(eb);
                            alfa=app.puntoSobreCurvaEliptica(ea,eb,p);
                            aux1=dec2hex(alfa);
                            aux1=append(aux1(1,:),',',aux1(2,:));
                            app.gc4EditField.Value=aux1;
                            privada=randi([2,p-1],1,1);
                            app.gc6EditField.Value=dec2hex(privada);
                           
                            beta=app.MultiplicacionPorEscalarRapida(alfa,privada,ea,eb,p);
                            if ~app.revisarPuntoEnEliptica(beta,ea,eb,p)
                                uialert(app.CriptUNUIFigure,append('El punto beta generado no está en la curva elíptica. Genere una nueva clave.'),'Error');
                            end
                            y2=@(x,a,b,p)mod(x^3+a*x+b,p);
                            isequal(mod(beta(2)*beta(2),p),y2(beta(1),ea,eb,p))
                            aux1=dec2hex(beta);
                            aux1=append(aux1(1,:),',',aux1(2,:));
                            app.gc5EditField.Value=aux1;

                            if numPuntosCE>256
                                uialert(app.CriptUNUIFigure,append('Se ha concluido el proceso exitosamente. El subgrupo cíclico usado tiene ',num2str(numPuntosCE),' elementos.'),'Información generada con exito');
                            else
                                uialert(app.CriptUNUIFigure,'El número de puntos en la curva eliptica no es suficiente para encriptar el texto. Genere una nueva clave','Error');
                                app.GenerarClaveLabel.Text='Error';
                                return
                            end
                        case 'Rabin'
                            p=app.generarPrimo(12);
                            while mod(p,4)~=3||p<2^8
                                p=app.generarPrimo(12);
                            end
                            app.gc1EditField.Value=dec2hex(p);
                            q=app.generarPrimo(12);
                            while mod(q,4)~=3||q<2^8
                                q=app.generarPrimo(12);
                            end
                            app.gc2EditField.Value=dec2hex(q);
                            n=p*q;
                            app.gc4EditField.Value=dec2hex(n);                           
                            app.gc5EditField.Value=dec2hex(randi([0,n-1],1,1));
                    end
                    app.GenerarClaveLabel.Text='Clave generada';
                case 'Limpiar claves'
                    app.GenerarClaveLabel.Text='';
                    app.gc1EditField.Value='';
                    app.gc2EditField.Value='';
                    app.gc3EditField.Value='';
                    app.gc4EditField.Value='';
                    app.gc5EditField.Value='';
                    app.gc6EditField.Value='';
                case 'Pegar arriba'
                    switch cspvalue
                        case 'RSA'
                            app.oc1EditField.Value=app.gc3EditField.Value;
                            if strcmp(cdvalue,'C')
                                app.oc4EditField.Value=app.gc4EditField.Value;
                            end
                            if strcmp(cdvalue,'D')
                                app.oc4EditField.Value=app.gc5EditField.Value;
                            end
                        case 'ElGamal (Menezes-Vanstone)'
                            app.oc1EditField.Value=app.gc1EditField.Value;
                            app.oc2EditField.Value=app.gc2EditField.Value;
                            app.oc3EditField.Value=app.gc3EditField.Value;
                            if strcmp(cdvalue,'C')
                                app.oc4EditField.Value=app.gc4EditField.Value;
                                app.oc5EditField.Value=app.gc5EditField.Value;
                            end
                            if strcmp(cdvalue,'D')
                                app.oc4EditField.Value=app.gc6EditField.Value;
                            end
                        case 'Rabin'
                            switch app.CDSwitch.Value
                                case 'C'
                                    app.oc1EditField.Value=app.gc4EditField.Value;
                                    app.oc2EditField.Value=app.gc5EditField.Value;
                                case 'D'
                                    app.oc1EditField.Value=app.gc1EditField.Value;
                                    app.oc2EditField.Value=app.gc2EditField.Value;
                                    app.oc4EditField.Value=app.gc5EditField.Value;
                            end
                    end


            end

            
        end

        % Button pushed function: CifrarCDButton
        function CifrarCDButtonPushed(app, event)
            textoEntrada=strjoin(app.TextoClaroTextArea.Value,newline);
            criptopublico=app.CriptosistemaPublicoDropDown.Value;
            modo=app.CDSwitch.Value;
            switch modo
                case 'C'
                    app.RespuestaLabel.Text='Cifrando';
                case 'D'
                    app.RespuestaLabel.Text='Decifrando';
            end
            pause(0.1);
            switch criptopublico
                case 'RSA'
                    %El tamaño de los bloques de codificación puede ser
                    %diferente al tamaño de la subdivisión del texto.
                    %Suponga que n en hexadecimal tiene 6 dígitos, la
                    %subdivisión del texto no puede ser de 6 dígitos,
                    %porque habrán números que sobrepasen a n y estos no se
                    %podrán decodificar de la manera adecuada. Lo hecho a
                    %continuación es dividir el texto con 1 dígito
                    %hexadecimal menos que los dígitos de n. Por ejemplo,
                    %si n tiene 6 dígitos hexadecimales, el texto se
                    %dividirá de a 5 caracteres. Para decodificar, se
                    %deberán tomar de a 6 caracteres y luego convertirlo a
                    %un número de 5, para poder obtener el texto original.
                    %La codificación se dejará en HexaDecimal
                    n=hex2dec(app.oc1EditField.Value);
                    if isempty(app.oc1EditField.Value)
                        uialert(app.CriptUNUIFigure,'Ingrese un valor para n.','Error');
                        return
                    end
                    tamBloque=ceil(log(n)/log(256)); %Bits requeridos para n, Matlab molesta si es mayor que 6
                    tamDivTexto=tamBloque-1; %Tamaño de las divisiones del texto, debe ser menor que tamBloque pero mayor o igual a 1
                    if tamBloque==1
                        uialert(app.CriptUNUIFigure,'El valor de n no es adecuado para encriptar todo el texto (debe ser mayor que FF o 255). Ingrese uno diferente.','Error');
                        return
                    end
                    textoEntrada=pad(textoEntrada,ceil(length(textoEntrada)/tamDivTexto)*tamDivTexto);
                    switch modo
                        case 'C'
                            if isempty(app.oc4EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un valor para la clave pública.','Error');
                                return
                            end
                            publica=hex2dec(app.oc4EditField.Value);
                            textoSalida='';
                            for ii=1:tamDivTexto:length(textoEntrada)-tamDivTexto+1
                                divTexto=textoEntrada(ii:ii+tamDivTexto-1);
                                x=hex2dec(reshape(transpose(dec2hex(double(divTexto),2)),1,[]));
                                numeroCifrado=dec2hex(app.potenciaModular(x,publica,n),2*tamBloque);
                                textoSalida=append(textoSalida,numeroCifrado);
                            end
                            app.TextoCifradoTextArea.Value=textoSalida;
                        case 'D'
                            if isempty(app.oc4EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un valor para la clave privada.','Error');
                                return
                            end
                            privada=hex2dec(app.oc4EditField.Value);
                            textoSalida='';
                            for ii=1:2*tamBloque:length(textoEntrada)-2*tamBloque+1
                                bloque=textoEntrada(ii:ii+2*tamBloque-1);
                                y=hex2dec(bloque);
                                numeroDecifrado=dec2hex(app.potenciaModular(y,privada,n),2*tamDivTexto);
                                x=reshape(char(hex2dec(transpose(reshape(numeroDecifrado,2,[])))),1,[]);
                                textoSalida=append(textoSalida,x);
                            end
                            app.TextoCifradoTextArea.Value=textoSalida;
                    end
                case 'Rabin'
                    %N debe ser mayor que FFFF en hex
                    %El texto se dividirá de a 2 letras que se codificarán
                    %usando bits repetidos para poder obtener el mensaje
                    %original.
                    %Se codificará un texto y se decodificará un mensaje en
                    %hexadecimal.
                    switch modo
                        case 'C'
                            if isempty(app.oc1EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un valor para la N.','Error');
                                return
                            end
                            if isempty(app.oc2EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un valor para B.','Error');
                                return
                            end
                            N=hex2dec(app.oc1EditField.Value);
                            if N<2^16
                                uialert(app.CriptUNUIFigure,'El valor de n no es adecuado para encriptar todo el texto (debe ser mayor que FFFF o 2^16). Ingrese uno diferente.','Error');
                                return
                            end
                            hexUnitsN=ceil(log(N)/log(16));
                            publica=hex2dec(app.oc2EditField.Value);
                            textoSalida='';
                            for ii=1:length(textoEntrada)
                                letra2hex=dec2hex(double(textoEntrada(ii)),2);
                                duplicado=append(letra2hex,letra2hex);
                                x=hex2dec(duplicado);
                                y=dec2hex(mod(x*mod(x+publica,N),N),hexUnitsN);
                                textoSalida=append(textoSalida,y);
                            end
                            app.TextoCifradoTextArea.Value=textoSalida;
                        case 'D'
                            p=hex2dec(app.oc1EditField.Value);
                            q=hex2dec(app.oc2EditField.Value);
                            if isempty(app.oc1EditField.Value)||~isprime(p)||mod(p,4)~=3
                                uialert(app.CriptUNUIFigure,'Ingrese un valor correcto para p. Recuerde que debe ser un primo equivalente a 3 módulo 4.','Error');
                                return
                            end
                            if isempty(app.oc2EditField.Value)||~isprime(q)||mod(q,4)~=3
                                uialert(app.CriptUNUIFigure,'Ingrese un valor correcto para q. Recuerde que debe ser un primo equivalente a 3 módulo 4','Error');
                                return
                            end
                            if isempty(app.oc4EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un valor para B.','Error');
                                return
                            end
                            if mod(p,4)~=3||mod(q,4)~=3
                                uialert(app.CriptUNUIFigure,'Los valores p y q deben ser equivalentes 3 módulo 4.','Error');
                                return
                            end
                            n=p*q;
                            hexUnitsN=ceil(log(n)/log(16));
                            if n<2^16
                                uialert(app.CriptUNUIFigure,'Los valores de p y q no son adecuados para desencriptar el texto (su producto debe ser mayor que FFFF o 2^16). Ingrese valores diferentes.','Error');
                                return
                            end
                            publica=hex2dec(app.oc4EditField.Value);
                            textoSalida='';
                            for ii=1:hexUnitsN:length(textoEntrada)-hexUnitsN+1
                                bloque=textoEntrada(ii:ii+hexUnitsN-1);
                                y=hex2dec(bloque);
                                Bs2=mod(publica*app.inversoMultiplicativo(2,n),n);
                                Bs2ala2=mod(Bs2*Bs2,n);
                                raices=app.raiz2_pq3m4(mod(Bs2ala2+y,n),p,q);
                                soluciones=mod(raices-Bs2,n);
                                hexDecifradas=cell(4,1);
                                for jj=1:length(hexDecifradas)
                                    hexDecifradas{jj}=dec2hex(soluciones(jj),4);
                                end
                                %hexDecifrado=dec2hex(raiz)
                                x='';
                                for jj=1:4
                                    aux=hexDecifradas{jj,:};
                                    if strcmp(aux(1:2),aux(3:4))&&length(aux)==4
                                        x=char(hex2dec(aux(1:2)));
                                        break
                                    end
                                end
                                textoSalida=append(textoSalida,x);
                            end
                            app.TextoCifradoTextArea.Value=textoSalida;
                    end
                case 'ElGamal (Menezes-Vanstone)'
                    p=hex2dec(app.oc1EditField.Value);
                    ea=hex2dec(app.oc2EditField.Value);
                    eb=hex2dec(app.oc3EditField.Value);
                    if isempty(app.oc1EditField.Value)
                        uialert(app.CriptUNUIFigure,'Ingrese un número primo.','Error');
                        return
                    end
                    if ~isprime(p)
                        uialert(app.CriptUNUIFigure,'Ingrese un número primo.','Error');
                        return
                    end
                    if isempty(app.oc2EditField.Value)||isempty(app.oc3EditField.Value)
                        uialert(app.CriptUNUIFigure,'Ingrese los parámetros de la curva elíptica y^2=x^3+ax+b.','Error');
                        return
                    end
                    switch modo
                        case 'C'
                            if isempty(app.oc4EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese un punto alfa en la curva elíptica.','Error');
                                return
                            end
                            
                            if isempty(app.oc5EditField.Value)
                                uialert(app.CriptUNUIFigure,'Ingrese el punto beta, obtenido al operar la clave con el punto alfa.','Error');
                                return
                            end
                            
                            aux=strsplit(app.oc4EditField.Value,',');
                            alfa=[hex2dec(aux{1}),hex2dec(aux{2})];
                            if ~app.revisarPuntoEnEliptica(alfa,ea,eb,p)
                                uialert(app.CriptUNUIFigure,'El punto alfa no está en la curva elíptica.','Error');
                                return
                            end
                            aux=strsplit(app.oc5EditField.Value,',');
                            beta=[hex2dec(aux{1}),hex2dec(aux{2})];
                            if ~app.revisarPuntoEnEliptica(beta,ea,eb,p)
                                uialert(app.CriptUNUIFigure,'El punto beta no está en la curva elíptica.','Error');
                                return
                            end
                            k=randi([1,p-1],1,1);
                            num2str(k);
                            uialert(app.CriptUNUIFigure,append('El valor aleatorio de k fue ',num2str(k)),'Valor de k');
                            %k=10;
                            y0=app.MultiplicacionPorEscalarRapida(alfa,k,ea,eb,p);
                            c=app.MultiplicacionPorEscalarRapida(beta,k,ea,eb,p);
                            if mod(length(textoEntrada),2)==1
                                textoEntrada=append(textoEntrada,'.');
                            end
                            aux=dec2hex(y0);
                            textoSalida=append('(',aux(1,:),',',aux(2,:),');');
                            for ii=1:2:length(textoEntrada)-1
                                x=textoEntrada(ii:ii+1);
                                y1=mod(c(1)*x(1),p);
                                y2=mod(c(2)*x(2),p);
                                textoSalida=append(textoSalida,'(',dec2hex(y1),',',dec2hex(y2),');');
                            end
                            app.TextoCifradoTextArea.Value=textoSalida(1:end-1);
                        case 'D'
                            privada=hex2dec(app.oc4EditField.Value);
                            textoEntrada=erase(textoEntrada,'(');
                            textoEntrada=erase(textoEntrada,')');
                            parejasEntrada=strsplit(textoEntrada,';');
                            aux=parejasEntrada{1};
                            aux=strsplit(aux,',');
                            y0=[hex2dec(aux{1}),hex2dec(aux{2})];
                            c=app.MultiplicacionPorEscalarRapida(y0,privada,ea,eb,p);
                            c1inv=app.inversoMultiplicativo(c(1),p);
                            c2inv=app.inversoMultiplicativo(c(2),p);
                            textoSalida='';
                            for ii=2:length(parejasEntrada)
                                aux=parejasEntrada{ii};
                                aux=strsplit(aux,',');
                                y=[hex2dec(aux{1}),hex2dec(aux{2})];
                                x1=mod(y(1)*c1inv,p);
                                x2=mod(y(2)*c2inv,p);
                                textoSalida=append(textoSalida,char(x1),char(x2));
                            end
                            app.TextoCifradoTextArea.Value=textoSalida;
                    end
            end

            switch modo
                case 'C'
                    app.RespuestaLabel.Text='Cifrado terminado';
                case 'D'
                    app.RespuestaLabel.Text='Decifrado terminado';
            end
        end

        % Button pushed function: LimpiarButton_2
        function LimpiarButton_2Pushed(app, event)
            app.oc1EditField.Value='';
            app.oc2EditField.Value='';
            app.oc3EditField.Value='';
            app.oc4EditField.Value='';
            app.oc5EditField.Value='';
        end

        % Menu selected function: LimpiarMenu_3
        function LimpiarMenu_3Selected(app, event)
            app.TextoClaroTextArea.Value='';
        end

        % Menu selected function: CargarMenu_3
        function CargarMenu_3Selected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            app.TextoClaroTextArea.Value=fileread(fullfile(path,file));
        end

        % Menu selected function: LimpiarMenu_4
        function LimpiarMenu_4Selected(app, event)
            app.TextoCifradoTextArea.Value='';
        end

        % Menu selected function: GuardarMenu_4
        function GuardarMenu_4Selected(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar','Resultado.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writecell(app.TextoCifradoTextArea.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: RSAMenu
        function RSAMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.ClavePublicaTab;
            evento=matlab.ui.eventdata.ValueChangedData('RSA',app.CriptosistemaPublicoDropDown.Value);
            app.CriptosistemaPublicoDropDown.Value='RSA';
            app.CriptosistemaPublicoDropDownValueChanged(evento);
            
        end

        % Menu selected function: RabinMenu
        function RabinMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.ClavePublicaTab;
            evento=matlab.ui.eventdata.ValueChangedData('Rabin',app.CriptosistemaPublicoDropDown.Value);
            app.CriptosistemaPublicoDropDown.Value='Rabin';
            app.CriptosistemaPublicoDropDownValueChanged(evento);
        end

        % Menu selected function: ElGamalMenezesVanstoneMenu
        function ElGamalMenezesVanstoneMenuSelected(app, event)
            app.CriptUNTabGroup.SelectedTab=app.ClavePublicaTab;
            evento=matlab.ui.eventdata.ValueChangedData('ElGamal (Menezes-Vanstone)',app.CriptosistemaPublicoDropDown.Value);
            app.CriptosistemaPublicoDropDown.Value='ElGamal (Menezes-Vanstone)';
            app.CriptosistemaPublicoDropDownValueChanged(evento);
        end

        % Value changed function: OpcionesDropDown
        function OpcionesDropDownValueChanged(app, event)
            value = app.OpcionesDropDown.Value;
            switch value
                case '¿Qué desea hacer?'
                    app.GenerelascontraseasPanel.Visible=0;
                    app.FirmeeldocumentoPanel.Visible=0;
                    app.PrimoEditField.Value='';
                    app.GeneradorEditField.Value='';
                    app.SHA256delarchivoEditField.Value='';
                    app.PasswordEditField.Value='';
                    app.PublicaEditField.Value='';
                    app.gammaEditField.Value='';
                    app.deltaEditField.Value='';

                case 'Firmar un archivo'
                    %Panel de contraseñas
                    app.GenerelascontraseasPanel.Visible=1;
                    app.GenerelascontraseasPanel.Title='3. Genere las contraseñas';
                    app.PasswordEditField.Visible=1;
                    app.PasswordEditFieldLabel.Visible=1;
                    app.GenerarPasswordButton.Visible=1;
                    app.GenerarCPbButton.Visible=1;
                    app.GuardarPasswordButton.Visible=1;

                    %Panel de firma/verificación
                    app.FirmeeldocumentoPanel.Visible=1;
                    app.FirmeeldocumentoPanel.Title='4. Firme el documento';
                    app.VerificarLabel.Text='';
                    app.FirmarVerificarFirmaButton.Text='Firmar';
                    app.GuardarCargarFirmaButton.Text='Guardar';
                    
                case 'Verificar una firma'
                    %Panel de contraseñas
                    app.GenerelascontraseasPanel.Visible=1;
                    app.GenerelascontraseasPanel.Title='3. Ingrese la contraseña pública';
                    app.PasswordEditField.Visible=0;
                    app.PasswordEditFieldLabel.Visible=0;
                    app.GenerarPasswordButton.Visible=0;
                    app.GenerarCPbButton.Visible=0;
                    app.GuardarPasswordButton.Visible=0;

                    %Panel de firma/verificación
                    app.FirmeeldocumentoPanel.Visible=1;
                    app.FirmeeldocumentoPanel.Title='4. Verifique la firma';
                    app.VerificarLabel.Text='';
                    app.FirmarVerificarFirmaButton.Text='Verificar';
                    app.GuardarCargarFirmaButton.Text='Cargar';
            end
        end

        % Button pushed function: GenerarEsquemaButton
        function GenerarEsquemaButtonPushed(app, event)
            p=app.generarPrimo(24);
            g=randi([1,p-1],1,1);
            app.PrimoEditField.Value=dec2hex(p);
            app.GeneradorEditField.Value=dec2hex(g);
        end

        % Button pushed function: GuardarEsquemaButton
        function GuardarEsquemaButtonPushed(app, event)
            if isempty(app.PrimoEditField.Value)||isempty(app.GeneradorEditField.Value)
                uialert(app.CriptUNUIFigure,'No se puede guardar la configuración. Revise que todos los campos estén llenos e intente nuevamente.','Error');
            end
            p=app.PrimoEditField.Value;
            g=app.GeneradorEditField.Value;
            configuracion=append(p,newline,g);
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar','Configuración esquema.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writematrix(configuracion, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: CargarEsquemaButton
        function CargarEsquemaButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            configuracion=fileread(fullfile(path,file));
            c=strsplit(configuracion,newline);
            app.PrimoEditField.Value=c{1};
            app.GeneradorEditField.Value=c{2};
        end

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            figure(app.CriptUNUIFigure);
            if file==0
                return
            end
            archivo=fileread(fullfile(path,file));
            app.SHA256delarchivoEditField.Value=DataHash(archivo,'SHA-256','HEX');
        end

        % Button pushed function: GenerarPasswordButton
        function GenerarPasswordButtonPushed(app, event)
            p=hex2dec(app.PrimoEditField.Value);
            pw=randi([1,p-1],1,1);
            app.PasswordEditField.Value=dec2hex(pw);
        end

        % Button pushed function: GuardarPasswordButton
        function GuardarPasswordButtonPushed(app, event)
            if isempty(app.PasswordEditField.Value)
                uialert(app.CriptUNUIFigure,'No hay ninguna contraseña para guardar. Revise que el cmapo esté lleno e intente nuevamente.','Error');
            end
            pw=app.PasswordEditField.Value;
            clavePublica=app.PublicaEditField.Value;
            msg = 'Seleccione el tipo de contraseña que desea guardar';
            title = 'Guardar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Guardar clave privada','Guardar clave pública','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Guardar clave privada'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar','Clave privada.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writematrix(pw, fullfile(path,file), 'QuoteStrings',false);
                case 'Guardar clave pública'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar','Clave pública.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    writematrix(clavePublica, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: CargarPasswordButton
        function CargarPasswordButtonPushed(app, event)
            msg = 'Seleccione el tipo de contraseña que desea cargar';
            title = 'Cargar';
            selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                        'Options',{'Cargar clave privada','Cargar clave pública','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Cargar clave privada'
                    [file,path] = uigetfile('*.*');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    pw=fileread(fullfile(path,file));
                    app.PasswordEditField.Value=strtrim(pw);
                case 'Cargar clave pública'
                    [file,path] = uigetfile('*.*');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    clavePublica=fileread(fullfile(path,file));
                    app.PublicaEditField.Value=strtrim(clavePublica);
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: GenerarCPbButton
        function GenerarCPbButtonPushed(app, event)
            if isempty(app.PasswordEditField.Value)
                uialert(app.CriptUNUIFigure,'No se puede generar la contraseña pública. Revise que el campo de la contraseña privada no sea vacío e intente nuevamente.','Error');
            end
            pw=strtrim( app.PasswordEditField.Value);
            p=app.PrimoEditField.Value;
            g=app.GeneradorEditField.Value;
            cpub=app.potenciaModularHex(g,pw,p);
            app.PublicaEditField.Value=cpub;
        end

        % Button pushed function: FirmarVerificarFirmaButton
        function FirmarVerificarFirmaButtonPushed(app, event)
            opciones=app.FirmarVerificarFirmaButton.Text;
            switch opciones
                case 'Firmar'
                    pHex=app.PrimoEditField.Value;
                    p=hex2dec(pHex);
                    gHex=app.GeneradorEditField.Value;
                    g=hex2dec(gHex);
                    k=randi([1,p-2],1,1);
                    %k=213;
                    while gcd(k,p-1)~=1
                        k=randi([1,p-2],1,1);
                    end
                    kHex=dec2hex(k);
                    kinv=app.inversoMultiplicativo(k,p-1);
                    gammaHex=app.potenciaModularHex(gHex,kHex,pHex);
                    gamma=hex2dec(gammaHex);
                    pm1Hex=dec2hex(p-1);
                    x=hex2dec(app.modHex(app.SHA256delarchivoEditField.Value,pm1Hex));
                    a=hex2dec(app.modHex(app.PasswordEditField.Value,pm1Hex));
                    delta=mod(mod(x-mod(a*gamma,p-1),p-1)*kinv,p-1);
                    app.gammaEditField.Value=dec2hex(gamma);
                    app.deltaEditField.Value=dec2hex(delta);
                case 'Verificar'
                    pHex=app.PrimoEditField.Value;
                    p=hex2dec(pHex);
                    gHex=app.GeneradorEditField.Value;
                    g=hex2dec(gHex);
                    gammaHex=app.gammaEditField.Value;
                    gamma=hex2dec(gammaHex);
                    deltaHex=app.deltaEditField.Value;
                    delta=hex2dec(deltaHex);
                    clavePublicaHex=(app.PublicaEditField.Value);
                    clavePublica=hex2dec(clavePublicaHex);
                    verificar1Hex=app.potenciaModularHex(gHex,app.SHA256delarchivoEditField.Value,pHex);
                    verificar1=hex2dec(verificar1Hex);
                    aux1=app.potenciaModular(clavePublica,gamma,p);
                    aux2=app.potenciaModular(gamma,delta,p);
                    verificar2=mod(aux1*aux2,p);
                    if verificar1==verificar2
                        app.VerificarLabel.Text='El archivo corresponde con la firma ingresada.';
                    else
                        app.VerificarLabel.Text='El archivo NO corresponde con la firma ingresada.';
                    end

            end
        end

        % Button pushed function: GuardarCargarFirmaButton
        function GuardarCargarFirmaButtonPushed(app, event)
            opciones=app.GuardarCargarFirmaButton.Text;
            switch opciones
                case 'Guardar'
                    if isempty(app.gammaEditField.Value)||isempty(app.deltaEditField.Value)
                        uialert(app.CriptUNUIFigure,'No se puede guardar la firma. Revise que todos los campos estén llenos e intente nuevamente.','Error');
                    end
                    gamma=app.gammaEditField.Value;
                    delta=app.deltaEditField.Value;
                    firma=append(gamma,newline,delta);
                    msg = '¿Desea guardar?.';
                    title = 'Guardar';
                    selection = uiconfirm(app.CriptUNUIFigure,msg,title, ...
                                'Options',{'Guardar','Cancelar'}, ...
                                'DefaultOption',1,'CancelOption',2);
                    switch selection
                        case 'Guardar'
                            filter = {'*.txt';'*.*'};
                            [file, path] = uiputfile(filter,'Guardar','Firma.txt');
                            figure(app.CriptUNUIFigure);
                            if file==0
                                return
                            end
                            writematrix(firma, fullfile(path,file), 'QuoteStrings',false);
                        case 'Cancelar'
                            return
                    end
                case 'Cargar'
                    [file,path] = uigetfile('*.txt');
                    figure(app.CriptUNUIFigure);
                    if file==0
                        return
                    end
                    firma=fileread(fullfile(path,file));
                    c=strsplit(firma,newline);
                    app.gammaEditField.Value=c{1};
                    app.deltaEditField.Value=c{2};
            end
        end

        % Button pushed function: PruebasButton_2
        function PruebasButton_2Pushed(app, event)
            for ii=1:10000
                a=randi([1,1024],1,1);
                b=randi([1,1024],1,1);
                aHex=dec2hex(a);
                bHex=dec2hex(b);
                if ~isequal(app.modHex(aHex,bHex),dec2hex(mod(a,b)))
                    disp('feo')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CriptUNUIFigure = uifigure('Visible', 'off');
            app.CriptUNUIFigure.Name = 'CriptUN';
            app.CriptUNUIFigure.Icon = 'Logo.png';
            app.CriptUNUIFigure.CloseRequestFcn = createCallbackFcn(app, @CriptUNUIFigureCloseRequest, true);
            app.ArchivoMenu = uimenu(app.CriptUNUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.SclsicosMenu = uimenu(app.CriptUNUIFigure);
            app.SclsicosMenu.Text = 'S. clásicos';
            app.DesplazamientoMenu_2 = uimenu(app.SclsicosMenu);
            app.DesplazamientoMenu_2.Text = 'Desplazamiento';
            app.CifrarMenu_7 = uimenu(app.DesplazamientoMenu_2);
            app.CifrarMenu_7.MenuSelectedFcn = createCallbackFcn(app, @DesplazamientoCifrarSelected, true);
            app.CifrarMenu_7.Text = 'Cifrar';
            app.DecifrarMenu_7 = uimenu(app.DesplazamientoMenu_2);
            app.DecifrarMenu_7.MenuSelectedFcn = createCallbackFcn(app, @DesplazamientoDecifrarSelected, true);
            app.DecifrarMenu_7.Text = 'Decifrar';
            app.SustitucinMenu_2 = uimenu(app.SclsicosMenu);
            app.SustitucinMenu_2.Text = 'Sustitución';
            app.CifrarMenu_8 = uimenu(app.SustitucinMenu_2);
            app.CifrarMenu_8.MenuSelectedFcn = createCallbackFcn(app, @SustitucionCifrarSelected, true);
            app.CifrarMenu_8.Text = 'Cifrar';
            app.DecifrarMenu_8 = uimenu(app.SustitucinMenu_2);
            app.DecifrarMenu_8.MenuSelectedFcn = createCallbackFcn(app, @SustitucionDecifrarSelected, true);
            app.DecifrarMenu_8.Text = 'Decifrar';
            app.AfnMenu_2 = uimenu(app.SclsicosMenu);
            app.AfnMenu_2.Text = 'Afín';
            app.CifrarMenu_10 = uimenu(app.AfnMenu_2);
            app.CifrarMenu_10.MenuSelectedFcn = createCallbackFcn(app, @AfinCifrarSelected, true);
            app.CifrarMenu_10.Text = 'Cifrar';
            app.DecifrarMenu_10 = uimenu(app.AfnMenu_2);
            app.DecifrarMenu_10.MenuSelectedFcn = createCallbackFcn(app, @AfinDecifrarSelected, true);
            app.DecifrarMenu_10.Text = 'Decifrar';
            app.VigenreMenu_2 = uimenu(app.SclsicosMenu);
            app.VigenreMenu_2.Text = 'Vigenère';
            app.CifrarMenu_11 = uimenu(app.VigenreMenu_2);
            app.CifrarMenu_11.MenuSelectedFcn = createCallbackFcn(app, @VigenereCifrarSelected, true);
            app.CifrarMenu_11.Text = 'Cifrar';
            app.DecifrarMenu_11 = uimenu(app.VigenreMenu_2);
            app.DecifrarMenu_11.MenuSelectedFcn = createCallbackFcn(app, @VigenereDecifrarSelected, true);
            app.DecifrarMenu_11.Text = 'Decifrar';
            app.HillMenu_2 = uimenu(app.SclsicosMenu);
            app.HillMenu_2.Text = 'Hill';
            app.CifrarMenu_12 = uimenu(app.HillMenu_2);
            app.CifrarMenu_12.MenuSelectedFcn = createCallbackFcn(app, @HillCifrarSelected, true);
            app.CifrarMenu_12.Text = 'Cifrar';
            app.DecifrarMenu_12 = uimenu(app.HillMenu_2);
            app.DecifrarMenu_12.MenuSelectedFcn = createCallbackFcn(app, @HillDecifrarSelected, true);
            app.DecifrarMenu_12.Text = 'Decifrar';
            app.ImgenesMenu_2 = uimenu(app.HillMenu_2);
            app.ImgenesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ImgenesMenu_2Selected, true);
            app.ImgenesMenu_2.Text = 'Imágenes';
            app.PermutacinMenu_2 = uimenu(app.SclsicosMenu);
            app.PermutacinMenu_2.Text = 'Permutación';
            app.CifrarMenu_9 = uimenu(app.PermutacinMenu_2);
            app.CifrarMenu_9.MenuSelectedFcn = createCallbackFcn(app, @PermutacionCifrarSelected, true);
            app.CifrarMenu_9.Text = 'Cifrar';
            app.DecifrarMenu_9 = uimenu(app.PermutacinMenu_2);
            app.DecifrarMenu_9.MenuSelectedFcn = createCallbackFcn(app, @PermutacionDecifrarSelected, true);
            app.DecifrarMenu_9.Text = 'Decifrar';
            app.CriptoanlisisMenu = uimenu(app.CriptUNUIFigure);
            app.CriptoanlisisMenu.Text = 'Criptoanálisis';
            app.DesplazamientoMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.DesplazamientoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @DesplazamientoMenu_3Selected, true);
            app.DesplazamientoMenu_3.Text = 'Desplazamiento';
            app.SustitucionMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.SustitucionMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SustitucionMenu_3Selected, true);
            app.SustitucionMenu_3.Text = 'Sustitución';
            app.AfnMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.AfnMenu_3.MenuSelectedFcn = createCallbackFcn(app, @AfnMenu_3Selected, true);
            app.AfnMenu_3.Text = 'Afín';
            app.VigenreMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.VigenreMenu_3.MenuSelectedFcn = createCallbackFcn(app, @VigenreMenu_3Selected, true);
            app.VigenreMenu_3.Text = 'Vigenère';
            app.HillMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.HillMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HillMenu_3Selected, true);
            app.HillMenu_3.Text = 'Hill';
            app.PermutacinMenu_3 = uimenu(app.CriptoanlisisMenu);
            app.PermutacinMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PermutacinMenu_3Selected, true);
            app.PermutacinMenu_3.Text = 'Permutación';
            app.SdeBloqueMenu = uimenu(app.CriptUNUIFigure);
            app.SdeBloqueMenu.MenuSelectedFcn = createCallbackFcn(app, @SdeBloqueMenuSelected, true);
            app.SdeBloqueMenu.Text = 'S. de Bloque';
            app.GammaPentagonalMenu = uimenu(app.SdeBloqueMenu);
            app.GammaPentagonalMenu.MenuSelectedFcn = createCallbackFcn(app, @GammaPentagonalMenuSelected, true);
            app.GammaPentagonalMenu.Text = 'Gamma Pentagonal';
            app.SDESMenu = uimenu(app.SdeBloqueMenu);
            app.SDESMenu.MenuSelectedFcn = createCallbackFcn(app, @SDESMenuSelected, true);
            app.SDESMenu.Text = 'S-DES';
            app.DESMenu = uimenu(app.SdeBloqueMenu);
            app.DESMenu.MenuSelectedFcn = createCallbackFcn(app, @DESMenuSelected, true);
            app.DESMenu.Text = 'DES';
            app.TRESDESMenu = uimenu(app.SdeBloqueMenu);
            app.TRESDESMenu.MenuSelectedFcn = createCallbackFcn(app, @TRESDESMenuSelected, true);
            app.TRESDESMenu.Text = '3-DES';
            app.AESMenu = uimenu(app.SdeBloqueMenu);
            app.AESMenu.MenuSelectedFcn = createCallbackFcn(app, @AESMenuSelected, true);
            app.AESMenu.Text = 'AES';
            app.SClavePblicaMenu = uimenu(app.CriptUNUIFigure);
            app.SClavePblicaMenu.Text = 'S. Clave Pública';
            app.RSAMenu = uimenu(app.SClavePblicaMenu);
            app.RSAMenu.MenuSelectedFcn = createCallbackFcn(app, @RSAMenuSelected, true);
            app.RSAMenu.Text = 'RSA';
            app.RabinMenu = uimenu(app.SClavePblicaMenu);
            app.RabinMenu.MenuSelectedFcn = createCallbackFcn(app, @RabinMenuSelected, true);
            app.RabinMenu.Text = 'Rabin';
            app.ElGamalMenezesVanstoneMenu = uimenu(app.SClavePblicaMenu);
            app.ElGamalMenezesVanstoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ElGamalMenezesVanstoneMenuSelected, true);
            app.ElGamalMenezesVanstoneMenu.Text = 'ElGamal (Menezes-Vanstone)';
            app.AyudaMenu = uimenu(app.CriptUNUIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.AcercaMenu = uimenu(app.AyudaMenu);
            app.AcercaMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercaMenuSelected, true);
            app.AcercaMenu.Text = 'Acerca';
            app.GridLayout = uigridlayout(app.CriptUNUIFigure);
            app.CriptUNTabGroup = uitabgroup(app.GridLayout);
            app.CriptoClasicaTab = uitab(app.CriptUNTabGroup);
            app.CriptoClasicaTab.Title = 'S. Clásicos';
            app.GridLayoutCriptoClasica = uigridlayout(app.CriptoClasicaTab);
            app.Label = uilabel(app.GridLayoutCriptoClasica);
            app.Label.Text = 'No ha seleccionado ningún criptosistema. Haga click derecho sobre este texto para seleccionar uno.';
            app.TextodeentradaPanel = uipanel(app.GridLayoutCriptoClasica);
            app.TextodeentradaPanel.Title = 'Texto de entrada';
            app.GridLayoutEntrada = uigridlayout(app.TextodeentradaPanel);
            app.TextAreaTexto = uitextarea(app.GridLayoutEntrada);
            app.TextAreaTexto.Placeholder = 'Ingrese un texto de entrada. Por defecto la aplicación ignorará los espacios, los números y los signos de puntuación.';
            app.LimpiarTextoButton = uibutton(app.GridLayoutEntrada, 'push');
            app.LimpiarTextoButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarTextoButtonPushed, true);
            app.LimpiarTextoButton.Text = 'Limpiar';
            app.CargarTextoButton = uibutton(app.GridLayoutEntrada, 'push');
            app.CargarTextoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarTextoButtonPushed, true);
            app.CargarTextoButton.Text = 'Cargar';
            app.PegarButton = uibutton(app.GridLayoutEntrada, 'push');
            app.PegarButton.ButtonPushedFcn = createCallbackFcn(app, @PegarButtonPushed, true);
            app.PegarButton.Text = 'Pegar';
            app.ResultadoPanel = uipanel(app.GridLayoutCriptoClasica);
            app.ResultadoPanel.Title = 'Resultado';
            app.GridLayoutResultado = uigridlayout(app.ResultadoPanel);
            app.TextAreaResultado = uitextarea(app.GridLayoutResultado);
            app.TextAreaResultado.Editable = 'off';
            app.TextAreaResultado.Placeholder = 'Haga click en el botón cifrar/decifrar para ver el resultado.';
            app.GuardarResultadoButton = uibutton(app.GridLayoutResultado, 'push');
            app.GuardarResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarResultadoButtonPushed, true);
            app.GuardarResultadoButton.Text = 'Guardar';
            app.LimpiarButton = uibutton(app.GridLayoutResultado, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CifrarDecifrarButton = uibutton(app.GridLayoutResultado, 'push');
            app.CifrarDecifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarDecifrarButtonPushed, true);
            app.CifrarDecifrarButton.Text = 'Cifrar/Decifrar';
            app.CopiarButton = uibutton(app.GridLayoutResultado, 'push');
            app.CopiarButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarButtonPushed, true);
            app.CopiarButton.Text = 'Copiar';
            app.ClavePanel = uipanel(app.GridLayoutCriptoClasica);
            app.ClavePanel.Title = 'Clave';
            app.GridLayoutClave = uigridlayout(app.ClavePanel);
            app.TextAreaClave = uitextarea(app.GridLayoutClave);
            app.TextAreaClave.Placeholder = 'Ingrese una clave.';
            app.CargarClaveButton = uibutton(app.GridLayoutClave, 'push');
            app.CargarClaveButton.ButtonPushedFcn = createCallbackFcn(app, @CargarClaveButtonPushed, true);
            app.CargarClaveButton.Text = 'Cargar';
            app.LimpiarClaveButton = uibutton(app.GridLayoutClave, 'push');
            app.LimpiarClaveButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarClaveButtonPushed, true);
            app.LimpiarClaveButton.Text = 'Limpiar';
            app.GenerarClaveButton = uibutton(app.GridLayoutClave, 'push');
            app.GenerarClaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarClaveButtonPushed, true);
            app.GenerarClaveButton.Enable = 'off';
            app.GenerarClaveButton.Text = 'Generar';
            app.SdeBloqueTab = uitab(app.CriptUNTabGroup);
            app.SdeBloqueTab.Title = 'S. de Bloque';
            app.GridLayout2 = uigridlayout(app.SdeBloqueTab);
            app.CriptosistemaDropDownLabel = uilabel(app.GridLayout2);
            app.CriptosistemaDropDownLabel.Text = 'Criptosistema';
            app.CriptosistemaDropDown = uidropdown(app.GridLayout2);
            app.CriptosistemaDropDown.Items = {'S-DES', 'DES', '3-DES', 'AES', 'Gamma Pentagonal'};
            app.CriptosistemaDropDown.ValueChangedFcn = createCallbackFcn(app, @CriptosistemaDropDownValueChanged, true);
            app.CriptosistemaDropDown.Value = 'S-DES';
            app.ModosdeciframientoDropDownLabel = uilabel(app.GridLayout2);
            app.ModosdeciframientoDropDownLabel.Text = 'Modos de ciframiento';
            app.ModosdeciframientoDropDown = uidropdown(app.GridLayout2);
            app.ModosdeciframientoDropDown.Items = {'ECB', 'CBC', 'CFB', 'OFB', 'CTR'};
            app.ModosdeciframientoDropDown.ValueChangedFcn = createCallbackFcn(app, @ModosdeciframientoDropDownValueChanged, true);
            app.ModosdeciframientoDropDown.Value = 'ECB';
            app.TamaodelaclavebitsDropDownLabel = uilabel(app.GridLayout2);
            app.TamaodelaclavebitsDropDownLabel.Text = 'Tamaño de la clave(bits)';
            app.TamaodelaclavebitsDropDown = uidropdown(app.GridLayout2);
            app.TamaodelaclavebitsDropDown.Items = {'10'};
            app.TamaodelaclavebitsDropDown.ValueChangedFcn = createCallbackFcn(app, @TamaodelaclavebitsDropDownValueChanged, true);
            app.TamaodelaclavebitsDropDown.Value = '10';
            app.ClaveEditFieldLabel = uilabel(app.GridLayout2);
            app.ClaveEditFieldLabel.Text = 'Clave';
            app.ClaveEditField = uieditfield(app.GridLayout2, 'text');
            app.ClaveEditField.Placeholder = 'Haga click derecho sobre este campo para ver las opciones de la clave.';
            app.IVEditFieldLabel = uilabel(app.GridLayout2);
            app.IVEditFieldLabel.Visible = 'off';
            app.IVEditFieldLabel.Text = 'IV';
            app.IVEditField = uieditfield(app.GridLayout2, 'text');
            app.IVEditField.Visible = 'off';
            app.IVEditField.Placeholder = 'Haga click derecho sobre este campo para ver las opciones del valor inicial.';
            app.ImagendeEntradaPanel = uipanel(app.GridLayout2);
            app.ImagendeEntradaPanel.Title = 'Imagen de Entrada';
            app.GridLayout3 = uigridlayout(app.ImagendeEntradaPanel);
            app.InputImage = uiimage(app.GridLayout3);
            app.CargarInputImageButton = uibutton(app.GridLayout3, 'push');
            app.CargarInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @CargarInputImageButtonPushed, true);
            app.CargarInputImageButton.Text = 'Cargar';
            app.LimpiarInputImageButton = uibutton(app.GridLayout3, 'push');
            app.LimpiarInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarInputImageButtonPushed, true);
            app.LimpiarInputImageButton.Text = 'Limpiar';
            app.ImagendeSalidaPanel = uipanel(app.GridLayout2);
            app.ImagendeSalidaPanel.Title = 'Imagen de Salida';
            app.GridLayout4 = uigridlayout(app.ImagendeSalidaPanel);
            app.OutputImage = uiimage(app.GridLayout4);
            app.GuardarOutputImageButton = uibutton(app.GridLayout4, 'push');
            app.GuardarOutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarOutputImageButtonPushed, true);
            app.GuardarOutputImageButton.Text = 'Guardar';
            app.LimpiarOutputImageButton = uibutton(app.GridLayout4, 'push');
            app.LimpiarOutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarOutputImageButtonPushed, true);
            app.LimpiarOutputImageButton.Text = 'Limpiar';
            app.CifrarButton = uibutton(app.GridLayout2, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = 'Cifrar';
            app.DecifrarButton = uibutton(app.GridLayout2, 'push');
            app.DecifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DecifrarButtonPushed, true);
            app.DecifrarButton.Text = 'Decifrar';
            app.PruebasButton = uibutton(app.GridLayout2, 'push');
            app.PruebasButton.ButtonPushedFcn = createCallbackFcn(app, @PruebasButtonPushed, true);
            app.PruebasButton.Enable = 'off';
            app.PruebasButton.Visible = 'off';
            app.PruebasButton.Text = 'Pruebas';
            app.TrabajandoLabel = uilabel(app.GridLayout2);
            app.TrabajandoLabel.Text = '';
            app.ClavePublicaTab = uitab(app.CriptUNTabGroup);
            app.ClavePublicaTab.Title = 'S. de Clave pública';
            app.ClavePublicaGridLayout = uigridlayout(app.ClavePublicaTab);
            app.CriptosistemaDropDown_2Label = uilabel(app.ClavePublicaGridLayout);
            app.CriptosistemaDropDown_2Label.Text = 'Criptosistema';
            app.CriptosistemaPublicoDropDown = uidropdown(app.ClavePublicaGridLayout);
            app.CriptosistemaPublicoDropDown.Items = {'Selecciones un criptosistema', 'RSA', 'Rabin', 'ElGamal (Menezes-Vanstone)'};
            app.CriptosistemaPublicoDropDown.ValueChangedFcn = createCallbackFcn(app, @CriptosistemaPublicoDropDownValueChanged, true);
            app.CriptosistemaPublicoDropDown.Value = 'Selecciones un criptosistema';
            app.OpcionesdeclavePanel = uipanel(app.ClavePublicaGridLayout);
            app.OpcionesdeclavePanel.Title = 'Opciones de clave';
            app.OpcionesdeclavePanel.Visible = 'off';
            app.OpcionesCPGridLayout = uigridlayout(app.OpcionesdeclavePanel);
            app.oc1EditFieldLabel = uilabel(app.OpcionesCPGridLayout);
            app.oc1EditFieldLabel.Interpreter = 'latex';
            app.oc1EditFieldLabel.Text = 'oc1';
            app.oc1EditField = uieditfield(app.OpcionesCPGridLayout, 'text');
            app.oc4EditFieldLabel = uilabel(app.OpcionesCPGridLayout);
            app.oc4EditFieldLabel.Interpreter = 'latex';
            app.oc4EditFieldLabel.Text = 'oc4';
            app.oc4EditField = uieditfield(app.OpcionesCPGridLayout, 'text');
            app.oc2EditFieldLabel = uilabel(app.OpcionesCPGridLayout);
            app.oc2EditFieldLabel.Interpreter = 'latex';
            app.oc2EditFieldLabel.Text = 'oc2';
            app.oc2EditField = uieditfield(app.OpcionesCPGridLayout, 'text');
            app.oc5EditFieldLabel = uilabel(app.OpcionesCPGridLayout);
            app.oc5EditFieldLabel.Interpreter = 'latex';
            app.oc5EditFieldLabel.Text = 'oc5';
            app.oc5EditField = uieditfield(app.OpcionesCPGridLayout, 'text');
            app.oc3EditFieldLabel = uilabel(app.OpcionesCPGridLayout);
            app.oc3EditFieldLabel.Interpreter = 'latex';
            app.oc3EditFieldLabel.Text = 'oc3';
            app.oc3EditField = uieditfield(app.OpcionesCPGridLayout, 'text');
            app.LimpiarButton_2 = uibutton(app.OpcionesCPGridLayout, 'push');
            app.LimpiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_2Pushed, true);
            app.LimpiarButton_2.Text = 'Limpiar';
            app.TextoclaroPanel = uipanel(app.ClavePublicaGridLayout);
            app.TextoclaroPanel.Title = 'Texto claro';
            app.TextoclaroPanel.Visible = 'off';
            app.TextoClaroGridLayout = uigridlayout(app.TextoclaroPanel);
            app.TextoClaroTextArea = uitextarea(app.TextoClaroGridLayout);
            app.TextocifradoPanel = uipanel(app.ClavePublicaGridLayout);
            app.TextocifradoPanel.Title = 'Texto cifrado';
            app.TextocifradoPanel.Visible = 'off';
            app.TextoCifradoGridLayout = uigridlayout(app.TextocifradoPanel);
            app.TextoCifradoTextArea = uitextarea(app.TextoCifradoGridLayout);
            app.CifrarCDButton = uibutton(app.ClavePublicaGridLayout, 'push');
            app.CifrarCDButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarCDButtonPushed, true);
            app.CifrarCDButton.Visible = 'off';
            app.CifrarCDButton.Text = 'Cifrar';
            app.CDSwitch = uiswitch(app.ClavePublicaGridLayout, 'slider');
            app.CDSwitch.Items = {'C', 'D'};
            app.CDSwitch.ValueChangedFcn = createCallbackFcn(app, @CDSwitchValueChanged, true);
            app.CDSwitch.Value = 'C';
            app.CifrarDecifrarLabel = uilabel(app.ClavePublicaGridLayout);
            app.CifrarDecifrarLabel.Text = 'Cifrar/Decifrar';
            app.GeneracindeclavesPanel = uipanel(app.ClavePublicaGridLayout);
            app.GeneracindeclavesPanel.Title = 'Generación de claves';
            app.GeneracindeclavesPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.GeneracindeclavesPanel);
            app.EjecutarButton = uibutton(app.GridLayout5, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.gc1EditFieldLabel = uilabel(app.GridLayout5);
            app.gc1EditFieldLabel.Interpreter = 'latex';
            app.gc1EditFieldLabel.Text = 'gc1';
            app.gc1EditField = uieditfield(app.GridLayout5, 'text');
            app.gc1EditField.Editable = 'off';
            app.gc4EditFieldLabel = uilabel(app.GridLayout5);
            app.gc4EditFieldLabel.Interpreter = 'latex';
            app.gc4EditFieldLabel.Text = 'gc4';
            app.gc4EditField = uieditfield(app.GridLayout5, 'text');
            app.gc4EditField.Editable = 'off';
            app.gc2EditFieldLabel = uilabel(app.GridLayout5);
            app.gc2EditFieldLabel.Interpreter = 'latex';
            app.gc2EditFieldLabel.Text = 'gc2';
            app.gc2EditField = uieditfield(app.GridLayout5, 'text');
            app.gc2EditField.Editable = 'off';
            app.gc5EditFieldLabel = uilabel(app.GridLayout5);
            app.gc5EditFieldLabel.Interpreter = 'latex';
            app.gc5EditFieldLabel.Text = 'gc5';
            app.gc5EditField = uieditfield(app.GridLayout5, 'text');
            app.gc5EditField.Editable = 'off';
            app.gc3EditFieldLabel = uilabel(app.GridLayout5);
            app.gc3EditFieldLabel.Interpreter = 'latex';
            app.gc3EditFieldLabel.Text = 'gc3';
            app.gc3EditField = uieditfield(app.GridLayout5, 'text');
            app.gc3EditField.Editable = 'off';
            app.gc6EditFieldLabel = uilabel(app.GridLayout5);
            app.gc6EditFieldLabel.Interpreter = 'latex';
            app.gc6EditFieldLabel.Text = 'gc6';
            app.gc6EditField = uieditfield(app.GridLayout5, 'text');
            app.gc6EditField.Editable = 'off';
            app.OpcionesEjecutarDropDown = uidropdown(app.GridLayout5);
            app.OpcionesEjecutarDropDown.Items = {'Opciones', 'Generar claves', 'Pegar arriba', 'Limpiar claves'};
            app.OpcionesEjecutarDropDown.Value = 'Opciones';
            app.GenerarClaveLabel = uilabel(app.ClavePublicaGridLayout);
            app.GenerarClaveLabel.Text = '';
            app.RespuestaLabel = uilabel(app.ClavePublicaGridLayout);
            app.RespuestaLabel.Text = '';
            app.FirmaDigitaldeArchivosTab = uitab(app.CriptUNTabGroup);
            app.FirmaDigitaldeArchivosTab.Title = 'Firma Digital de Archivos';
            app.GridLayout6 = uigridlayout(app.FirmaDigitaldeArchivosTab);
            app.EsquemadefirmaElGamalPanel = uipanel(app.GridLayout6);
            app.EsquemadefirmaElGamalPanel.Title = 'Esquema de firma ElGamal';
            app.EsquemaGridLayout = uigridlayout(app.EsquemadefirmaElGamalPanel);
            app.CargueunarchivoPanel = uipanel(app.EsquemaGridLayout);
            app.CargueunarchivoPanel.Title = '2. Cargue un archivo';
            app.CargarArchivoGridLayout = uigridlayout(app.CargueunarchivoPanel);
            app.CargarArchivoButton = uibutton(app.CargarArchivoGridLayout, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar';
            app.SHA256delarchivoEditFieldLabel = uilabel(app.CargarArchivoGridLayout);
            app.SHA256delarchivoEditFieldLabel.Text = 'SHA-256 del archivo';
            app.SHA256delarchivoEditField = uieditfield(app.CargarArchivoGridLayout, 'text');
            app.ConfigureelesquemaPanel = uipanel(app.EsquemaGridLayout);
            app.ConfigureelesquemaPanel.Title = '1. Configure el esquema';
            app.ConfiguracionGridLayout = uigridlayout(app.ConfigureelesquemaPanel);
            app.PrimoEditFieldLabel = uilabel(app.ConfiguracionGridLayout);
            app.PrimoEditFieldLabel.Text = 'Primo';
            app.PrimoEditField = uieditfield(app.ConfiguracionGridLayout, 'text');
            app.GeneradorEditFieldLabel = uilabel(app.ConfiguracionGridLayout);
            app.GeneradorEditFieldLabel.Text = 'Generador';
            app.GeneradorEditField = uieditfield(app.ConfiguracionGridLayout, 'text');
            app.CargarEsquemaButton = uibutton(app.ConfiguracionGridLayout, 'push');
            app.CargarEsquemaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEsquemaButtonPushed, true);
            app.CargarEsquemaButton.Text = 'Cargar';
            app.GuardarEsquemaButton = uibutton(app.ConfiguracionGridLayout, 'push');
            app.GuardarEsquemaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarEsquemaButtonPushed, true);
            app.GuardarEsquemaButton.Text = 'Guardar';
            app.GenerarEsquemaButton = uibutton(app.ConfiguracionGridLayout, 'push');
            app.GenerarEsquemaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarEsquemaButtonPushed, true);
            app.GenerarEsquemaButton.Text = 'Generar';
            app.OpcionesDropDown = uidropdown(app.ConfiguracionGridLayout);
            app.OpcionesDropDown.Items = {'¿Qué desea hacer?', 'Firmar un archivo', 'Verificar una firma'};
            app.OpcionesDropDown.ValueChangedFcn = createCallbackFcn(app, @OpcionesDropDownValueChanged, true);
            app.OpcionesDropDown.Value = '¿Qué desea hacer?';
            app.GenerelascontraseasPanel = uipanel(app.EsquemaGridLayout);
            app.GenerelascontraseasPanel.Title = '3. Genere las contraseñas';
            app.GenerelascontraseasPanel.Visible = 'off';
            app.PasswordGridLayout = uigridlayout(app.GenerelascontraseasPanel);
            app.PasswordEditFieldLabel = uilabel(app.PasswordGridLayout);
            app.PasswordEditFieldLabel.Text = 'Clave privada';
            app.PasswordEditField = uieditfield(app.PasswordGridLayout, 'text');
            app.CargarPasswordButton = uibutton(app.PasswordGridLayout, 'push');
            app.CargarPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @CargarPasswordButtonPushed, true);
            app.CargarPasswordButton.Text = 'Cargar';
            app.GuardarPasswordButton = uibutton(app.PasswordGridLayout, 'push');
            app.GuardarPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarPasswordButtonPushed, true);
            app.GuardarPasswordButton.Text = 'Guardar';
            app.GenerarPasswordButton = uibutton(app.PasswordGridLayout, 'push');
            app.GenerarPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarPasswordButtonPushed, true);
            app.GenerarPasswordButton.Text = 'Generar C. Priv.';
            app.PublicaEditFieldLabel = uilabel(app.PasswordGridLayout);
            app.PublicaEditFieldLabel.Text = 'Clave pública';
            app.PublicaEditField = uieditfield(app.PasswordGridLayout, 'text');
            app.GenerarCPbButton = uibutton(app.PasswordGridLayout, 'push');
            app.GenerarCPbButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarCPbButtonPushed, true);
            app.GenerarCPbButton.Text = 'Generar C. Púb.';
            app.FirmeeldocumentoPanel = uipanel(app.EsquemaGridLayout);
            app.FirmeeldocumentoPanel.Title = '4. Firme el documento';
            app.FirmeeldocumentoPanel.Visible = 'off';
            app.FirmarVerificarGridLayout = uigridlayout(app.FirmeeldocumentoPanel);
            app.deltaEditFieldLabel = uilabel(app.FirmarVerificarGridLayout);
            app.deltaEditFieldLabel.Interpreter = 'latex';
            app.deltaEditFieldLabel.Text = '$\delta$';
            app.deltaEditField = uieditfield(app.FirmarVerificarGridLayout, 'text');
            app.gammaEditFieldLabel = uilabel(app.FirmarVerificarGridLayout);
            app.gammaEditFieldLabel.Interpreter = 'latex';
            app.gammaEditFieldLabel.Text = '$\gamma$';
            app.gammaEditField = uieditfield(app.FirmarVerificarGridLayout, 'text');
            app.FirmarVerificarFirmaButton = uibutton(app.FirmarVerificarGridLayout, 'push');
            app.FirmarVerificarFirmaButton.ButtonPushedFcn = createCallbackFcn(app, @FirmarVerificarFirmaButtonPushed, true);
            app.FirmarVerificarFirmaButton.Text = 'Firmar';
            app.GuardarCargarFirmaButton = uibutton(app.FirmarVerificarGridLayout, 'push');
            app.GuardarCargarFirmaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarCargarFirmaButtonPushed, true);
            app.GuardarCargarFirmaButton.Text = 'Guardar';
            app.VerificarLabel = uilabel(app.FirmarVerificarGridLayout);
            app.PruebasButton_2 = uibutton(app.FirmarVerificarGridLayout, 'push');
            app.PruebasButton_2.ButtonPushedFcn = createCallbackFcn(app, @PruebasButton_2Pushed, true);
            app.PruebasButton_2.Visible = 'off';
            app.PruebasButton_2.Text = 'Pruebas';
            app.Button = uibutton(app.CriptUNUIFigure, 'state');
            app.Button.Text = 'Button';
            app.ContextMenu = uicontextmenu(app.CriptUNUIFigure);
            app.DesplazamientoMenu = uimenu(app.ContextMenu);
            app.DesplazamientoMenu.Text = 'Desplazamiento';
            app.CifrarMenu = uimenu(app.DesplazamientoMenu);
            app.CifrarMenu.MenuSelectedFcn = createCallbackFcn(app, @DesplazamientoCifrarSelected, true);
            app.CifrarMenu.Text = 'Cifrar';
            app.DecifrarMenu = uimenu(app.DesplazamientoMenu);
            app.DecifrarMenu.MenuSelectedFcn = createCallbackFcn(app, @DesplazamientoDecifrarSelected, true);
            app.DecifrarMenu.Text = 'Decifrar';
            app.SustitucinMenu = uimenu(app.ContextMenu);
            app.SustitucinMenu.Text = 'Sustitución';
            app.CifrarMenu_2 = uimenu(app.SustitucinMenu);
            app.CifrarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SustitucionCifrarSelected, true);
            app.CifrarMenu_2.Text = 'Cifrar';
            app.DecifrarMenu_2 = uimenu(app.SustitucinMenu);
            app.DecifrarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SustitucionDecifrarSelected, true);
            app.DecifrarMenu_2.Text = 'Decifrar';
            app.AfnMenu = uimenu(app.ContextMenu);
            app.AfnMenu.Text = 'Afín';
            app.CifrarMenu_4 = uimenu(app.AfnMenu);
            app.CifrarMenu_4.MenuSelectedFcn = createCallbackFcn(app, @AfinCifrarSelected, true);
            app.CifrarMenu_4.Text = 'Cifrar';
            app.DecifrarMenu_4 = uimenu(app.AfnMenu);
            app.DecifrarMenu_4.MenuSelectedFcn = createCallbackFcn(app, @AfinDecifrarSelected, true);
            app.DecifrarMenu_4.Text = 'Decifrar';
            app.VigenreMenu = uimenu(app.ContextMenu);
            app.VigenreMenu.Text = 'Vigenère';
            app.CifrarMenu_5 = uimenu(app.VigenreMenu);
            app.CifrarMenu_5.MenuSelectedFcn = createCallbackFcn(app, @VigenereCifrarSelected, true);
            app.CifrarMenu_5.Text = 'Cifrar';
            app.DecifrarMenu_5 = uimenu(app.VigenreMenu);
            app.DecifrarMenu_5.MenuSelectedFcn = createCallbackFcn(app, @VigenereDecifrarSelected, true);
            app.DecifrarMenu_5.Text = 'Decifrar';
            app.HillMenu = uimenu(app.ContextMenu);
            app.HillMenu.Text = 'Hill';
            app.CifrarMenu_6 = uimenu(app.HillMenu);
            app.CifrarMenu_6.MenuSelectedFcn = createCallbackFcn(app, @HillCifrarSelected, true);
            app.CifrarMenu_6.Text = 'Cifrar';
            app.DecifrarMenu_6 = uimenu(app.HillMenu);
            app.DecifrarMenu_6.MenuSelectedFcn = createCallbackFcn(app, @HillDecifrarSelected, true);
            app.DecifrarMenu_6.Text = 'Decifrar';
            app.ImgenesMenu = uimenu(app.HillMenu);
            app.ImgenesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImgenesMenuSelected, true);
            app.ImgenesMenu.Text = 'Imágenes';
            app.PermutacinMenu = uimenu(app.ContextMenu);
            app.PermutacinMenu.Text = 'Permutación';
            app.CifrarMenu_3 = uimenu(app.PermutacinMenu);
            app.CifrarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PermutacionCifrarSelected, true);
            app.CifrarMenu_3.Text = 'Cifrar';
            app.DecifrarMenu_3 = uimenu(app.PermutacinMenu);
            app.DecifrarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PermutacionDecifrarSelected, true);
            app.DecifrarMenu_3.Text = 'Decifrar';
            % Assign app.ContextMenu
            app.Label.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.CriptUNUIFigure);
            app.GenerarMenu = uimenu(app.ContextMenu2);
            app.GenerarMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarMenuSelected, true);
            app.GenerarMenu.Text = 'Generar';
            app.LimpiarMenu = uimenu(app.ContextMenu2);
            app.LimpiarMenu.MenuSelectedFcn = createCallbackFcn(app, @LimpiarMenuSelected, true);
            app.LimpiarMenu.Text = 'Limpiar';
            app.CargarMenu = uimenu(app.ContextMenu2);
            app.CargarMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarMenuSelected, true);
            app.CargarMenu.Text = 'Cargar';
            app.GuardarMenu = uimenu(app.ContextMenu2);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Text = 'Guardar';
            % Assign app.ContextMenu2
            app.ClaveEditField.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.CriptUNUIFigure);
            app.GenerarMenu_2 = uimenu(app.ContextMenu3);
            app.GenerarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GenerarMenu_2Selected, true);
            app.GenerarMenu_2.Text = 'Generar';
            app.LimpiarMenu_2 = uimenu(app.ContextMenu3);
            app.LimpiarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LimpiarMenu_2Selected, true);
            app.LimpiarMenu_2.Text = 'Limpiar';
            app.CargarMenu_2 = uimenu(app.ContextMenu3);
            app.CargarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CargarMenu_2Selected, true);
            app.CargarMenu_2.Text = 'Cargar';
            app.GuardarMenu_2 = uimenu(app.ContextMenu3);
            app.GuardarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenu_2Selected, true);
            app.GuardarMenu_2.Text = 'Guardar';
            % Assign app.ContextMenu3
            app.IVEditField.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.CriptUNUIFigure);
            app.LimpiarMenu_3 = uimenu(app.ContextMenu4);
            app.LimpiarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LimpiarMenu_3Selected, true);
            app.LimpiarMenu_3.Text = 'Limpiar';
            app.CargarMenu_3 = uimenu(app.ContextMenu4);
            app.CargarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CargarMenu_3Selected, true);
            app.CargarMenu_3.Text = 'Cargar';
            % Assign app.ContextMenu4
            app.TextoClaroTextArea.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.CriptUNUIFigure);
            app.LimpiarMenu_4 = uimenu(app.ContextMenu5);
            app.LimpiarMenu_4.MenuSelectedFcn = createCallbackFcn(app, @LimpiarMenu_4Selected, true);
            app.LimpiarMenu_4.Text = 'Limpiar';
            app.GuardarMenu_4 = uimenu(app.ContextMenu5);
            app.GuardarMenu_4.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenu_4Selected, true);
            app.GuardarMenu_4.Text = 'Guardar';
            % Assign app.ContextMenu5
            app.TextoCifradoTextArea.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.CriptUNUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CriptUN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CriptUNUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CriptUNUIFigure)
        end
    end
end