classdef CriptoAnalisisFuerzaBrutaWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        ResultadoPanel       matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        GuardarButton        matlab.ui.control.Button
        LimpiarButton_2      matlab.ui.control.Button
        CopiarButton         matlab.ui.control.Button
        AnalizarButton       matlab.ui.control.Button
        OutputTextArea       matlab.ui.control.TextArea
        TextodeentradaPanel  matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        CargarButton         matlab.ui.control.Button
        LimpiarButton        matlab.ui.control.Button
        PegarButton          matlab.ui.control.Button
        InputTextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        appPrincipal % Description
        metodo
    end
    
    methods (Access = private)
        
         function reporte=DesplazamientoCriptoanalisis(app,texto)
            reporte='';
            if strcmp(app.metodo,'Afin')
                return
            end
            titulo=upper('Resultados Obtenidos para el sistema de Desplazamiento:');
            reporte=append(reporte,titulo);
            for j=1:26
                reporte=append(reporte,newline,' - Texto desplazado ',int2str(j),' unidad(es):',newline);
                for i=1:length(texto)
                    reporte=append(reporte,app.appPrincipal.NumeroALetra(mod(app.appPrincipal.LetraANumero(texto(i))-j,26)));
                end
            end
         end
         function reporte=AfinCriptoanalisis(app,texto)
            reporte='';
            if strcmp(app.metodo,'Desplazamiento')
                return
            end
            titulo=upper('Resultados Obtenidos Para el sistema Afín:');
            reporte=append(reporte,titulo,newline);
            for i=1:26
                for j=1:26
                    if gcd(i,26)==1
                        reporte=append(reporte,sprintf(' -Con la clave a=%d y b=%d:',i,j),newline);
                        textoDecifrado=app.appPrincipal.AfinDecifrar(texto,[i,j]);
                        reporte=append(reporte,textoDecifrado,newline);
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN;
            app.metodo=app.appPrincipal.metodoFuerzaBruta;
            if strcmp(app.metodo,'Afin')
                app.UIFigure.Name='Criptoanálisis: Sistema Afín';
            end
            if strcmp(app.metodo,'Desplazamiento')
                app.UIFigure.Name='Criptoanálisis: Sistema Desplazamiento';
            end

        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure);
            if file==0
                return
            end
            app.InputTextArea.Value=fileread(fullfile(path,file));
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.InputTextArea.Value='';
        end

        % Button pushed function: PegarButton
        function PegarButtonPushed(app, event)
            app.InputTextArea.Value=clipboard('paste');
        end

        % Button pushed function: LimpiarButton_2
        function LimpiarButton_2Pushed(app, event)
            app.OutputTextArea.Value='';
        end

        % Button pushed function: CopiarButton
        function CopiarButtonPushed(app, event)
            cadena=join(string(app.InputTextArea.Value),newline);
            clipboard('copy',cadena);
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.UIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar resultado','Resultado.txt');
                    figure(app.UIFigure);
                    if file==0
                        return
                    end
                    writecell(app.InputTextArea.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            texto=lower(regexprep(convertStringsToChars(strjoin(app.InputTextArea.Value)),'[\W\d\r\n]',''));
            reporte=append(upper('Texto analizado:'),newline,texto,newline,newline);
            if isempty(texto)
                uialert(app.UIFigure,'No ingresó ningún texto o el texto ingresado no es válido.','Error');
                return
            end
            
            app.InputTextArea.Enable=0;
            app.CargarButton.Enable=0;
            app.LimpiarButton.Enable=0;
            app.PegarButton.Enable=0;
            pause(0.01);
            reporte=append(reporte,app.DesplazamientoCriptoanalisis(texto));
            reporte=append(reporte,app.AfinCriptoanalisis(texto));
            app.OutputTextArea.Value=reporte;

            app.InputTextArea.Enable=1;
            app.CargarButton.Enable=1;
            app.LimpiarButton.Enable=1;
            app.PegarButton.Enable=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'Logo.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TextodeentradaPanel = uipanel(app.GridLayout);
            app.TextodeentradaPanel.Title = 'Texto de entrada';
            app.GridLayout2 = uigridlayout(app.TextodeentradaPanel);
            app.InputTextArea = uitextarea(app.GridLayout2);
            app.InputTextArea.Placeholder = 'Ingrese el texto que desea decifrar. Seleccione en el panel de arriba el sistema en el que puede estar cifrado el texto.';
            app.PegarButton = uibutton(app.GridLayout2, 'push');
            app.PegarButton.ButtonPushedFcn = createCallbackFcn(app, @PegarButtonPushed, true);
            app.PegarButton.Text = 'Pegar';
            app.LimpiarButton = uibutton(app.GridLayout2, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CargarButton = uibutton(app.GridLayout2, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.ResultadoPanel = uipanel(app.GridLayout);
            app.ResultadoPanel.Title = 'Resultado';
            app.GridLayout3 = uigridlayout(app.ResultadoPanel);
            app.OutputTextArea = uitextarea(app.GridLayout3);
            app.OutputTextArea.Editable = 'off';
            app.OutputTextArea.Placeholder = 'Aquí se mostrará el resultado del análisis. Haga click en el botón Analizar para ver el resultado.';
            app.AnalizarButton = uibutton(app.GridLayout3, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.CopiarButton = uibutton(app.GridLayout3, 'push');
            app.CopiarButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarButtonPushed, true);
            app.CopiarButton.Text = 'Copiar';
            app.LimpiarButton_2 = uibutton(app.GridLayout3, 'push');
            app.LimpiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_2Pushed, true);
            app.LimpiarButton_2.Text = 'Limpiar';
            app.GuardarButton = uibutton(app.GridLayout3, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CriptoAnalisisFuerzaBrutaWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end