classdef CriptoAnalisisPermutacionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HillUIFigure               matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ResultadoPanel             matlab.ui.container.Panel
        ResultadoGridLayout        matlab.ui.container.GridLayout
        LimpiarButton              matlab.ui.control.Button
        AnalizarButton             matlab.ui.control.Button
        GuardarResultadoButton     matlab.ui.control.Button
        ResultadoTextArea          matlab.ui.control.TextArea
        OpcionesPanel              matlab.ui.container.Panel
        PalabrasGridLayout         matlab.ui.container.GridLayout
        NoseconocelacodificacinCheckBox  matlab.ui.control.CheckBox
        CodificacinEditField       matlab.ui.control.EditField
        CodificacinEditFieldLabel  matlab.ui.control.Label
        PalabraconocidaEditField   matlab.ui.control.EditField
        PalabraconocidaLabel       matlab.ui.control.Label
        IngresemSpinner            matlab.ui.control.Spinner
        IngresemSpinnerLabel       matlab.ui.control.Label
        EntradaPanel               matlab.ui.container.Panel
        EntradaGridLayout          matlab.ui.container.GridLayout
        LimpiarEntradaButton       matlab.ui.control.Button
        CargarEntradaButton        matlab.ui.control.Button
        EntradaTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        appPrincipal
        textoDeEntrada % Description
        palabraConocida
        codificacionConocida
    end
    
    methods (Access = private)
        
        function actualizarTextoDeEntrada(app)
            app.textoDeEntrada=lower(regexprep(convertStringsToChars(strjoin(app.EntradaTextArea.Value)),'[\W\d\r\n]',''));
        end

        function actualizarPalabraConocida(app)
            app.palabraConocida=lower(regexprep(convertStringsToChars(app.PalabraconocidaEditField.Value),'[\W\d\r\n]',''));
        end
        function actualizarCodificacionConocida(app)
            app.codificacionConocida=lower(regexprep(convertStringsToChars(app.CodificacinEditField.Value),'[\W\d\r\n]',''));
        end
        

        %Función que revisa si la información para el criptoanalisis es
        %correcta. Devuelve true si es correcta y false si no lo es
        function results = revisarInformacion(app)
            %no tiene sentido que la palabra conocida sea mayor que el
            %texto inicial
            if length(app.textoDeEntrada)<length(app.palabraConocida)
                results=false;
                uialert(app.HillUIFigure,'La palabra ingresada debe ser más pequeña que el texto a analizar.','Error');
                return
            end
            %no tiene sentido que la palabra conocida sea menor que m al
            %cuadrado
            if length(app.palabraConocida)<(app.IngresemSpinner.Value)^2
                results=false;
                uialert(app.HillUIFigure,'La palabra ingresada debe ser de longitud mayor a m cuadrado.','Error');
                return
            end
            %no tiene sentido que la palabra conocida y su codificacion
            %tengan tamaños diferentes
            if length(app.palabraConocida)~=length(app.codificacionConocida) && ~app.NoseconocelacodificacinCheckBox.Value
                results=false;
                uialert(app.HillUIFigure,'La palabra ingresada debe tener el mismo tamaño de su codificación.','Error');
                return
            end
            %no tiene sentido que no se ingrese una palabra
            if isempty(app.palabraConocida)
                results=false;
                uialert(app.HillUIFigure,'Debe ingresar una palabra para hacer el análisis.','Error');
                return
            end
            %no tiene sentido que no se ingrese un texto para analizar
            if isempty(app.textoDeEntrada)
                results=false;
                uialert(app.HillUIFigure,'Debe ingresar un texto para analizar.','Error');
                return
            end


            results=true;


        end


        %%Dados m, una palabra de tamaño m^2 y su codificacion, esta
        %%función encuentra la matriz asociada a la palabra y determina si
        %%tiene inversa o no, si la tiene calcula la matriz asociada a la
        %%clave y si no, devuelve una matriz de ceros. Se supondrá que
        %%palabra y codificacion están en letras minusculas
        function H = encontrarClave(app,m,palabra,codificacion)
            numerosDePalabra=repelem(-1,m^2);
            numerosDeCodificacion=repelem(-1,m^2);
            for i=1:m^2
                numerosDePalabra(i)=app.appPrincipal.LetraANumero(palabra(i));
                numerosDeCodificacion(i)=app.appPrincipal.LetraANumero(codificacion(i));
            end
            matrizPalabra=reshape(numerosDePalabra,m,[]);
            %%Se revisa que el determinante de matrizPalabra sea primo
            %%relativo con 26
            if gcd(app.appPrincipal.detMod(matrizPalabra,26),26)~=1
                H=nan;
                return
            end
            matrizCodificacion=reshape(numerosDeCodificacion,m,[]);
            matrizPalabrainv=app.appPrincipal.InversaModGauss(matrizPalabra,26);
            H=transpose(app.appPrincipal.MultMatMod(matrizCodificacion,matrizPalabrainv,26));
        end
        
        function [palabraAjustada,codificacionAjustada] = ajustarPalabraABloques(app,palabra,codificacion)
            m=app.IngresemSpinner.Value;
            indice=strfind(app.textoDeEntrada,codificacion);
            if isempty(indice)
                app.ResultadoTextArea.Value='No se encontró la codificación de la palabra en el texto de entrada. Revise nuevamente.';
                palabraAjustada='';
                codificacionAjustada='';
                return
            end
            if length(indice)>1
                disp('Hay más de una posible solución, se tomará la primera');
            end
            %Queremos obtener el índice de la codificación relativo al
            %indice encontrado en el texto
            ind=mod(m+1-mod(indice(1),m),m)+1;
            
            if length(codificacion(ind:end))<m^2
                disp('la palabra codificada ajustada no tiene el tamaño suficiente para hacer un análisis')
                palabraAjustada='';
                codificacionAjustada='';
                return
            end
            codificacionAjustada=codificacion(ind:ind+power(m,2)-1);
            palabraAjustada=palabra(ind:ind+power(m,2)-1);
        end
        
        function results = EsPermutacion(app,M)
            results=unique(sum(M))==1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN;
        end

        % Button pushed function: LimpiarEntradaButton
        function LimpiarEntradaButtonPushed(app, event)
            app.EntradaTextArea.Value='';
            app.actualizarTextoDeEntrada();
            
        end

        % Button pushed function: CargarEntradaButton
        function CargarEntradaButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.HillUIFigure);
            if file==0
                return
            end
            app.EntradaTextArea.Value=fileread(fullfile(path,file));
        end

        % Value changed function: NoseconocelacodificacinCheckBox
        function NoseconocelacodificacinCheckBoxValueChanged(app, event)
            value = app.NoseconocelacodificacinCheckBox.Value;
            app.CodificacinEditField.Enable=~value;
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            app.actualizarTextoDeEntrada();
            app.actualizarPalabraConocida();
            app.actualizarCodificacionConocida();
            app.ResultadoTextArea.Value='';

            if ~app.revisarInformacion()
                app.ResultadoTextArea.Value='No fue posible realizar el análisis.';
                return
            end

            %Hay 2 casos, cuando tenemos o no la codificación
            if app.NoseconocelacodificacinCheckBox.Value
                %%Hay que obtener la codificación de la palabra, la palabra
                %%debería ser mucho más larga que m^2 para obtener algún
                %%resultado
                %%Después de obtener la codificación,hacer lo de abajo
                
                app.ResultadoTextArea.Enable=0;
                pause(0.01)
                
                subCadenas=app.appPrincipal.subcadenasN(app.textoDeEntrada,length(app.palabraConocida));
                subCadenas=unique(subCadenas(:,1));
                resultado='ANÁLISIS PARA EL SISTEMA DE PERMUTACIÓN';
                for i=1:numel(subCadenas)
                    [palabraAjustada,codificacionAjustada]=app.ajustarPalabraABloques(app.palabraConocida,subCadenas{i});
                    if strcmp(palabraAjustada,'')
                        continue
                    end
                    H=app.encontrarClave(app.IngresemSpinner.Value,palabraAjustada,codificacionAjustada);
                    if isnan(H)
                        continue
                    end
                    textoDecifrado=app.appPrincipal.HillDecifrar(app.textoDeEntrada,H);
                    if isempty(strfind(textoDecifrado,app.palabraConocida))
                        continue
                    end
                    if ~app.EsPermutacion(H)
                        continue
                    end
                    resultado=append(resultado,newline,'=========================================================');
                    resultado=append(resultado,newline, '- La clave encontrada es:');
                    
                    %%Vamos a encontrar la permutación correspondiente a H
                    h=transpose(mod(find(H,length(H)),length(H)));
                    for j=1:length(H)
                        if h(j)==0
                            h(j)=length(H);
                        end
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%Poner codigo para convertir la
                    %%%%%%%%%%%%%%%%%%%%%%%%matriz a texto
                    archivotemporal=tempname;
                    writematrix(h,archivotemporal);
                    matrizTexto=fileread(append(archivotemporal,'.txt'));
                    delete(append(archivotemporal,'.txt'));
                    resultado=append(resultado,newline,matrizTexto);
    
                    resultado=append(resultado,newline,'- El texto decodificado es:');
                    resultado=append(resultado,newline,textoDecifrado);
                end
                app.ResultadoTextArea.Enable=1;
                if strcmp(resultado,'ANÁLISIS PARA EL SISTEMA DE PERMUTACIÓN')
                    resultado=append(resultado,newline,'No se encontró ninguna clave para la palabra indicada.');
                end
                app.ResultadoTextArea.Value=resultado;
                return
            else
                %%Hay que buscar la posición de la codificación de la
                %%palabra ingresada en el texto cifrado y cuadrarla para
                %%obtener los m bloques de mletras necesarios para intentar
                %%el ataque
                [palabraAjustada,codificacionAjustada]=app.ajustarPalabraABloques(app.palabraConocida,app.codificacionConocida);
                if strcmp(palabraAjustada,'')
                    return
                end
                H=app.encontrarClave(app.IngresemSpinner.Value,palabraAjustada,codificacionAjustada);
                if isnan(H)
                    app.ResultadoTextArea.Value='No fue posible realizar el análisis. La matriz asociada a la palabra no es invertible, por lo que no es posible encontrar la clave.';
                    return
                end
                resultado='- La clave encontrada es:';

                archivotemporal=tempname;
                writematrix(H,archivotemporal);
                matrizTexto=fileread(append(archivotemporal,'.txt'));
                delete(append(archivotemporal,'.txt'));
                resultado=append(resultado,newline,matrizTexto);
                resultado=append(resultado,newline,'- El texto decodificado es:');
                resultado=append(resultado,newline,app.appPrincipal.HillDecifrar(app.textoDeEntrada,H));
                
                app.ResultadoTextArea.Value=resultado;

            end
            

        end

        % Value changed function: EntradaTextArea
        function EntradaTextAreaValueChanged(app, event)
            app.actualizarTextoDeEntrada();
        end

        % Value changed function: PalabraconocidaEditField
        function PalabraconocidaEditFieldValueChanged(app, event)
            app.actualizarPalabraConocida();
            
        end

        % Value changed function: CodificacinEditField
        function CodificacinEditFieldValueChanged(app, event)
            app.actualizarCodificacionConocida();
            
        end

        % Callback function
        function PruebaButtonPushed(app, event)
            %H=app.encontrarClave(app.IngresemSpinner.Value,app.palabraConocida,app.codificacionConocida);
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.ResultadoTextArea.Value='';
        end

        % Button pushed function: GuardarResultadoButton
        function GuardarResultadoButtonPushed(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.HillUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar','Reporte.txt');
                    figure(app.HillUIFigure);
                    if file==0
                        return
                    end
                    reporte=join(string(app.ResultadoTextArea.Value),newline);
                    writematrix(reporte, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Close request function: HillUIFigure
        function HillUIFigureCloseRequest(app, event)
            figure(app.appPrincipal.CriptUNUIFigure)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HillUIFigure = uifigure('Visible', 'off');
            app.HillUIFigure.Name = 'Criptoanálisis: Sistema de Permutación';
            app.HillUIFigure.Icon = 'Logo.png';
            app.HillUIFigure.CloseRequestFcn = createCallbackFcn(app, @HillUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.HillUIFigure);
            app.EntradaPanel = uipanel(app.GridLayout);
            app.EntradaPanel.Title = 'Entrada';
            app.EntradaGridLayout = uigridlayout(app.EntradaPanel);
            app.EntradaTextArea = uitextarea(app.EntradaGridLayout);
            app.EntradaTextArea.ValueChangedFcn = createCallbackFcn(app, @EntradaTextAreaValueChanged, true);
            app.EntradaTextArea.Placeholder = 'Ingrese aquí el texto que desea decifrar.';
            app.CargarEntradaButton = uibutton(app.EntradaGridLayout, 'push');
            app.CargarEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEntradaButtonPushed, true);
            app.CargarEntradaButton.Text = 'Cargar';
            app.LimpiarEntradaButton = uibutton(app.EntradaGridLayout, 'push');
            app.LimpiarEntradaButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarEntradaButtonPushed, true);
            app.LimpiarEntradaButton.Text = 'Limpiar';
            app.OpcionesPanel = uipanel(app.GridLayout);
            app.OpcionesPanel.Title = 'Opciones';
            app.PalabrasGridLayout = uigridlayout(app.OpcionesPanel);
            app.IngresemSpinnerLabel = uilabel(app.PalabrasGridLayout);
            app.IngresemSpinnerLabel.Text = 'Ingrese m';
            app.IngresemSpinner = uispinner(app.PalabrasGridLayout);
            app.IngresemSpinner.Limits = [1 Inf];
            app.IngresemSpinner.Value = 1;
            app.PalabraconocidaLabel = uilabel(app.PalabrasGridLayout);
            app.PalabraconocidaLabel.Text = {'Palabra '; 'conocida'};
            app.PalabraconocidaEditField = uieditfield(app.PalabrasGridLayout, 'text');
            app.PalabraconocidaEditField.ValueChangedFcn = createCallbackFcn(app, @PalabraconocidaEditFieldValueChanged, true);
            app.PalabraconocidaEditField.Placeholder = 'Ingrese una palabra que pueda contener el texto decifrado.';
            app.CodificacinEditFieldLabel = uilabel(app.PalabrasGridLayout);
            app.CodificacinEditFieldLabel.Text = 'Codificación';
            app.CodificacinEditField = uieditfield(app.PalabrasGridLayout, 'text');
            app.CodificacinEditField.ValueChangedFcn = createCallbackFcn(app, @CodificacinEditFieldValueChanged, true);
            app.CodificacinEditField.Placeholder = 'Ingrese la codificación de la palabra.';
            app.NoseconocelacodificacinCheckBox = uicheckbox(app.PalabrasGridLayout);
            app.NoseconocelacodificacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoseconocelacodificacinCheckBoxValueChanged, true);
            app.NoseconocelacodificacinCheckBox.Text = 'No se conoce la codificación';
            app.ResultadoPanel = uipanel(app.GridLayout);
            app.ResultadoPanel.Title = 'Resultado';
            app.ResultadoGridLayout = uigridlayout(app.ResultadoPanel);
            app.ResultadoTextArea = uitextarea(app.ResultadoGridLayout);
            app.ResultadoTextArea.Placeholder = 'Haga click en el botón Analizar para ver el resultado.';
            app.GuardarResultadoButton = uibutton(app.ResultadoGridLayout, 'push');
            app.GuardarResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarResultadoButtonPushed, true);
            app.GuardarResultadoButton.Text = 'Guardar';
            app.AnalizarButton = uibutton(app.ResultadoGridLayout, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.LimpiarButton = uibutton(app.ResultadoGridLayout, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.HillUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CriptoAnalisisPermutacionWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HillUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HillUIFigure)
        end
    end
end