classdef CriptoAnalisisSustitucionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CriptoanlisisSustitucinUIFigure  matlab.ui.Figure
        CriptoAnaSustitucionGridLayout  matlab.ui.container.GridLayout
        InfoTextoPanel                  matlab.ui.container.Panel
        InfoTextoGridLayout             matlab.ui.container.GridLayout
        InfoTextoTabGroup               matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        InputGridLayout                 matlab.ui.container.GridLayout
        RefrescarButton                 matlab.ui.control.Button
        CargarInputButton               matlab.ui.control.Button
        LimpiarInputButton              matlab.ui.control.Button
        InputTextArea                   matlab.ui.control.TextArea
        LetrasTab                       matlab.ui.container.Tab
        LetrasGridLayout                matlab.ui.container.GridLayout
        LetrasUITable                   matlab.ui.control.Table
        DigramasTab                     matlab.ui.container.Tab
        DigramasGridLayout              matlab.ui.container.GridLayout
        DigramasUITable                 matlab.ui.control.Table
        TrigramasTab                    matlab.ui.container.Tab
        TrigramasGridLayout             matlab.ui.container.GridLayout
        TrigramasUITable                matlab.ui.control.Table
        NGramasTab                      matlab.ui.container.Tab
        NgramasGridLayout               matlab.ui.container.GridLayout
        NgramasSpinner                  matlab.ui.control.Spinner
        NSpinnerLabel                   matlab.ui.control.Label
        NgramasUITable                  matlab.ui.control.Table
        BuscarpatrnTab                  matlab.ui.container.Tab
        BuscarPatronGridLayout          matlab.ui.container.GridLayout
        BuscarPatronUITable             matlab.ui.control.Table
        BuscarPatronButton              matlab.ui.control.Button
        BuscarPatronEditField           matlab.ui.control.EditField
        IniciayterminaconLabel          matlab.ui.control.Label
        DatostilesTab                   matlab.ui.container.Tab
        EstadisticasGridLayout          matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        LetrasInglesTab                 matlab.ui.container.Tab
        LetrasInglesGridLayout          matlab.ui.container.GridLayout
        LetrasInglesTextArea            matlab.ui.control.TextArea
        BigramasInglesTab               matlab.ui.container.Tab
        BigramasInglesGridLayout        matlab.ui.container.GridLayout
        BigramasInglesTextArea          matlab.ui.control.TextArea
        TrigramasInglesTab              matlab.ui.container.Tab
        TrigramasInglesGridLayout       matlab.ui.container.GridLayout
        TrigramasInglesTextArea         matlab.ui.control.TextArea
        TetragramasInglesTab            matlab.ui.container.Tab
        TetragramasInglesGridLayout     matlab.ui.container.GridLayout
        TetragramasInglesTextArea       matlab.ui.control.TextArea
        TextofinalPanel                 matlab.ui.container.Panel
        TextoFinalGridLayout            matlab.ui.container.GridLayout
        CopiarclaveButton               matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        GuardarclaveButton              matlab.ui.control.Button
        TextoFinalTextArea              matlab.ui.control.TextArea
        SustitucionesPanel              matlab.ui.container.Panel
        SustitucionesGridLayout         matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        AEditField                      matlab.ui.control.EditField
        AEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.EditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        XEditFieldLabel                 matlab.ui.control.Label
        WEditField                      matlab.ui.control.EditField
        WEditFieldLabel                 matlab.ui.control.Label
        VEditField                      matlab.ui.control.EditField
        VEditFieldLabel                 matlab.ui.control.Label
        UEditField                      matlab.ui.control.EditField
        UEditFieldLabel                 matlab.ui.control.Label
        TEditField                      matlab.ui.control.EditField
        TEditFieldLabel                 matlab.ui.control.Label
        SEditField                      matlab.ui.control.EditField
        SEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.EditField
        REditFieldLabel                 matlab.ui.control.Label
        QEditField                      matlab.ui.control.EditField
        QEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.EditField
        PEditFieldLabel                 matlab.ui.control.Label
        OEditField                      matlab.ui.control.EditField
        OEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.EditField
        NEditFieldLabel                 matlab.ui.control.Label
        MEditField                      matlab.ui.control.EditField
        MEditFieldLabel                 matlab.ui.control.Label
        LEditField                      matlab.ui.control.EditField
        LEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.EditField
        KEditFieldLabel                 matlab.ui.control.Label
        JEditField                      matlab.ui.control.EditField
        JEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.EditField
        IEditFieldLabel                 matlab.ui.control.Label
        HEditField                      matlab.ui.control.EditField
        HEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.EditField
        GEditFieldLabel                 matlab.ui.control.Label
        FEditField                      matlab.ui.control.EditField
        FEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.EditField
        EEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.EditField
        DEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.EditField
        CEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.EditField
        BEditFieldLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        textoinicial='' % Description
        letrasReemplazadas=''
        appPrincipal % Description
        textoAdivinado=''
        clave='**************************' % Description
        tablaVacia % Description
    end
     
    methods (Access = private)

        
        function ActualizarTexto(app,letra,letra2)
            for i=1:length(app.textoinicial)
                if strcmpi(app.textoinicial(i),letra)
                    app.textoAdivinado(i)=lower(letra2);
                end
            end
            textoaux=convertStringsToChars(strjoin(string(reshape(app.textoAdivinado,[],1)),char(9)));
            textoaux2=convertStringsToChars(strjoin(string(reshape(app.textoinicial,[],1)),char(9)));
            app.TextoFinalTextArea.Value=append(textoaux,newline,upper(textoaux2));
            %disp(app.TextoFinalTextArea.Value)
            celda={app.AEditField.Value,...
                app.BEditField.Value,app.CEditField.Value,app.DEditField.Value,...
                app.EEditField.Value,app.FEditField.Value,app.GEditField.Value,...
                app.HEditField.Value,app.IEditField.Value,app.JEditField.Value,...
                app.KEditField.Value,app.LEditField.Value,app.MEditField.Value,...
                app.NEditField.Value,app.OEditField.Value,app.PEditField.Value,...
                app.QEditField.Value,app.REditField.Value,app.SEditField.Value,...
                app.TEditField.Value,app.UEditField.Value,app.VEditField.Value,...
                app.WEditField.Value,app.XEditField.Value,app.YEditField.Value,...
                app.ZEditField.Value};
            app.letrasReemplazadas=sort(char(join(celda,'')));
            app.actualizarClave();
        end
        
        function CambiarLetra(app,letraareemplazar,value)
            if length(value)>1
                uialert(app.CriptoanlisisSustitucinUIFigure,'Solo debe ingresar un caracter.Intente nuevamente','Error');
                return
            end
            if isempty(value)
                app.ActualizarTexto(letraareemplazar,'*');
            end
            if length(value)==1
                app.ActualizarTexto(letraareemplazar,value);
                
            end
            if ~strcmp(app.letrasReemplazadas,unique(app.letrasReemplazadas))
                uialert(app.CriptoanlisisSustitucinUIFigure,'Letra duplicada','Error');
            end
            
        end


        function reiniciarClave(app)
            app.AEditField.Value='';
            app.BEditField.Value='';
            app.CEditField.Value='';
            app.DEditField.Value='';
            app.EEditField.Value='';
            app.FEditField.Value='';
            app.GEditField.Value='';
            app.HEditField.Value='';
            app.IEditField.Value='';
            app.JEditField.Value='';
            app.KEditField.Value='';
            app.LEditField.Value='';
            app.MEditField.Value='';
            app.NEditField.Value='';
            app.OEditField.Value='';
            app.PEditField.Value='';
            app.QEditField.Value='';
            app.REditField.Value='';
            app.SEditField.Value='';
            app.TEditField.Value='';
            app.UEditField.Value='';
            app.VEditField.Value='';
            app.WEditField.Value='';
            app.XEditField.Value='';
            app.YEditField.Value='';
            app.ZEditField.Value='';
            app.textoAdivinado=repelem('*',length(app.textoinicial));
            textoaux=convertStringsToChars(strjoin(string(reshape(app.textoAdivinado,[],1)),char(9)));
            textoaux2=convertStringsToChars(strjoin(string(reshape(app.textoinicial,[],1)),char(9)));
            app.TextoFinalTextArea.Value=append(textoaux,newline,upper(textoaux2));
            app.actualizarClave();
        end
        
        function actualizarClave(app)
            celda={app.AEditField.Value,...
                app.BEditField.Value,app.CEditField.Value,app.DEditField.Value,...
                app.EEditField.Value,app.FEditField.Value,app.GEditField.Value,...
                app.HEditField.Value,app.IEditField.Value,app.JEditField.Value,...
                app.KEditField.Value,app.LEditField.Value,app.MEditField.Value,...
                app.NEditField.Value,app.OEditField.Value,app.PEditField.Value,...
                app.QEditField.Value,app.REditField.Value,app.SEditField.Value,...
                app.TEditField.Value,app.UEditField.Value,app.VEditField.Value,...
                app.WEditField.Value,app.XEditField.Value,app.YEditField.Value,...
                app.ZEditField.Value};
            for i=1:length(celda)
                if strcmp(convertStringsToChars(celda{i}),'')
                    celda{i}='*';
                end
            end
            casi=char(join(celda,''));
            alfabeto='abcdefghijklmnopqrstuvwxyz';
            
            for ii=1:26
                indice=strfind(casi,alfabeto(ii));
                if ~isempty(indice)
                    claveaux(ii)=alfabeto(indice);
                    faltantes(ii)=' ';
                else
                    claveaux(ii)='*';
                    faltantes(ii)=alfabeto(ii);
                end
            end
            app.clave=claveaux;
            app.Label.Text=faltantes;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN; %Para hacer que solo se ejecute si
            %se abre CriptUN
            app.TextoFinalTextArea.Value=repelem('*',length(app.textoinicial));
            app.tablaVacia=app.LetrasUITable.Data;
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            value = app.AEditField.Value;
            app.CambiarLetra('a',value);
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            value = app.BEditField.Value;
            app.CambiarLetra('b',value);
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            value = app.CEditField.Value;
            app.CambiarLetra('c',value);
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            app.CambiarLetra('d',value);
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            value = app.EEditField.Value;
            app.CambiarLetra('e',value);
        end

        % Value changed function: FEditField
        function FEditFieldValueChanged(app, event)
            value = app.FEditField.Value;
            app.CambiarLetra('f',value);
        end

        % Value changed function: GEditField
        function GEditFieldValueChanged(app, event)
            value = app.GEditField.Value;
            app.CambiarLetra('g',value);
        end

        % Value changed function: HEditField
        function HEditFieldValueChanged(app, event)
            value = app.HEditField.Value;
            app.CambiarLetra('h',value);
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            app.CambiarLetra('i',value);
        end

        % Value changed function: JEditField
        function JEditFieldValueChanged(app, event)
            value = app.JEditField.Value;
            app.CambiarLetra('j',value);
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
           app.CambiarLetra('k',value);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            app.CambiarLetra('l',value);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            value = app.MEditField.Value;
            app.CambiarLetra('m',value);
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.CambiarLetra('n',value);
        end

        % Value changed function: OEditField
        function OEditFieldValueChanged(app, event)
            value = app.OEditField.Value;
            app.CambiarLetra('o',value);
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            app.CambiarLetra('p',value);
        end

        % Value changed function: QEditField
        function QEditFieldValueChanged(app, event)
            value = app.QEditField.Value;
            app.CambiarLetra('q',value);
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            app.CambiarLetra('r',value);
        end

        % Value changed function: SEditField
        function SEditFieldValueChanged(app, event)
            value = app.SEditField.Value;
            app.CambiarLetra('s',value);
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.CambiarLetra('t',value);
        end

        % Value changed function: UEditField
        function UEditFieldValueChanged(app, event)
            value = app.UEditField.Value;
            app.CambiarLetra('u',value);
        end

        % Value changed function: VEditField
        function VEditFieldValueChanged(app, event)
            value = app.VEditField.Value;
            app.CambiarLetra('v',value);
        end

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)
            value = app.WEditField.Value;
            app.CambiarLetra('w',value);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.CambiarLetra('x',value);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.CambiarLetra('y',value);
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.CambiarLetra('z',value);
        end

        % Button pushed function: LimpiarButton
        function LimpiarClaveButtonPushed(app, event)
            app.reiniciarClave();
        end

        % Button pushed function: LimpiarInputButton
        function LimpiarInputButtonPushed(app, event)
            app.InputTextArea.Value='';
            app.textoinicial='';
            app.TextoFinalTextArea.Value='';
            app.textoAdivinado='';
             app.LetrasUITable.Data=app.tablaVacia;
            app.DigramasUITable.Data=app.tablaVacia;
            app.TrigramasUITable.Data=app.tablaVacia;
            app.NgramasUITable.Data=app.tablaVacia;
            app.actualizarClave();
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)

            app.textoinicial=upper(regexprep(convertStringsToChars(strjoin(app.InputTextArea.Value)),'[\W\d\r\n]',''));
            app.reiniciarClave();
            if strcmp(app.textoinicial,'')
                return
            end
            app.LetrasUITable.Data=app.appPrincipal.subcadenasN(app.textoinicial,1);
            app.DigramasUITable.Data=app.appPrincipal.subcadenasN(app.textoinicial,2);
            app.TrigramasUITable.Data=app.appPrincipal.subcadenasN(app.textoinicial,3);
            app.NgramasUITable.Data=app.appPrincipal.subcadenasN(app.textoinicial,app.NgramasSpinner.Value);
            app.reiniciarClave();
        end

        % Value changed function: NgramasSpinner
        function NgramasSpinnerValueChanged(app, event)
            value = app.NgramasSpinner.Value;
            app.NgramasUITable.Data=app.appPrincipal.subcadenasN(app.textoinicial,value);
        end

        % Button pushed function: BuscarPatronButton
        function BuscarPatronButtonPushed(app, event)
            texto=app.textoinicial;
            expr=char(join(string(app.BuscarPatronEditField.Value),''));
            if strcmp(expr,'')||strcmp(texto,'')
                return
            end
            aux=unique(regexpi(texto,expr,'match'));
            contaux={};
            porcentaje={};
            for i=1:length(aux)
                cont=0;
                for j=1:length(texto)-length(aux{i})+1
                    if strcmp(aux{i},texto(j:j+length(aux{i})-1))
                        cont=cont+1;
                    end
                end
                contaux{end+1}=cont;
                porcentaje{end+1}=cont/(length(texto)-length(aux{i}+1));
            end
            if isempty(aux)
                aux={'NO EXISTE'};
                contaux={0};
                porcentaje={0};
            end
            tabla=sortrows([aux;contaux;porcentaje]',2,'descend');
            app.BuscarPatronUITable.Data=tabla;


        end

        % Button pushed function: CopiarclaveButton
        function CopiarclaveButtonPushed(app, event)
            app.actualizarClave();
            clipboard('copy',app.clave);
        end

        % Button pushed function: CargarInputButton
        function CargarInputButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.CriptoanlisisSustitucinUIFigure);
            if file==0
                return
            end
            app.InputTextArea.Value=fileread(fullfile(path,file));
        end

        % Button pushed function: GuardarclaveButton
        function GuardarclaveButtonPushed(app, event)
            msg = '¿Desea guardarla clave?';
            title = 'Guardar';
            selection = uiconfirm(app.CriptoanlisisSustitucinUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    app.actualizarClave();
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar resultado','Resultado del criptoanálisis.txt');
                    figure(app.CriptoanlisisSustitucinUIFigure);
                    if file==0
                        return
                    end
                    writematrix(app.clave, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Close request function: CriptoanlisisSustitucinUIFigure
        function CriptoanlisisSustitucinUIFigureCloseRequest(app, event)
            figure(app.appPrincipal.CriptUNUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CriptoanlisisSustitucinUIFigure = uifigure('Visible', 'off');
            app.CriptoanlisisSustitucinUIFigure.Name = 'Criptoanálisis - Sustitución';
            app.CriptoanlisisSustitucinUIFigure.Icon = 'Logo.png';
            app.CriptoanlisisSustitucinUIFigure.CloseRequestFcn = createCallbackFcn(app, @CriptoanlisisSustitucinUIFigureCloseRequest, true);
            app.CriptoAnaSustitucionGridLayout = uigridlayout(app.CriptoanlisisSustitucinUIFigure);
            app.SustitucionesPanel = uipanel(app.CriptoAnaSustitucionGridLayout);
            app.SustitucionesPanel.Title = 'Sustituciones';
            app.SustitucionesGridLayout = uigridlayout(app.SustitucionesPanel);
            app.BEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.CEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.EEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.FEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.FEditField.ValueChangedFcn = createCallbackFcn(app, @FEditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @GEditFieldValueChanged, true);
            app.HEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.HEditField.ValueChangedFcn = createCallbackFcn(app, @HEditFieldValueChanged, true);
            app.IEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.JEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.JEditField.ValueChangedFcn = createCallbackFcn(app, @JEditFieldValueChanged, true);
            app.KEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.MEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.OEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.OEditFieldLabel.Text = 'O';
            app.OEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.OEditField.ValueChangedFcn = createCallbackFcn(app, @OEditFieldValueChanged, true);
            app.PEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.QEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.QEditField.ValueChangedFcn = createCallbackFcn(app, @QEditFieldValueChanged, true);
            app.REditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.SEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.SEditField.ValueChangedFcn = createCallbackFcn(app, @SEditFieldValueChanged, true);
            app.TEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.UEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.UEditFieldLabel.Text = 'U';
            app.UEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.UEditField.ValueChangedFcn = createCallbackFcn(app, @UEditFieldValueChanged, true);
            app.VEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @VEditFieldValueChanged, true);
            app.WEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.SustitucionesGridLayout);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.SustitucionesGridLayout, 'text');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.Label = uilabel(app.SustitucionesGridLayout);
            app.Label.Text = '';
            app.TextofinalPanel = uipanel(app.CriptoAnaSustitucionGridLayout);
            app.TextofinalPanel.Title = 'Texto final';
            app.TextoFinalGridLayout = uigridlayout(app.TextofinalPanel);
            app.TextoFinalTextArea = uitextarea(app.TextoFinalGridLayout);
            app.TextoFinalTextArea.Editable = 'off';
            app.GuardarclaveButton = uibutton(app.TextoFinalGridLayout, 'push');
            app.GuardarclaveButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarclaveButtonPushed, true);
            app.GuardarclaveButton.Text = 'Guardar clave';
            app.LimpiarButton = uibutton(app.TextoFinalGridLayout, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarClaveButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.CopiarclaveButton = uibutton(app.TextoFinalGridLayout, 'push');
            app.CopiarclaveButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarclaveButtonPushed, true);
            app.CopiarclaveButton.Text = 'Copiar clave';
            app.InfoTextoPanel = uipanel(app.CriptoAnaSustitucionGridLayout);
            app.InfoTextoPanel.Title = 'Información del texto';
            app.InfoTextoGridLayout = uigridlayout(app.InfoTextoPanel);
            app.InfoTextoTabGroup = uitabgroup(app.InfoTextoGridLayout);
            app.InputTab = uitab(app.InfoTextoTabGroup);
            app.InputTab.Title = 'Input';
            app.InputGridLayout = uigridlayout(app.InputTab);
            app.InputTextArea = uitextarea(app.InputGridLayout);
            app.InputTextArea.Placeholder = 'Ingrese el texto que desea decifrar. A continuación haga click en el botón Refrescar para actualizar los datos del texto. Para ver esta información, haga click en lalguna de las pestañas de la derecha.';
            app.LimpiarInputButton = uibutton(app.InputGridLayout, 'push');
            app.LimpiarInputButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarInputButtonPushed, true);
            app.LimpiarInputButton.Text = 'Limpiar';
            app.CargarInputButton = uibutton(app.InputGridLayout, 'push');
            app.CargarInputButton.ButtonPushedFcn = createCallbackFcn(app, @CargarInputButtonPushed, true);
            app.CargarInputButton.Text = 'Cargar';
            app.RefrescarButton = uibutton(app.InputGridLayout, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.LetrasTab = uitab(app.InfoTextoTabGroup);
            app.LetrasTab.Title = 'Letras';
            app.LetrasGridLayout = uigridlayout(app.LetrasTab);
            app.LetrasUITable = uitable(app.LetrasGridLayout);
            app.LetrasUITable.ColumnName = {'Letra'; 'Frecuencia'; '%'};
            app.LetrasUITable.RowName = {};
            app.LetrasUITable.ColumnSortable = [true true true];
            app.LetrasUITable.ColumnEditable = [false false false];
            app.DigramasTab = uitab(app.InfoTextoTabGroup);
            app.DigramasTab.Title = 'Digramas';
            app.DigramasGridLayout = uigridlayout(app.DigramasTab);
            app.DigramasUITable = uitable(app.DigramasGridLayout);
            app.DigramasUITable.ColumnName = {'Digrama'; 'Frecuencia'; '%'};
            app.DigramasUITable.RowName = {};
            app.DigramasUITable.ColumnSortable = [true true true];
            app.DigramasUITable.ColumnEditable = [false false false];
            app.TrigramasTab = uitab(app.InfoTextoTabGroup);
            app.TrigramasTab.Title = 'Trigramas';
            app.TrigramasGridLayout = uigridlayout(app.TrigramasTab);
            app.TrigramasUITable = uitable(app.TrigramasGridLayout);
            app.TrigramasUITable.ColumnName = {'Trigrama'; 'Frecuencia'; '%'};
            app.TrigramasUITable.RowName = {};
            app.TrigramasUITable.ColumnSortable = [true true true];
            app.TrigramasUITable.ColumnEditable = [false false false];
            app.NGramasTab = uitab(app.InfoTextoTabGroup);
            app.NGramasTab.Title = 'N-Gramas';
            app.NgramasGridLayout = uigridlayout(app.NGramasTab);
            app.NgramasUITable = uitable(app.NgramasGridLayout);
            app.NgramasUITable.ColumnName = {'N-grama'; 'Frecuencia'; '%'};
            app.NgramasUITable.RowName = {};
            app.NgramasUITable.ColumnSortable = [true true true];
            app.NgramasUITable.ColumnEditable = [false false false];
            app.NSpinnerLabel = uilabel(app.NgramasGridLayout);
            app.NSpinnerLabel.Text = 'N';
            app.NgramasSpinner = uispinner(app.NgramasGridLayout);
            app.NgramasSpinner.Limits = [1 Inf];
            app.NgramasSpinner.RoundFractionalValues = 'on';
            app.NgramasSpinner.ValueDisplayFormat = '%d';
            app.NgramasSpinner.ValueChangedFcn = createCallbackFcn(app, @NgramasSpinnerValueChanged, true);
            app.NgramasSpinner.Value = 1;
            app.BuscarpatrnTab = uitab(app.InfoTextoTabGroup);
            app.BuscarpatrnTab.Title = 'Buscar patrón';
            app.BuscarPatronGridLayout = uigridlayout(app.BuscarpatrnTab);
            app.IniciayterminaconLabel = uilabel(app.BuscarPatronGridLayout);
            app.IniciayterminaconLabel.Text = 'Patrón (regexp)';
            app.BuscarPatronEditField = uieditfield(app.BuscarPatronGridLayout, 'text');
            app.BuscarPatronButton = uibutton(app.BuscarPatronGridLayout, 'push');
            app.BuscarPatronButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarPatronButtonPushed, true);
            app.BuscarPatronButton.Text = 'Buscar';
            app.BuscarPatronUITable = uitable(app.BuscarPatronGridLayout);
            app.BuscarPatronUITable.ColumnName = {'Resultado'; 'Frecuencia'; '%'};
            app.BuscarPatronUITable.RowName = {};
            app.BuscarPatronUITable.ColumnSortable = [true true true];
            app.BuscarPatronUITable.ColumnEditable = [false false false];
            app.DatostilesTab = uitab(app.InfoTextoTabGroup);
            app.DatostilesTab.Title = 'Datos útiles';
            app.EstadisticasGridLayout = uigridlayout(app.DatostilesTab);
            app.TabGroup = uitabgroup(app.EstadisticasGridLayout);
            app.LetrasInglesTab = uitab(app.TabGroup);
            app.LetrasInglesTab.Title = 'Letras';
            app.LetrasInglesGridLayout = uigridlayout(app.LetrasInglesTab);
            app.LetrasInglesTextArea = uitextarea(app.LetrasInglesGridLayout);
            app.LetrasInglesTextArea.Editable = 'off';
            app.LetrasInglesTextArea.Value = {'Frecuencia de las letras del idioma inglés(en orden decreciente)'; '1. e (12.575645%)'; '2. t (9.085226%)'; '3. a (8.000395%)'; '4. o (7.591270%)'; '5. i (6.920007%)'; '6. n (6.903785%)'; '7. s (6.340880%)'; '8. h (6.236609%)'; '9. r (5.959034%)'; '10. d (4.317924%)'; '11. l (4.057231%)'; '12. u (2.841783%)'; '13. c (2.575785%)'; '14. m (2.560994%)'; '15. f (2.350463%)'; '16. w (2.224893%)'; '17. g (1.982677%)'; '18. y (1.900888%)'; '19. p (1.795742%)'; '20. b (1.535701%)'; '21. v (0.981717%)'; '22. k (0.739906%)'; '23. x (0.179556%)'; '24. j (0.145188%)'; '25. q (0.117571%)'; '26. z (0.079130%)'; 'Top 10 de letras al inicio de una palabra'; '1. t (15.94%)'; '2. a (15.5%)'; '3. i (8.23%)'; '4. s (7.75%)'; '5. o (7.12%)'; '6. c (5.97%)'; '7. m (4.26%)'; '8. f (4.08%)'; '9. p (4.0%)'; '10. w (3.82%)'; 'Top 10 de letras al final de una palabra'; '1. e (19.17%)'; '2. s (14.35%)'; '3. d (9.23%)'; '4. t (8.64%)'; '5. n (7.86%)'; '6. y (7.30%)'; '7. r (6.93%)'; '8. o (4.67%)'; '9. l (4.56%)'; '10. f (4.08%)'};
            app.BigramasInglesTab = uitab(app.TabGroup);
            app.BigramasInglesTab.Title = 'Bigramas';
            app.BigramasInglesGridLayout = uigridlayout(app.BigramasInglesTab);
            app.BigramasInglesTextArea = uitextarea(app.BigramasInglesGridLayout);
            app.BigramasInglesTextArea.Editable = 'off';
            app.BigramasInglesTextArea.Value = {'Top 20 de los bigramas más frecuentes'; '1. th (3.882543%)'; '2. he (3.681391%)'; '3. in (2.283899%)'; '4. er (2.178042%)'; '5. an (2.140460%)'; '6. re (1.749394%)'; '7. nd (1.571977%)'; '8. on (1.418244%)'; '9. en (1.383239%)'; '10. at (1.335523%)'; '11. ou (1.285484%)'; '12. ed (1.275779%)'; '13. ha (1.274742%)'; '14. to (1.169655%)'; '15. or (1.151094%)'; '16. it (1.134891%)'; '17. is (1.109877%)'; '18. hi (1.092302%)'; '19. es (1.092301%)'; '20. ng (1.053385%)'};
            app.TrigramasInglesTab = uitab(app.TabGroup);
            app.TrigramasInglesTab.Title = 'Trigramas';
            app.TrigramasInglesGridLayout = uigridlayout(app.TrigramasInglesTab);
            app.TrigramasInglesTextArea = uitextarea(app.TrigramasInglesGridLayout);
            app.TrigramasInglesTextArea.Editable = 'off';
            app.TrigramasInglesTextArea.Value = {'Top 20 de los trigramas más frecuentes'; '1. the (3.508232%)'; '2. and (1.593878%)'; '3. ing (1.147042%)'; '4. her (0.822444%)'; '5. hat (0.650715%)'; '6. his (0.596748%)'; '7. tha (0.593593%)'; '8. ere (0.560594%)'; '9. for (0.555372%)'; '10. ent (0.530771%)'; '11. ion (0.506454%)'; '12. ter (0.461099%)'; '13. was (0.460487%)'; '14. you (0.437213%)'; '15. ith (0.431250%)'; '16. ver (0.430732%)'; '17. all (0.422758%)'; '18. wit (0.397290%)'; '19. thi (0.394796%)'; '20. tio (0.378058%)'};
            app.TetragramasInglesTab = uitab(app.TabGroup);
            app.TetragramasInglesTab.Title = 'Tetragramas';
            app.TetragramasInglesGridLayout = uigridlayout(app.TetragramasInglesTab);
            app.TetragramasInglesTextArea = uitextarea(app.TetragramasInglesGridLayout);
            app.TetragramasInglesTextArea.Editable = 'off';
            app.TetragramasInglesTextArea.Value = {'Top 20 de los tetragramas más frecuentes'; '1. that (0.761242%)'; '2. ther (0.604501%)'; '3. with (0.573866%)'; '4. tion (0.551919%)'; '5. here (0.374549%)'; '6. ould (0.369920%)'; '7. ight (0.309440%)'; '8. have (0.290544%)'; '9. hich (0.284292%)'; '10. whic (0.283826%)'; '11. this (0.276333%)'; '12. thin (0.270413%)'; '13. they (0.262421%)'; '14. atio (0.262386%)'; '15. ever (0.260695%)'; '16. from (0.258580%)'; '17. ough (0.253447%)'; '18. were (0.231089%)'; '19. hing (0.229944%)'; '20. ment (0.223347%)'; '20. ment (0.223347%)'};
            % Show the figure after all components are created
            app.CriptoanlisisSustitucinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CriptoAnalisisSustitucionWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CriptoanlisisSustitucinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CriptoanlisisSustitucinUIFigure)
        end
    end
end