classdef CriptoAnalisisVigenereWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CriptoanlisisdelsistemadeVigenereUIFigure  matlab.ui.Figure
        VigenereGridLayout             matlab.ui.container.GridLayout
        VigenereTabGroup               matlab.ui.container.TabGroup
        TextodeentradaTab              matlab.ui.container.Tab
        TextodeEntradaGridLayout       matlab.ui.container.GridLayout
        LimpiarButton                  matlab.ui.control.Button
        CargarTextoButton              matlab.ui.control.Button
        InputTextArea                  matlab.ui.control.TextArea
        LongituddelaclaveTab           matlab.ui.container.Tab
        LongClaveGridLayout            matlab.ui.container.GridLayout
        AIOCPanel                      matlab.ui.container.Panel
        AIOCGridLayout                 matlab.ui.container.GridLayout
        AIOCTextArea                   matlab.ui.control.TextArea
        TablasMg                       matlab.ui.container.Tab
        TablasMgGridLayout             matlab.ui.container.GridLayout
        TablasMgUITable                matlab.ui.control.Table
        LongituddelaclaveSpinner       matlab.ui.control.Spinner
        LongituddelaclaveSpinnerLabel  matlab.ui.control.Label
        ClavesposiblesTab              matlab.ui.container.Tab
        ClavesposiblesGridLayout       matlab.ui.container.GridLayout
        ClavesEditField                matlab.ui.control.EditField
        ClavesEditFieldLabel           matlab.ui.control.Label
        DiferenciaconrespectoalmxSpinner  matlab.ui.control.Spinner
        Radioconrespectoa0065Label     matlab.ui.control.Label
        ClavesposiblesUITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        appPrincipal % Description
    end
    
    methods (Access = private)
        
        function actualizarAnalisis(app)
            texto=lower(regexprep(convertStringsToChars(strjoin(app.InputTextArea.Value)),'[\W\d\r\n]',''));
            [reporte,tamClave]=app.AnalisisIndiceDeCoincidencias(texto);
            app.AIOCTextArea.Value=reporte;
            %automaticamente toma la clave de IdC
            app.LongituddelaclaveSpinner.Value=tamClave;
            app.llenarTablaMg(texto,tamClave);
        end
        

        function mgyi=MgYi(app,texto)
            f=app.frecuenciasTexto(texto);
            n=length(texto);
            mgyi=[];
            for i=1:26
                p=circshift(app.appPrincipal.probabilidadesAlfabeto,i-1);
                mgyi=[mgyi,sum(f.*p)/n];
            end
        end
        
        function F=frecuenciasTexto(app,texto)
            F=[];
            for i=1:26
                F=[F,count(texto,app.appPrincipal.alfabeto(i))];
            end
        end

        function I=indiceDeCoincidencias(app,texto)
            f=app.frecuenciasTexto(texto);
            I=sum(f.*(f-1))/(length(texto)*(length(texto)-1));
        end

        function [reporte,tamClave]=AnalisisIndiceDeCoincidencias(app,texto)
            error=100;
            tamClave=0;
            reporte='';
            while error>0.007&&tamClave<(length(texto)/2)
                promedio=0;
                tamClave=tamClave+1;
                tamTexto=tamClave*floor(length(texto)/tamClave);
                aux=reshape(texto(1:tamTexto),tamClave,[]);
                reporte=append(reporte,'-El IdC para m=',int2str(tamClave),' es:');
                for i=1:tamClave
                    indice=app.indiceDeCoincidencias(aux(i,:));
                    promedio=promedio+indice;
                    reporte=append(reporte,num2str(indice),', ');
                end
                reporte=reporte(1:end-2);
                reporte=append(reporte,newline);
                promedio=promedio/tamClave;
                error=abs(promedio-0.065);
            end
            
            reporte=append(reporte,'Resultado: Es muy probable que la longitud de la clave sea ',int2str(tamClave),'.',newline);
            reporte=append(reporte,'Si no obtuvo los resultados deseados, pruebe con alguno de los divisores del número.',newline);
        end

        function llenarTablaMg(app,texto,tamClave)
            tamTextoAjustado=tamClave*floor(length(texto)/tamClave);
            aux=reshape(texto(1:tamTextoAjustado),tamClave,[]);
            matrizMg=zeros(tamClave,26);
            for i=1:tamClave
                matrizMg(i,:)=app.MgYi(aux(i,:));
            end
            app.TablasMgUITable.Data=num2cell(matrizMg);
            app.TablasMgUITable.RowName=[1:tamClave];
            app.actualizarPosiblesClaves();
            %archivotemporal=tempname;
            %writematrix(matrizMg,archivotemporal,'Delimiter','tab');
            %textoMg=fileread(append(archivotemporal,'.txt'));
            %delete(append(archivotemporal,'.txt'));
            %reporte=append(reporte,'La matriz Mg es:',newline,textoMg,newline);
            %disp(reporte)
        end

        function actualizarPosiblesClaves(app)
            tablaMg=cell2mat(app.TablasMgUITable.Data);
            posiblesClaves={};
            for i=1:size(tablaMg,1)
                aux='';
                maximo=max(tablaMg(i,:));
                for j=1:26
                    if tablaMg(i,j)>maximo-app.DiferenciaconrespectoalmxSpinner.Value
                        aux=append(aux,app.appPrincipal.alfabeto(j));
                    end
                end
                posiblesClaves=[posiblesClaves,aux];
            end
            numPosiblesClaves=1;
            for i=1:length(posiblesClaves)
                numPosiblesClaves=numPosiblesClaves*length(posiblesClaves{i});
            end
            app.ClavesEditField.Value=int2str(numPosiblesClaves);
            if numPosiblesClaves>1000
                app.ClavesposiblesUITable.Data={};
                uialert(app.CriptoanlisisdelsistemadeVigenereUIFigure,'Hay demasiadas claves posibles para mostrar en pantalla (más de 1000).','Error - Demasiadas posibilidades');
                return
            end

            claves=app.makeCombinations(posiblesClaves);
            textosDecifrados=repmat({''},numPosiblesClaves,1);
            texto=lower(regexprep(convertStringsToChars(strjoin(app.InputTextArea.Value)),'[\W\d\r\n]',''));
            for i=1:numPosiblesClaves
                textosDecifrados{i}=app.appPrincipal.VigenereDecifrar(texto,claves{i});
            end
            tablaaux=[claves,textosDecifrados];
            app.ClavesposiblesUITable.Data=tablaaux;
        end

        function M=makeCombinations(~,celda)
            num=1;
            for i=1:numel(celda)
                num=num*numel(celda{i});
            end
            aux1=num;
            aux2=1;
            M=zeros(numel(celda),num);
            for i=1:numel(celda)
                aux1=aux1/numel(celda{i});
                aux3='';
                aux4=celda{i};
                for j=1:numel(celda{i})
                    aux3=append(aux3,repmat(aux4(j),1,aux1));
                end
                aux3=repmat(aux3,1,aux2);
                M(i,:)=aux3;
                aux2=aux2*numel(celda{i});
            end
            M=transpose(char(M));
            M=join(num2cell(M),'');
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN;
        end

        % Button pushed function: CargarTextoButton
        function CargarTextoButtonPushed(app, event)
             [file,path] = uigetfile('*.txt');
            figure(app.CriptoanlisisdelsistemadeVigenereUIFigure);
            if file==0
                return
            end
            app.InputTextArea.Value=fileread(fullfile(path,file));

            app.actualizarAnalisis();

            %Hacer el análisis inmediatamente después de cargarlo
        end

        % Value changed function: InputTextArea
        function InputTextAreaValueChanged(app, event)
            value = app.InputTextArea.Value;
            %hacer el análisis
            app.actualizarAnalisis();
            
        end

        % Value changed function: LongituddelaclaveSpinner
        function LongituddelaclaveSpinnerValueChanged(app, event)
            value = app.LongituddelaclaveSpinner.Value;
            texto=lower(regexprep(convertStringsToChars(strjoin(app.InputTextArea.Value)),'[\W\d\r\n]',''));
            app.llenarTablaMg(texto,value);
        end

        % Value changed function: DiferenciaconrespectoalmxSpinner
        function DiferenciaconrespectoalmxSpinnerValueChanged(app, event)
            value = app.DiferenciaconrespectoalmxSpinner.Value;
            app.actualizarPosiblesClaves();
        end

        % Close request function: CriptoanlisisdelsistemadeVigenereUIFigure
        function CriptoanlisisdelsistemadeVigenereUIFigureCloseRequest(app, event)
            figure(app.appPrincipal.CriptUNUIFigure);
            delete(app)
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.InputTextArea.Value='';
            app.actualizarAnalisis();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CriptoanlisisdelsistemadeVigenereUIFigure = uifigure('Visible', 'off');
            app.CriptoanlisisdelsistemadeVigenereUIFigure.Name = 'Criptoanálisis del sistema de Vigenere';
            app.CriptoanlisisdelsistemadeVigenereUIFigure.Icon = 'Logo.png';
            app.CriptoanlisisdelsistemadeVigenereUIFigure.CloseRequestFcn = createCallbackFcn(app, @CriptoanlisisdelsistemadeVigenereUIFigureCloseRequest, true);
            app.VigenereGridLayout = uigridlayout(app.CriptoanlisisdelsistemadeVigenereUIFigure);
            app.VigenereTabGroup = uitabgroup(app.VigenereGridLayout);
            app.TextodeentradaTab = uitab(app.VigenereTabGroup);
            app.TextodeentradaTab.Title = 'Texto de entrada';
            app.TextodeEntradaGridLayout = uigridlayout(app.TextodeentradaTab);
            app.InputTextArea = uitextarea(app.TextodeEntradaGridLayout);
            app.InputTextArea.ValueChangedFcn = createCallbackFcn(app, @InputTextAreaValueChanged, true);
            app.InputTextArea.Placeholder = 'Ingrese el texto que desea decifrar.';
            app.CargarTextoButton = uibutton(app.TextodeEntradaGridLayout, 'push');
            app.CargarTextoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarTextoButtonPushed, true);
            app.CargarTextoButton.Text = 'Cargar';
            app.LimpiarButton = uibutton(app.TextodeEntradaGridLayout, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.LongituddelaclaveTab = uitab(app.VigenereTabGroup);
            app.LongituddelaclaveTab.Title = 'Longitud de la clave';
            app.LongClaveGridLayout = uigridlayout(app.LongituddelaclaveTab);
            app.AIOCPanel = uipanel(app.LongClaveGridLayout);
            app.AIOCPanel.Title = 'Análisis por Indices de Coincidencias';
            app.AIOCGridLayout = uigridlayout(app.AIOCPanel);
            app.AIOCTextArea = uitextarea(app.AIOCGridLayout);
            app.AIOCTextArea.Editable = 'off';
            app.TablasMg = uitab(app.VigenereTabGroup);
            app.TablasMg.Title = 'Tablas Mg';
            app.TablasMgGridLayout = uigridlayout(app.TablasMg);
            app.LongituddelaclaveSpinnerLabel = uilabel(app.TablasMgGridLayout);
            app.LongituddelaclaveSpinnerLabel.Text = 'Longitud de la clave';
            app.LongituddelaclaveSpinner = uispinner(app.TablasMgGridLayout);
            app.LongituddelaclaveSpinner.Limits = [1 Inf];
            app.LongituddelaclaveSpinner.ValueChangedFcn = createCallbackFcn(app, @LongituddelaclaveSpinnerValueChanged, true);
            app.LongituddelaclaveSpinner.Value = 1;
            app.TablasMgUITable = uitable(app.TablasMgGridLayout);
            app.TablasMgUITable.ColumnName = {'a'; 'b'; 'c'; 'd'; 'e'; 'f'; 'g'; 'h'; 'i'; 'j'; 'k'; 'l'; 'm'; 'n'; 'o'; 'p'; 'q'; 'r'; 's'; 't'; 'u'; 'v'; 'w'; 'x'; 'y'; 'z'};
            app.TablasMgUITable.RowName = {};
            app.TablasMgUITable.ColumnSortable = true;
            app.ClavesposiblesTab = uitab(app.VigenereTabGroup);
            app.ClavesposiblesTab.Title = 'Claves posibles';
            app.ClavesposiblesGridLayout = uigridlayout(app.ClavesposiblesTab);
            app.ClavesposiblesUITable = uitable(app.ClavesposiblesGridLayout);
            app.ClavesposiblesUITable.ColumnName = {'Clave'; 'Decodificación'};
            app.ClavesposiblesUITable.RowName = {};
            app.ClavesposiblesUITable.ColumnSortable = true;
            app.ClavesposiblesUITable.ColumnEditable = [false false];
            app.Radioconrespectoa0065Label = uilabel(app.ClavesposiblesGridLayout);
            app.Radioconrespectoa0065Label.Text = {'Diferencia (con '; 'respecto al máx)'};
            app.DiferenciaconrespectoalmxSpinner = uispinner(app.ClavesposiblesGridLayout);
            app.DiferenciaconrespectoalmxSpinner.Step = 0.0005;
            app.DiferenciaconrespectoalmxSpinner.Limits = [0.0005 0.02];
            app.DiferenciaconrespectoalmxSpinner.ValueDisplayFormat = '%.4f';
            app.DiferenciaconrespectoalmxSpinner.ValueChangedFcn = createCallbackFcn(app, @DiferenciaconrespectoalmxSpinnerValueChanged, true);
            app.DiferenciaconrespectoalmxSpinner.Value = 0.001;
            app.ClavesEditFieldLabel = uilabel(app.ClavesposiblesGridLayout);
            app.ClavesEditFieldLabel.Text = '#Claves';
            app.ClavesEditField = uieditfield(app.ClavesposiblesGridLayout, 'text');
            app.ClavesEditField.Editable = 'off';
            % Show the figure after all components are created
            app.CriptoanlisisdelsistemadeVigenereUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CriptoAnalisisVigenereWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CriptoanlisisdelsistemadeVigenereUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CriptoanlisisdelsistemadeVigenereUIFigure)
        end
    end
end