classdef ImagenesHill < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImgHillUIFigure       matlab.ui.Figure
        ImgHillGridLayout     matlab.ui.container.GridLayout
        OpcionesPanel         matlab.ui.container.Panel
        OpcionesGridLayout    matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        ReiniciarButton       matlab.ui.control.Button
        EjecutarButton        matlab.ui.control.Button
        CDLabel               matlab.ui.control.Label
        CDSwitch              matlab.ui.control.Switch
        ClavePanel            matlab.ui.container.Panel
        ClaveGridLayout       matlab.ui.container.GridLayout
        ClaveTextArea         matlab.ui.control.TextArea
        ResultadoPanel        matlab.ui.container.Panel
        ImgSalidaGridLayout   matlab.ui.container.GridLayout
        ImgSalida             matlab.ui.control.Image
        ImgEntradaPanel       matlab.ui.container.Panel
        ImgEntradaGridLayout  matlab.ui.container.GridLayout
        ImgEntrada            matlab.ui.control.Image
        ContextMenu           matlab.ui.container.ContextMenu
        LimpiarclaveMenu      matlab.ui.container.Menu
        GenerarclaveMenu      matlab.ui.container.Menu
        GuardarclaveMenu      matlab.ui.container.Menu
        CargarclaveMenu       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        appPrincipal% Description
        modoGris
        imagen
        divisoresClave
    end
    
    methods (Access = private)
        
        function imagenCifrada=HillCifrar(app,imagen,H)
            n=length(H);
            altoImagen=size(imagen,1);
            %Debemos convertir la imagen en un vector
            imgVector=reshape(imagen,1,[]);
            dimOriginal=length(imgVector);
            %Debemos asegurarnos que la dimensión de imgVector concuerda
            %con el tamaño de la matriz H, si no, agregamos 0 hasta que
            %concuerde, este nuevo vector se llamara imgVectoraux
            imgVectoraux=zeros(1,n*ceil(length(imgVector)/n));
            imgVectoraux(1:dimOriginal)=imgVector;
            %Ahora debemos hacer que imgVectoraux tenga dimensiones para
            %poderlo multiplicar con H
            imgMat=transpose(reshape(imgVectoraux,n,[]));
            %imagenCifrada=app.appPrincipal.MultMatMod(imgMat,H,256);
            imagenCifrada=mod(imgMat*H,256);
            %Después de obtener una matrizCifrada, debemos convertirla al
            %formato de la imagen original
            imagenCifrada=reshape(transpose(imagenCifrada),1,[]);
            imagenCifrada=imagenCifrada(1:dimOriginal);
            imagenCifrada=reshape(imagenCifrada,altoImagen,[]);
            
        end

        function imagenDecifrada=HillDecifrar(app,imagen,H)
            n=length(H);
            altoImagen=size(imagen,1);
            %invH=app.appPrincipal.InversaModGauss(H,256);
            invH=H;
            %Decodificación

            %Debemos convertir la imagen en un vector
            imgVector=reshape(imagen,1,[]);
            dimOriginal=length(imgVector);
            %Debemos asegurarnos que la dimensión de imgVector concuerda
            %con el tamaño de la matriz H, si no, agregamos 0 hasta que
            %concuerde, este nuevo vector se llamara imgVectoraux
            imgVectoraux=zeros(1,n*ceil(length(imgVector)/n));
            imgVectoraux(1:dimOriginal)=imgVector;
            %Ahora debemos hacer que imgVectoraux tenga dimensiones para
            %poderlo multiplicar con H
            imgMat=transpose(reshape(imgVectoraux,n,[]));
            imagenDecifrada=app.appPrincipal.MultMatMod(imgMat,invH,256);
            %Después de obtener una matrizCifrada, debemos convertirla al
            %formato de la imagen original
            imagenDecifrada=reshape(transpose(imagenDecifrada),1,[]);
            imagenDecifrada=imagenDecifrada(1:dimOriginal);
            imagenDecifrada=reshape(imagenDecifrada,altoImagen,[]);
        end
        
        function actualizarEtiqueta(app,texto)
            app.Label.Text=texto;
        end
        
        function codificarImagenHillOriginal(app,H)
            imgaux=app.ImgEntrada.ImageSource;
            imagenEntrada1=imgaux(:,:,1);
            imagenEntrada2=imgaux(:,:,2);
            imagenEntrada3=imgaux(:,:,3);
            imagenSalida(:,:,1)=cast(app.HillCifrar(imagenEntrada1,H),'uint8');
            imagenSalida(:,:,2)=cast(app.HillCifrar(imagenEntrada2,H),'uint8');
            imagenSalida(:,:,3)=cast(app.HillCifrar(imagenEntrada3,H),'uint8');
            app.ImgSalida.ImageSource=imagenSalida;
        end

        function decodificarImagenHillOriginal(app,H)
            imgaux=app.ImgEntrada.ImageSource;
            imagenEntrada1=imgaux(:,:,1);
            imagenEntrada2=imgaux(:,:,2);
            imagenEntrada3=imgaux(:,:,3);
                
            imagenSalida(:,:,1)=cast(app.HillDecifrar(imagenEntrada1,H),'uint8');
            imagenSalida(:,:,2)=cast(app.HillDecifrar(imagenEntrada2,H),'uint8');
            imagenSalida(:,:,3)=cast(app.HillDecifrar(imagenEntrada3,H),'uint8');
            app.ImgSalida.ImageSource=imagenSalida;
        end
        
        function codificarImagenHillModificado(app,H)
            imgaux=app.ImgEntrada.ImageSource;
            tamClave=size(H,1); %tamClave = numero de bloques
            imagenSalida=imgaux;
            ancho=size(imgaux,2);
            alto=size(imgaux,1);
            cols=ancho/tamClave;
            fil=alto/tamClave;
            for ii=1:3
                imagenEntradaii=imgaux(:,:,ii);
                imagenSalidaAux=zeros(size(imagenEntradaii));
                for jj=1:tamClave
                    for kk=1:tamClave
                        tmpBloque=imagenEntradaii(jj:tamClave:end,kk:tamClave:end);
                        tmpBloque=cast(app.HillCifrar(tmpBloque,H),'uint8');
                        tmpBloque=cast(app.HillCifrar(transpose(tmpBloque),H),'uint8');
                        %imagenSalidaAux(jj:tamClave:end,kk:tamClave:end)=transpose(tmpBloque);
                        imagenSalidaAux((jj-1)*fil+1:jj*fil,(kk-1)*cols+1:kk*cols)=transpose(tmpBloque);
                        
                    end
                end
                imagenSalida(:,:,ii)=cast(imagenSalidaAux,'uint8');
            end
            app.ImgSalida.ImageSource=imagenSalida;
        end

        function decodificarImagenHillModificado(app,H)
            imgaux=app.ImgEntrada.ImageSource;
            tamClave=size(H,1); %tamClave = numero de bloques
            imagenSalida=imgaux;
            ancho=size(imgaux,2);
            alto=size(imgaux,1);
            cols=ancho/tamClave;
            fil=alto/tamClave;
            for ii=1:3
                imagenEntradaii=imgaux(:,:,ii);
                imagenSalidaAux=zeros(size(imagenEntradaii));
                for jj=1:tamClave
                    for kk=1:tamClave
                        tmpBloque=transpose(imagenEntradaii((jj-1)*fil+1:jj*fil,(kk-1)*cols+1:kk*cols));
                        tmpBloque=cast(app.HillDecifrar(tmpBloque,H),'uint8');
                        tmpBloque=cast(app.HillDecifrar(transpose(tmpBloque),H),'uint8');
                        imagenSalidaAux(jj:tamClave:end,kk:tamClave:end)=tmpBloque;
                        %imagenSalidaAux((jj-1)*fil+1:jj*fil,(kk-1)*cols+1:kk*cols)=transpose(tmpBloque);
                        
                    end
                end
                imagenSalida(:,:,ii)=cast(imagenSalidaAux,'uint8');
            end
            app.ImgSalida.ImageSource=imagenSalida;
        end
        
        
        function mcd = MCD(app,a,b)
            while ~isequal(a,b)
                if a>b
                    a=a-b;
                else
                    b=b-a;
                end
            end
            mcd=a;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN;
        end

        % Image clicked function: ImgEntrada
        function ImgEntradaImageClicked(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           figure(app.appPrincipal.CriptUNUIFigure)
           figure(app.ImgHillUIFigure)
           
           if filename==0
                return
           end
           app.Label.Text='';
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           app.imagen=ImageFile;
           [~,~,colores]=size(ImageFile);
           if colores==1
                app.modoGris=true;
                imagenAux(:,:,1)=ImageFile;
                imagenAux(:,:,2)=ImageFile;
                imagenAux(:,:,3)=ImageFile;
           end
           if colores==3
               app.modoGris=false;
               imagenAux=ImageFile;
           end
           
           tam=size(imagenAux(:,:,1));
           if mod(tam(2),2)==1
                imagenAux=imagenAux(:,1:end-1,:);
            end
            if mod(tam(1),2)==1
                imagenAux=imagenAux(1:end-1,:,:);
            end
           
           tam=size(imagenAux(:,:,1));
           ancho=num2str(tam(2));
           alto=num2str(tam(1));
           mcd=app.MCD(tam(2),tam(1));
           K = 1:ceil(sqrt(mcd));
           D = K(rem(mcd,K)==0);
           D = [D sort(mcd./D)];
           D= unique(D(mod(D,2)==0));
           app.divisoresClave=D;
           if length(app.divisoresClave)==1
               uialert(app.ImgHillUIFigure,'La imagen ingresada solo puede ser procesada con una clave de tamaño 2x2. El resultado obtenido podría ser insuficiente, considere redimensionar la imagen en un editor con dimensiones de manera tal que el ancho y el alto sean multiplos de 2 y tengan un máximo común divisor mayor que 2.','Advertencia');
           end
           app.ImgEntrada.ImageSource=imagenAux;   
           app.ClaveTextArea.Value=append('El tamaño de la imagen ingresada es de ',ancho,'x',alto,'. El tamaño de la clave debe ser divisor par de ',num2str(mcd),'. Por ejemplo:',num2str(D) ,'. Haga clic derecho sobre este espacio para ver opciones de la clave.'); 
        end

        % Value changed function: CDSwitch
        function CDSwitchValueChanged(app, event)
            value = app.CDSwitch.Value;
            if strcmp(value,'C')
                app.CDLabel.Text='Codificar';
            else
                app.CDLabel.Text='Decodificar';
            end
        end

        % Image clicked function: ImgSalida
        function ImgSalidaImageClicked(app, event)
            %%% Guardar resultado
            if isempty(app.ImgSalida.ImageSource)
                uialert(app.ImgHillUIFigure,'No se ha generado ningún resultado.','Error');
                return
            end
            msg = '¿Desea guardar el resultado?.';
            title = 'Guardar';
            selection = uiconfirm(app.ImgHillUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.png';'*.*';};
                    [file, path] = uiputfile(filter,'Guardar resultado','Resultado.png');
                    figure(app.ImgHillUIFigure);
                    if file==0
                        return
                    end
                    imwrite(app.ImgSalida.ImageSource, fullfile(path,file),'png');
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: LimpiarclaveMenu
        function LimpiarclaveMenuSelected(app, event)
            app.ClaveTextArea.Value='';
        end

        % Menu selected function: GenerarclaveMenu
        function GenerarclaveMenuSelected(app, event)
            entrada=inputdlg('Ingrese la dimensión de la matriz:','Generar clave');
            figure(app.ImgHillUIFigure);
            if isempty(entrada)
                return
            end
            tam=str2double(entrada);
            if isempty(app.divisoresClave(app.divisoresClave==tam))
                uialert(app.ImgHillUIFigure,'El número ingresado no está permitido.','Error');
                return
            end
            
            M22=randi(256,tam/2,tam/2)-1;
            M11=mod(-M22,256);
            
            k=randi(256,1,1)-1;
            while gcd(k,256)~=1
                k=randi(256,1,1)-1;
            end
            [~,~,kinv]=app.appPrincipal.ExtendedEuclideanAlgorithm(256,k);
            M12= mod(k*(eye(tam/2)-M11),256);
            M21= mod(kinv*(eye(tam/2)+M11),256);

            matriz=[M11,M12;M21,M22];

            app.appPrincipal.MultMatMod(matriz,matriz,256)


            %if tam<11
            %   matriz=randi(256,tam,tam)-1;

            %    while gcd(app.appPrincipal.detMod(matriz,256),256)~=1
            %        matriz=randi(256,tam,tam)-1;
            %    end
            %else
            %    generador=@(M)M(randperm(length(M)),:);
            %    matriz=generador(eye(tam));
            %end

            archivotemporal=tempname;
            writematrix(matriz,archivotemporal);
            app.ClaveTextArea.Value=fileread(append(archivotemporal,'.txt'));
            delete(append(archivotemporal,'.txt'));
        end

        % Menu selected function: GuardarclaveMenu
        function GuardarclaveMenuSelected(app, event)
            msg = '¿Desea guardar la clave?';
            title = 'Guardar';
            selection = uiconfirm(app.ImgHillUIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar clave',append('clave - imagen.txt'));
                    figure(app.ImgHillUIFigure);
                    if file==0
                        return
                    end
                    writecell(app.ClaveTextArea.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: CargarclaveMenu
        function CargarclaveMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.ImgHillUIFigure);
            if file==0
                return
            end
            app.ClaveTextArea.Value=fileread(fullfile(path,file));
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            app.ClaveTextArea.Value='';
            app.ImgEntrada.ImageSource='cargar.png';
            app.ImgSalida.ImageSource='guardar.png';
            app.Label.Text='';
            app.ClaveTextArea.Value='Ingrese una imagen haciendo clic en la opción de abajo. Después se mostrará aquí el tamaño de la imagen y el tamaño de la clave recomendada. Haga clic derecho sobre este espacio para ver opciones de la clave. Tenga en cuenta que para tamaños de clave mayores a 10, el programa se puede tardar bastante.';
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            %revisar que la clave sea correcta
            %La clave es una matriz, que se ingresará de la misma manera
            %que se ingresan las matrices en matlab, excluyendo los []
            app.Label.Text='Procesando';
            app.ImgSalida.Visible=0;
            pause(0.01)
            archivotemporal=tempname;
            writecell(app.ClaveTextArea.Value, archivotemporal, 'QuoteStrings',false);
            H=round(readmatrix(archivotemporal));
            delete(append(archivotemporal,'.txt'));
            if isempty(H)
                uialert(app.ImgHillUIFigure,'No se ingresó ninguna clave.','Error');
                app.Label.Text='Error';
                return
            end
            if sum(isnan(H(:)))==1
                uialert(app.ImgHillUIFigure,'La matriz ingresada no es una matriz válida. Revise que todos los valores sean números enteros','Error');
                app.Label.Text='Error';
                return
            end
            if gcd(app.appPrincipal.detMod(H,256),256)~=1
                uialert(app.ImgHillUIFigure,'La clave ingresada no es válida. El determinante de la matriz debe ser primo relativo con 256.','Error');
                app.Label.Text='Error';
                return
            end
            %revisar que haya una imagen de entrada
            if isempty(app.ImgEntrada.ImageSource)
                uialert(app.ImgHillUIFigure,'No se ingresó ninguna imagen de entrada.','Error');
                app.Label.Text='Error';
                return
            end

            %la imagen de entrada solo puede ser rgb, por lo tanto, si se
            %ingresa una en tonalidad de grises, hay que repetirla para
            %mostrarla en la aplicación
            
            if strcmp(app.CDSwitch.Value,'C')
                app.codificarImagenHillModificado(H);
            else
                app.decodificarImagenHillModificado(H);
            end
            app.Label.Text='Listo';
            app.ImgSalida.Visible=1;
            %{
            disp(magic(10))
            imagenSalida=app.HillCifrar(magic(10),H);
            disp(imagenSalida)
            imagenSalida=app.HillDecifrar(imagenSalida,H);
            disp(imagenSalida)
            %}
        end

        % Close request function: ImgHillUIFigure
        function ImgHillUIFigureCloseRequest(app, event)
            figure(app.appPrincipal.CriptUNUIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImgHillUIFigure = uifigure('Visible', 'off');
            app.ImgHillUIFigure.Name = 'Imágenes con el sistema de Hill';
            app.ImgHillUIFigure.Icon = 'Logo.png';
            app.ImgHillUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImgHillUIFigureCloseRequest, true);
            app.ImgHillGridLayout = uigridlayout(app.ImgHillUIFigure);
            app.ImgEntradaPanel = uipanel(app.ImgHillGridLayout);
            app.ImgEntradaPanel.Title = 'Entrada';
            app.ImgEntradaGridLayout = uigridlayout(app.ImgEntradaPanel);
            app.ImgEntrada = uiimage(app.ImgEntradaGridLayout);
            app.ImgEntrada.ImageClickedFcn = createCallbackFcn(app, @ImgEntradaImageClicked, true);
            app.ImgEntrada.ImageSource = 'cargar.png';
            app.ResultadoPanel = uipanel(app.ImgHillGridLayout);
            app.ResultadoPanel.Title = 'Resultado';
            app.ImgSalidaGridLayout = uigridlayout(app.ResultadoPanel);
            app.ImgSalida = uiimage(app.ImgSalidaGridLayout);
            app.ImgSalida.ImageClickedFcn = createCallbackFcn(app, @ImgSalidaImageClicked, true);
            app.ImgSalida.ImageSource = 'guardar.png';
            app.ClavePanel = uipanel(app.ImgHillGridLayout);
            app.ClavePanel.Title = 'Clave';
            app.ClaveGridLayout = uigridlayout(app.ClavePanel);
            app.ClaveTextArea = uitextarea(app.ClaveGridLayout);
            app.ClaveTextArea.Editable = 'off';
            app.ClaveTextArea.Placeholder = 'Ingrese una imagen haciendo clic en la opción de abajo. Después se mostrará aquí el tamaño de la imagen y el tamaño de la clave recomendada. Haga clic derecho sobre este espacio para ver opciones de la clave. Tenga en cuenta que para tamaños de clave mayores a 10, el programa se puede tardar bastante.';
            app.OpcionesPanel = uipanel(app.ImgHillGridLayout);
            app.OpcionesPanel.Title = 'Opciones';
            app.OpcionesGridLayout = uigridlayout(app.OpcionesPanel);
            app.CDSwitch = uiswitch(app.OpcionesGridLayout, 'slider');
            app.CDSwitch.Items = {'C', 'D'};
            app.CDSwitch.ValueChangedFcn = createCallbackFcn(app, @CDSwitchValueChanged, true);
            app.CDSwitch.Value = 'C';
            app.CDLabel = uilabel(app.OpcionesGridLayout);
            app.CDLabel.Text = 'Codificar';
            app.EjecutarButton = uibutton(app.OpcionesGridLayout, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.ReiniciarButton = uibutton(app.OpcionesGridLayout, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.Label = uilabel(app.OpcionesGridLayout);
            app.Label.Text = '';
            app.ContextMenu = uicontextmenu(app.ImgHillUIFigure);
            app.LimpiarclaveMenu = uimenu(app.ContextMenu);
            app.LimpiarclaveMenu.MenuSelectedFcn = createCallbackFcn(app, @LimpiarclaveMenuSelected, true);
            app.LimpiarclaveMenu.Text = 'Limpiar clave';
            app.GenerarclaveMenu = uimenu(app.ContextMenu);
            app.GenerarclaveMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarclaveMenuSelected, true);
            app.GenerarclaveMenu.Text = 'Generar clave';
            app.GuardarclaveMenu = uimenu(app.ContextMenu);
            app.GuardarclaveMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarclaveMenuSelected, true);
            app.GuardarclaveMenu.Text = 'Guardar clave';
            app.CargarclaveMenu = uimenu(app.ContextMenu);
            app.CargarclaveMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarclaveMenuSelected, true);
            app.CargarclaveMenu.Text = 'Cargar clave';
            % Assign app.ContextMenu
            app.ClaveTextArea.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ImgHillUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagenesHill(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImgHillUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImgHillUIFigure)
        end
    end
end