classdef SistemaGammaPentagonal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Gamma5UIFigure            matlab.ui.Figure
        ArchivoMenu               matlab.ui.container.Menu
        ReiniciarMenu             matlab.ui.container.Menu
        OpcionesMenu              matlab.ui.container.Menu
        GuardaropcionesMenu       matlab.ui.container.Menu
        CargaropcionesMenu        matlab.ui.container.Menu
        SalirMenu                 matlab.ui.container.Menu
        Gamma5GridLayout          matlab.ui.container.GridLayout
        TextoCifradoPanel         matlab.ui.container.Panel
        TextoCifradoGridLayout    matlab.ui.container.GridLayout
        TextoCifradoTextArea      matlab.ui.control.TextArea
        TextoClaroPanel           matlab.ui.container.Panel
        TextoClaroGridLayout      matlab.ui.container.GridLayout
        TextoClaroTextArea        matlab.ui.control.TextArea
        OpcionesPanel             matlab.ui.container.Panel
        OpcionesGridLayout        matlab.ui.container.GridLayout
        DecifrarButton            matlab.ui.control.Button
        CifrarButton              matlab.ui.control.Button
        YmaxSpinner               matlab.ui.control.Spinner
        YmaxSpinnerLabel          matlab.ui.control.Label
        XmaxSpinner               matlab.ui.control.Spinner
        XmaxSpinnerLabel          matlab.ui.control.Label
        YminSpinner               matlab.ui.control.Spinner
        YminSpinnerLabel          matlab.ui.control.Label
        XminSpinner               matlab.ui.control.Spinner
        XminSpinnerLabel          matlab.ui.control.Label
        GenerarpermutacinButton   matlab.ui.control.Button
        PermutacinEditField       matlab.ui.control.EditField
        PermutacinEditFieldLabel  matlab.ui.control.Label
        YinicialSpinner           matlab.ui.control.Spinner
        YinicialSpinnerLabel      matlab.ui.control.Label
        XinicialSpinner           matlab.ui.control.Spinner
        XinicialSpinnerLabel      matlab.ui.control.Label
        TipodegrafoDropDown       matlab.ui.control.DropDown
        TipodegrafoDropDownLabel  matlab.ui.control.Label
        GraficasTabGroup          matlab.ui.container.TabGroup
        GrafoTab                  matlab.ui.container.Tab
        GrafoGridLayout           matlab.ui.container.GridLayout
        GrafoUIAxes               matlab.ui.control.UIAxes
        AlfabetosTab              matlab.ui.container.Tab
        AlfabetosGridLayout       matlab.ui.container.GridLayout
        AlfabetosUIAxes           matlab.ui.control.UIAxes
        TextoClaroContextMenu     matlab.ui.container.ContextMenu
        TextoClaroCargarMenu      matlab.ui.container.Menu
        TextoClaroGuardarMenu     matlab.ui.container.Menu
        TextoClaroLimpiarMenu     matlab.ui.container.Menu
        TextoCifradoContextMenu   matlab.ui.container.ContextMenu
        TextoCifradoCargarMenu    matlab.ui.container.Menu
        TextoCifradoGuardarMenu   matlab.ui.container.Menu
        TextoCifradoLimpiarMenu   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        xmin=-10
        xmax=10
        ymin=-10
        ymax=20
        origenGrafoX=0      %
        origenGrafoY=0      %
        grafo               %puntos del Grafo
        tipoGrafo='1'           %Tipo del grafo
        celdaMatrizAlfabeto
        coordenadas
        flechasEntrada
        permutacionX

        appPrincipal % Description
    end
    
    methods (Access = private)
        
        function actualizarGraficasAlfabeto(app)
            [xgrid,ygrid]=ndgrid(0:app.xmax,0:app.ymax);
            plot(app.AlfabetosUIAxes,xgrid,ygrid,'.',"Color",[192,0,0]/255);
            app.coordenadas=cell(size(xgrid));
            alfabetos=cell(size(xgrid));
    
            for ii=1:size(alfabetos,1)
                for jj=1:size(alfabetos,2)
                    %labels{ii,jj}=sprintf('(%d,%d)',[xgrid(ii,jj),ygrid(ii,jj)]);
                    % coordenadas 
                    alfabetos{ii,jj}=char(97+mod(ii+jj-2,26));
                end
            end

            %aplicar las transformaciones a la matriz aquí!!!
            %primero suma la matriz con flechas de entrada
            for ii=1:size(alfabetos,1)
                for jj=1:size(alfabetos,2)
                    app.coordenadas{ii,jj}=sprintf('(%d,%d)',[xgrid(ii,jj),ygrid(ii,jj)]);
                    alfabetos{ii,jj}=char(97+mod(ii+jj-2+app.flechasEntrada(ii,jj),26));
                end
            end
            %después hace la permutación
            alfabetos=alfabetos(app.permutacionX,:);
            %axis equal
            app.AlfabetosUIAxes.XLim=[-1,app.xmax+1];
            app.AlfabetosUIAxes.YLim=[-1,app.ymax+1];
            app.AlfabetosUIAxes.XAxisLocation='origin';
            app.AlfabetosUIAxes.YAxisLocation='origin';
            app.AlfabetosUIAxes.XAxis.Color=[200,200,200]/255;
            app.AlfabetosUIAxes.YAxis.Color=[200,200,200]/255;
            set(app.AlfabetosUIAxes,'XTick',0:app.xmax,'YTick',0:app.ymax);
            set(app.AlfabetosUIAxes,'XTickLabel',0:app.xmax,'YTickLabel',0:app.ymax,'Box','off');
            %text(app.AlfabetosUIAxes,reshape(xgrid,1,[]),reshape(ygrid,1,[]),reshape(labels,1,[]),'VerticalAlignment','bottom','HorizontalAlignment','center','FontSize',5,'Color',[128,128,128]/255)
            text(app.AlfabetosUIAxes,reshape(xgrid,1,[]),reshape(ygrid,1,[]),reshape(alfabetos,1,[]),'VerticalAlignment','bottom','HorizontalAlignment','left','FontSize',12)
            
            disableDefaultInteractivity(app.AlfabetosUIAxes);
            app.celdaMatrizAlfabeto=alfabetos;
        end

        function actualizarGraficaGrafo(app)
            switch app.tipoGrafo
                case '1'
                    g=app.generarGrafo1(); %g es la cadena de puntos
                case '2'
                    g=app.generarGrafo2();
            end
            [xgrid,ygrid]=ndgrid(app.xmin:app.xmax,app.ymin:app.ymax);
            plot(app.GrafoUIAxes,xgrid,ygrid,'.',"Color",[192,192,192]/255);
            app.GrafoUIAxes.XLim=[app.xmin-1,app.xmax+1];
            app.GrafoUIAxes.YLim=[app.ymin-1,app.ymax+1];
            app.GrafoUIAxes.XAxisLocation='origin';
            app.GrafoUIAxes.YAxisLocation='origin';
            app.GrafoUIAxes.XAxis.Color=[200,200,200]/255;
            app.GrafoUIAxes.YAxis.Color=[200,200,200]/255;
            set(app.GrafoUIAxes,'XTickLabel',[],'YTickLabel',[],'Box','off');
            hold(app.GrafoUIAxes,"on");
            plot(app.GrafoUIAxes,g(1,:),g(2,:),'k-');
            hold(app.GrafoUIAxes,"off");
            disableDefaultInteractivity(app.GrafoUIAxes);
            
            %Actualizar matriz
            app.grafo=g;
            %crear la matriz que guardará la cantidad de flechas que le
            %entran al punto
            flechasEntradaAux=zeros(size(xgrid));
            %leer el arreglo del grafo g, de a 2,porque el primer punto
            %dice el inicio de la linea y el segundo el fin
            rangox=app.xmin:app.xmax;
            rangoy=app.ymin:app.ymax;
            for ii=1:size(g,2)-1
                %saltar los casos donde aparece nan
                if isnan(g(1,ii))||isnan(g(1,ii+1))||isnan(g(2,ii))||isnan(g(2,ii+1))
                    continue
                end
                indiceX=find(rangox==g(1,ii+1));
                indiceY=find(rangoy==g(2,ii+1));
                flechasEntradaAux(indiceX,indiceY)=flechasEntradaAux(indiceX,indiceY)+1;
            end
            app.flechasEntrada=flechasEntradaAux(find(rangoy==0):end,find(rangoy==0):end);
        end
        
        
        function g = generarGrafo1(app)
            puntoinicial=[app.origenGrafoX,app.origenGrafoY,1,0];
            limites=[app.xmax,app.ymax,inf];
            curvastipo1=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
            curvastipo2={};
            for ii=2:length(curvastipo1(1,:))
                puntoinicial=[curvastipo1(1,ii),curvastipo1(2,ii),2,0];
                aux=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
                curvastipo2=[curvastipo2,aux];
            end
            curvastipo3={};
            for ii=1:length(curvastipo2)
                aux=curvastipo2{ii};
                for jj=2:length(aux(1,:))
                    limites=[app.xmax,app.ymax,aux(4,jj)];
                    puntoinicial=[aux(1,jj),aux(2,jj),3,0];
                    aux2=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
                    curvastipo3=[curvastipo3,aux2(:,1:end-1)];
                end
            end
            puntos2=[];
            for ii=1:length(curvastipo2)
                puntos2=[puntos2,[nan;nan;nan;nan],curvastipo2{ii}];
            end
            puntos3=[];
            for ii=1:length(curvastipo3)
                puntos3=[puntos3,[nan;nan;nan;nan],curvastipo3{ii}];
            end
            g=[curvastipo1,puntos2,puntos3];
        end


        function g = generarGrafo2(app)
            puntoinicial=[app.origenGrafoX,app.origenGrafoY,1,0];
            limites=[app.xmax,app.ymax,inf];
            curvastipo1=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
            curvastipo2={};
            for ii=2:length(curvastipo1(1,:))
                puntoinicial=[curvastipo1(1,ii),curvastipo1(2,ii),2,0];
                aux=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
                curvastipo2=[curvastipo2,aux];
            end
            puntos2=[];
            for ii=1:length(curvastipo2)
                puntos2=[puntos2,[nan;nan;nan;nan],curvastipo2{ii}];
            end
            puntoinicial=[app.origenGrafoX+2,app.origenGrafoY+1,1,0];
            limites=[app.xmax,app.ymax,inf];
            curvastipo1aux=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
            curvastipo2aux={};
            puntos11=curvastipo1aux;
            puntos22=[];
            jj=1;
            while puntoinicial(1)<limites(1)&&puntoinicial(2)<limites(2)
                for ii=2:length(curvastipo1aux(1,:))
                    puntoinicial=[curvastipo1aux(1,ii),curvastipo1aux(2,ii),2,0];
                    aux=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
                    curvastipo2aux=[curvastipo2aux,aux];
                end
                for ii=1:length(curvastipo2aux)
                    puntos22=[puntos22,[nan;nan;nan;nan],curvastipo2aux{ii}];
                end
                puntoinicial=[app.origenGrafoX+2*(jj+1),app.origenGrafoY+1*(jj+1),1,0];
                curvastipo1aux=app.gamma5r(puntoinicial,limites,transpose(puntoinicial),0);
                puntos11=[puntos11,[nan;nan;nan;nan],curvastipo1aux];
                curvastipo2aux={};
                jj=jj+1;
            end
            g=[curvastipo1,puntos2,puntos22];
        end

        function puntos=gamma5r(app,puntoinicial,limites,puntos,pendiente) 
            %puntoinicial: [x,y,tipodecurva,pendientecurvatipo2]
            %limites: [xmax,ymax,pendmaxima]
            if puntoinicial(1)>limites(1)||puntoinicial(2)>limites(2)||puntoinicial(4)>limites(3)
                return
            else
                siguientependiente=pendiente+1;
                puntosiguiente=[puntoinicial(1)+1,puntoinicial(2)+pendiente,puntoinicial(3),pendiente];
                puntos=app.gamma5r(puntosiguiente,limites,[puntos,transpose(puntosiguiente)],siguientependiente);
            end
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CriptUN)
            app.appPrincipal=CriptUN;
            
            %Valores por defecto
            app.XminSpinner.Value=-10;
            app.XmaxSpinner.Value=10;
            app.YminSpinner.Value=-10;
            app.YmaxSpinner.Value=20;
            app.TipodegrafoDropDown.Value='1';
            app.XinicialSpinner.Value=0;
            app.YinicialSpinner.Value=0;
            app.PermutacinEditField.Value='';
            app.TextoClaroTextArea.Value='';
            app.TextoCifradoTextArea.Value='';

            app.xmin=app.XminSpinner.Value;
            app.xmax=app.XmaxSpinner.Value;
            app.ymin=app.YminSpinner.Value;
            app.ymax=app.YmaxSpinner.Value;
            app.origenGrafoX=app.XinicialSpinner.Value;      %
            app.origenGrafoY=app.YinicialSpinner.Value;
            app.permutacionX=1:app.xmax+1;
            app.XinicialSpinner.Limits=[app.xmin,app.xmax];
            app.YinicialSpinner.Limits=[app.ymin,app.ymax];
            app.tipoGrafo=app.TipodegrafoDropDown.Value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();            
        end

        % Value changed function: TipodegrafoDropDown
        function TipodegrafoDropDownValueChanged(app, event)
            value = app.TipodegrafoDropDown.Value;
            app.tipoGrafo=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
        end

        % Value changed function: XinicialSpinner
        function XinicialSpinnerValueChanged(app, event)
            value = app.XinicialSpinner.Value;
            app.origenGrafoX=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
        end

        % Value changed function: YinicialSpinner
        function YinicialSpinnerValueChanged(app, event)
            value = app.YinicialSpinner.Value;
            app.origenGrafoY=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
        end

        % Value changed function: XminSpinner
        function XminSpinnerValueChanged(app, event)
            value = app.XminSpinner.Value;
            app.xmin=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            app.XinicialSpinner.Limits=[app.xmin,app.xmax];
        end

        % Value changed function: YminSpinner
        function YminSpinnerValueChanged(app, event)
            value = app.YminSpinner.Value;
            app.ymin=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            app.YinicialSpinner.Limits=[app.ymin,app.ymax];
        end

        % Value changed function: XmaxSpinner
        function XmaxSpinnerValueChanged(app, event)
            value = app.XmaxSpinner.Value;
            app.xmax=value;
            app.permutacionX=1:app.xmax+1;
            app.PermutacinEditField.Value='';
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            app.XinicialSpinner.Limits=[app.xmin,app.xmax];
            

        end

        % Value changed function: YmaxSpinner
        function YmaxSpinnerValueChanged(app, event)
            value = app.YmaxSpinner.Value;
            app.ymax=value;
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            app.YinicialSpinner.Limits=[app.ymin,app.ymax];
        end

        % Button pushed function: GenerarpermutacinButton
        function GenerarpermutacinButtonPushed(app, event)
            %app.PermutacinEditField.Value
            perm=randperm(app.xmax+1)-1;
            archivotemporal=tempname;
            writematrix(perm,archivotemporal);
            app.PermutacinEditField.Value=fileread(append(archivotemporal,'.txt'));
            delete(append(archivotemporal,'.txt'));
            app.CifrarButton.Enable=1;
            app.DecifrarButton.Enable=1;
            app.permutacionX=perm+1;
            app.actualizarGraficasAlfabeto();
        end

        % Value changed function: PermutacinEditField
        function PermutacinEditFieldValueChanged(app, event)
            value = app.PermutacinEditField.Value;
            archivotemporal=tempname;
            writematrix(value, archivotemporal, 'QuoteStrings',false);
            perm=round(readmatrix(archivotemporal));
            delete(append(archivotemporal,'.txt'));
            if isempty(perm)
                perm=0:app.xmax;
            end
            if ~isequal(sort(perm),0:app.xmax)
                uialert(app.Gamma5UIFigure,append('La clave ingresada no es una permutación de números del 0 al ',string(app.xmax),'.'),'Error');
                app.CifrarButton.Enable=0;
                app.DecifrarButton.Enable=0;
                return
            end
            app.CifrarButton.Enable=1;
            app.DecifrarButton.Enable=1;
            app.permutacionX=perm+1;
            app.actualizarGraficasAlfabeto();
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            if strcmp(app.TextoClaroTextArea.Value,'')
                uialert(app.Gamma5UIFigure,'No hay ningún texto para cifrar.','Error');
                return
            end
            textoClaro=lower(regexprep(convertStringsToChars(strjoin(app.TextoClaroTextArea.Value)),'[\W\d\r\n]',''));
            coord=reshape(transpose(app.coordenadas),1,[]);
            alfab=reshape(transpose(app.celdaMatrizAlfabeto),1,[]);
            jj=1;
            textoCifrado=cell(1,length(textoClaro));
            for ii=1:length(textoClaro)
                while ~strcmp(textoClaro(ii),alfab{jj})
                    jj=mod(jj,length(alfab))+1;
                end
                textoCifrado{ii}=coord{jj};
                jj=mod(jj,length(alfab))+1;
            end
            app.TextoCifradoTextArea.Value=strjoin(textoCifrado,';');
        end

        % Button pushed function: DecifrarButton
        function DecifrarButtonPushed(app, event)
            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();
            if strcmp(app.TextoCifradoTextArea.Value,'')
                uialert(app.Gamma5UIFigure,'No hay ningún texto para decifrar.','Error');
                return
            end
            textoCifrado=convertStringsToChars(strjoin(app.TextoCifradoTextArea.Value));
            [inicio,final]=regexp(textoCifrado,'(\(\d+,\d+\);)*\(\d+,\d+\)');
            if isempty(inicio)||isempty(final)||inicio~=1||final~=length(textoCifrado)
                uialert(app.Gamma5UIFigure,'El texto ingresado para decifrar no es válido. Revíselo e intente nuevamente.','Error');
                return
            end
            textoCifrado=strrep(textoCifrado,'(','[');
            textoCifrado=strrep(textoCifrado,')',']');
            textoCifrado=strsplit(textoCifrado,';');
            textoClaro='';
            for ii=1:length(textoCifrado)
                coord=str2num(textoCifrado{ii});
                textoClaro=append(textoClaro,app.celdaMatrizAlfabeto{coord(1)+1,coord(2)+1});
            end
            app.TextoClaroTextArea.Value=textoClaro;
        end

        % Menu selected function: TextoClaroCargarMenu
        function TextoClaroCargarMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.Gamma5UIFigure);
            if file==0
                return
            end
            app.TextoClaroTextArea.Value=fileread(fullfile(path,file));
        end

        % Menu selected function: TextoClaroLimpiarMenu
        function TextoClaroLimpiarMenuSelected(app, event)
            app.TextoClaroTextArea.Value='';
        end

        % Menu selected function: ReiniciarMenu
        function ReiniciarMenuSelected(app, event)
            app.startupFcn();

        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            selection = uiconfirm(app.Gamma5UIFigure,'¿Desea cerrar la aplicación?',...
                    'CriptUN'); 
            switch selection 
                case 'OK'
                    closereq();
                case 'Cancelar'
                    return 
            end
            delete(app)
        end

        % Close request function: Gamma5UIFigure
        function Gamma5UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.Gamma5UIFigure,'¿Desea cerrar la aplicación?',...
                    'CriptUN'); 
            switch selection 
                case 'OK'
                    closereq();
                case 'Cancelar'
                    return 
            end
            delete(app)
        end

        % Menu selected function: TextoCifradoLimpiarMenu
        function TextoCifradoLimpiarMenuSelected(app, event)
            app.TextoCifradoTextArea.Value='';
        end

        % Menu selected function: TextoCifradoCargarMenu
        function TextoCifradoCargarMenuSelected(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.Gamma5UIFigure);
            if file==0
                return
            end
            app.TextoCifradoTextArea.Value=fileread(fullfile(path,file));
        end

        % Menu selected function: TextoCifradoGuardarMenu
        function TextoCifradoGuardarMenuSelected(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.Gamma5UIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar',append('Texto Cifrado - Gamma Pentagonal','.txt'));
                    figure(app.Gamma5UIFigure);
                    if file==0
                        return
                    end
                    writecell(app.TextoCifradoTextArea.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: TextoClaroGuardarMenu
        function TextoClaroGuardarMenuSelected(app, event)
            msg = '¿Desea guardar?.';
            title = 'Guardar';
            selection = uiconfirm(app.Gamma5UIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.txt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar',append('Texto Claro - Gamma Pentagonal','.txt'));
                    figure(app.Gamma5UIFigure);
                    if file==0
                        return
                    end
                    writecell(app.TextoClaroTextArea.Value, fullfile(path,file), 'QuoteStrings',false);
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: GuardaropcionesMenu
        function GuardaropcionesMenuSelected(app, event)
            msg = '¿Desea guardar las opciones?.';
            title = 'Guardar';
            selection = uiconfirm(app.Gamma5UIFigure,msg,title, ...
                        'Options',{'Guardar','Cancelar'}, ...
                        'DefaultOption',1,'CancelOption',2);
            switch selection
                case 'Guardar'
                    filter = {'*.g5opt';'*.*'};
                    [file, path] = uiputfile(filter,'Guardar',append('configuracion','.g5opt'));
                    figure(app.Gamma5UIFigure);
                    if file==0
                        return
                    end
                    celda={app.XminSpinner.Value,app.XmaxSpinner.Value,app.YminSpinner.Value,...
                            app.YmaxSpinner.Value,app.TipodegrafoDropDown.Value,app.XinicialSpinner.Value,...
                            app.YinicialSpinner.Value,app.PermutacinEditField.Value};
                    writecell(celda, fullfile(path,file),'FileType','text');
                case 'Cancelar'
                    return
            end
        end

        % Menu selected function: CargaropcionesMenu
        function CargaropcionesMenuSelected(app, event)
            [file,path] = uigetfile('*.g5opt');
            figure(app.Gamma5UIFigure);
            if file==0
                return
            end
            
            celda=readcell(fullfile(path,file),'FileType','text');
            if length(celda)~=8
                uialert(app.Gamma5UIFigure,'No se pudieron cargar las configuraciones desde el archivo.','Error');
                return
            end
            app.XminSpinner.Value=celda{1};
            app.XmaxSpinner.Value=celda{2};
            app.YminSpinner.Value=celda{3};
            app.YmaxSpinner.Value=celda{4};
            app.TipodegrafoDropDown.Value=num2str(celda{5});
            app.XinicialSpinner.Value=celda{6};
            app.YinicialSpinner.Value=celda{7};
            app.PermutacinEditField.Value=celda{8};

            app.xmin=app.XminSpinner.Value;
            app.xmax=app.XmaxSpinner.Value;
            app.ymin=app.YminSpinner.Value;
            app.ymax=app.YmaxSpinner.Value;
            app.origenGrafoX=app.XinicialSpinner.Value;      %
            app.origenGrafoY=app.YinicialSpinner.Value;
            app.permutacionX=str2num(app.PermutacinEditField.Value)+1;
            app.XinicialSpinner.Limits=[app.xmin,app.xmax];
            app.YinicialSpinner.Limits=[app.ymin,app.ymax];
            app.tipoGrafo=app.TipodegrafoDropDown.Value;

            app.actualizarGraficaGrafo();
            app.actualizarGraficasAlfabeto();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Gamma5UIFigure = uifigure('Visible', 'off');
            app.Gamma5UIFigure.Name = 'CriptUN - Sistema Gamma Pentagonal';
            app.Gamma5UIFigure.Icon = 'gamma.png';
            app.Gamma5UIFigure.CloseRequestFcn = createCallbackFcn(app, @Gamma5UIFigureCloseRequest, true);
            app.ArchivoMenu = uimenu(app.Gamma5UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.ReiniciarMenu = uimenu(app.ArchivoMenu);
            app.ReiniciarMenu.MenuSelectedFcn = createCallbackFcn(app, @ReiniciarMenuSelected, true);
            app.ReiniciarMenu.Text = 'Reiniciar';
            app.OpcionesMenu = uimenu(app.ArchivoMenu);
            app.OpcionesMenu.Text = 'Opciones';
            app.GuardaropcionesMenu = uimenu(app.OpcionesMenu);
            app.GuardaropcionesMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardaropcionesMenuSelected, true);
            app.GuardaropcionesMenu.Text = 'Guardar opciones';
            app.CargaropcionesMenu = uimenu(app.OpcionesMenu);
            app.CargaropcionesMenu.MenuSelectedFcn = createCallbackFcn(app, @CargaropcionesMenuSelected, true);
            app.CargaropcionesMenu.Text = 'Cargar opciones';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            app.Gamma5GridLayout = uigridlayout(app.Gamma5UIFigure);
            app.GraficasTabGroup = uitabgroup(app.Gamma5GridLayout);
            app.GrafoTab = uitab(app.GraficasTabGroup);
            app.GrafoTab.Title = 'Grafo';
            app.GrafoGridLayout = uigridlayout(app.GrafoTab);
            app.GrafoUIAxes = uiaxes(app.GrafoGridLayout);
            app.GrafoUIAxes.XTickLabel = '';
            app.GrafoUIAxes.YTickLabel = '';
            app.AlfabetosTab = uitab(app.GraficasTabGroup);
            app.AlfabetosTab.Title = 'Alfabetos';
            app.AlfabetosGridLayout = uigridlayout(app.AlfabetosTab);
            app.AlfabetosUIAxes = uiaxes(app.AlfabetosGridLayout);
            app.AlfabetosUIAxes.XTickLabel = {};
            app.AlfabetosUIAxes.YTickLabel = {};
            app.OpcionesPanel = uipanel(app.Gamma5GridLayout);
            app.OpcionesPanel.Title = 'Opciones';
            app.OpcionesGridLayout = uigridlayout(app.OpcionesPanel);
            app.TipodegrafoDropDownLabel = uilabel(app.OpcionesGridLayout);
            app.TipodegrafoDropDownLabel.Text = 'Tipo de grafo';
            app.TipodegrafoDropDown = uidropdown(app.OpcionesGridLayout);
            app.TipodegrafoDropDown.Items = {'1', '2'};
            app.TipodegrafoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodegrafoDropDownValueChanged, true);
            app.TipodegrafoDropDown.Value = '1';
            app.XinicialSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.XinicialSpinnerLabel.Text = 'X inicial';
            app.XinicialSpinner = uispinner(app.OpcionesGridLayout);
            app.XinicialSpinner.RoundFractionalValues = 'on';
            app.XinicialSpinner.ValueChangedFcn = createCallbackFcn(app, @XinicialSpinnerValueChanged, true);
            app.YinicialSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.YinicialSpinnerLabel.Text = 'Y inicial';
            app.YinicialSpinner = uispinner(app.OpcionesGridLayout);
            app.YinicialSpinner.RoundFractionalValues = 'on';
            app.YinicialSpinner.ValueChangedFcn = createCallbackFcn(app, @YinicialSpinnerValueChanged, true);
            app.PermutacinEditFieldLabel = uilabel(app.OpcionesGridLayout);
            app.PermutacinEditFieldLabel.Text = 'Permutación';
            app.PermutacinEditField = uieditfield(app.OpcionesGridLayout, 'text');
            app.PermutacinEditField.ValueChangedFcn = createCallbackFcn(app, @PermutacinEditFieldValueChanged, true);
            app.GenerarpermutacinButton = uibutton(app.OpcionesGridLayout, 'push');
            app.GenerarpermutacinButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarpermutacinButtonPushed, true);
            app.GenerarpermutacinButton.Text = 'Generar permutación';
            app.XminSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.XminSpinnerLabel.Text = 'X min';
            app.XminSpinner = uispinner(app.OpcionesGridLayout);
            app.XminSpinner.Limits = [-Inf -1];
            app.XminSpinner.RoundFractionalValues = 'on';
            app.XminSpinner.ValueChangedFcn = createCallbackFcn(app, @XminSpinnerValueChanged, true);
            app.XminSpinner.Value = -10;
            app.YminSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.YminSpinnerLabel.Text = 'Y min';
            app.YminSpinner = uispinner(app.OpcionesGridLayout);
            app.YminSpinner.Limits = [-Inf -1];
            app.YminSpinner.RoundFractionalValues = 'on';
            app.YminSpinner.ValueChangedFcn = createCallbackFcn(app, @YminSpinnerValueChanged, true);
            app.YminSpinner.Value = -10;
            app.XmaxSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.XmaxSpinnerLabel.Text = 'X max';
            app.XmaxSpinner = uispinner(app.OpcionesGridLayout);
            app.XmaxSpinner.Limits = [1 Inf];
            app.XmaxSpinner.RoundFractionalValues = 'on';
            app.XmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @XmaxSpinnerValueChanged, true);
            app.XmaxSpinner.Value = 10;
            app.YmaxSpinnerLabel = uilabel(app.OpcionesGridLayout);
            app.YmaxSpinnerLabel.Text = 'Y max';
            app.YmaxSpinner = uispinner(app.OpcionesGridLayout);
            app.YmaxSpinner.Limits = [1 Inf];
            app.YmaxSpinner.RoundFractionalValues = 'on';
            app.YmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @YmaxSpinnerValueChanged, true);
            app.YmaxSpinner.Value = 20;
            app.CifrarButton = uibutton(app.OpcionesGridLayout, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = 'Cifrar';
            app.DecifrarButton = uibutton(app.OpcionesGridLayout, 'push');
            app.DecifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DecifrarButtonPushed, true);
            app.DecifrarButton.Text = 'Decifrar';
            app.TextoClaroPanel = uipanel(app.Gamma5GridLayout);
            app.TextoClaroPanel.Title = 'Texto Claro';
            app.TextoClaroGridLayout = uigridlayout(app.TextoClaroPanel);
            app.TextoClaroTextArea = uitextarea(app.TextoClaroGridLayout);
            app.TextoCifradoPanel = uipanel(app.Gamma5GridLayout);
            app.TextoCifradoPanel.Title = 'Texto Cifrado';
            app.TextoCifradoGridLayout = uigridlayout(app.TextoCifradoPanel);
            app.TextoCifradoTextArea = uitextarea(app.TextoCifradoGridLayout);
            app.TextoClaroContextMenu = uicontextmenu(app.Gamma5UIFigure);
            app.TextoClaroCargarMenu = uimenu(app.TextoClaroContextMenu);
            app.TextoClaroCargarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoClaroCargarMenuSelected, true);
            app.TextoClaroCargarMenu.Text = 'Cargar';
            app.TextoClaroGuardarMenu = uimenu(app.TextoClaroContextMenu);
            app.TextoClaroGuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoClaroGuardarMenuSelected, true);
            app.TextoClaroGuardarMenu.Text = 'Guardar';
            app.TextoClaroLimpiarMenu = uimenu(app.TextoClaroContextMenu);
            app.TextoClaroLimpiarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoClaroLimpiarMenuSelected, true);
            app.TextoClaroLimpiarMenu.Text = 'Limpiar';
            % Assign app.TextoClaroContextMenu
            app.TextoClaroTextArea.ContextMenu = app.TextoClaroContextMenu;
            app.TextoCifradoContextMenu = uicontextmenu(app.Gamma5UIFigure);
            app.TextoCifradoCargarMenu = uimenu(app.TextoCifradoContextMenu);
            app.TextoCifradoCargarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoCifradoCargarMenuSelected, true);
            app.TextoCifradoCargarMenu.Text = 'Cargar';
            app.TextoCifradoGuardarMenu = uimenu(app.TextoCifradoContextMenu);
            app.TextoCifradoGuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoCifradoGuardarMenuSelected, true);
            app.TextoCifradoGuardarMenu.Text = 'Guardar';
            app.TextoCifradoLimpiarMenu = uimenu(app.TextoCifradoContextMenu);
            app.TextoCifradoLimpiarMenu.MenuSelectedFcn = createCallbackFcn(app, @TextoCifradoLimpiarMenuSelected, true);
            app.TextoCifradoLimpiarMenu.Text = 'Limpiar';
            % Assign app.TextoCifradoContextMenu
            app.TextoCifradoTextArea.ContextMenu = app.TextoCifradoContextMenu;
            % Show the figure after all components are created
            app.Gamma5UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemaGammaPentagonal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Gamma5UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Gamma5UIFigure)
        end
    end
end